classdef HueDashboard < matlab.apps.AppBase
    %HUEDASHBOARD (Developer and support notes)
    %   Developed by:
    %   Stephen Folmer
    %   spfolmer@niceway.com
    %   (c) 2026 Stephen Folmer
    %   
    %   Provided 'as is' without support or warranty.
    %   
    %   ----------------------------------------------------------------
    %   Developed on Matlab r2025 but, save for dark mode presentation,
    %   will be backwards compatible for several releases.
    %   ----------------------------------------------------------------
    %   
    %   Update log:
    %   20251015 - (ra4) v1.4 - corrected handling for change in Bridge IP
    %   - (ra5) v1.5 - tidied up 'no internet' handling.
    %   20251017 - (ra6) v1.6 - added detailed help text for control of
    %   lights.  
    %   20251019 - v2.0 - complete re-design of the Bridge pairing
    %   (adding to whitelist) and IP address update processes.
    %   20251022 - v2.1 cosmetic changes to informative messages.
    %   20251101 - v2.2 slightly larger display area, with new colouration,
    %   some larger fonts and real-time lights' status count after
    %   toggling.
    %   20251104 - v2.3 included dimmer switches in the sensor data table.
    %   dynamic text for low battery and unreachable lights diagnostics.
    %   20251105 - v2.4 added Last Event column to Sensors and Switches
    %   grid.  Moved things around to accommodate the expanded grid.  Also
    %   various display and performance enhancements..
    %   20251109 - v2.5 improvements to Rules details - cater for 'zero'
    %   groups and dimmers quirks.  Added 'last motion detected' section.
    %   20251112 - v2.6 added max/min temperatures in Sensor Temperatures
    %   panel title.
    %   20251121 - v2.7 added dynamic text indicating change in maximum and
    %   minimum temperatures.
    %   20251126 - v2.8 improved directory control for Hue Data.  Added
    %   HTML/CSS utilisation for enhanced presentation.
    %   20251203 - v2.9 minor bug fixes and enhancements to HTML.
    %   20251209 - v2.10 presentation improvements.
    %   20251212 - v2.11 bug fixes (including: Correct 'Lights On' count
    %   after toggling; Update base arrays after re-sorting the UI Lights
    %   grid to ensure correct lights/groups are processed); 
    %   Update the 'Lights Groups On' count in line with Toggled lights.
    %   20251224 - v3.0 added auto-restart when refresh response exceeds
    %   1.25 x app startup time.  Also incorporated hueAppDiagnostics.mat
    %   file.
    %   20251231 - v3.1 - Pulsing Hue Logo and minor code tidying.
    %   20260101 - v3.2 - removed extraneous Command Window diagnostics.
    %   20260102 - v3.3 - added count for number of Rooms with Lights ON.
    %   20260103 - v3.4 - introduced checkbox to restrict lights shown in
    %   the grid to only those that are lit and in a Room group.
    %   20260107 - v3.5 - added Location to Rules grid, which is derived by
    %   pattern-matching rules, sensors and resource links data.
    %   20260108 - v3.6 - Bri-Col column in the lights grid now shows the
    %   colour of the lights.
    %   20260110 - v3.7 - optimised hueXYtoRGB function.  Replaced arrayfun
    %   with vectorised solution.
    %   20260111 - v3.8 - remember last position of UIFigure
    %   20260113 - v3.9 - improved colour rendering for Bri-col cells.
    %   20260116 - v3.10 - convert RGB to nearest colour name match.
    %   20260117 - v4.0 - major changes.  Added 'Colour' column to Lights
    %   table.  Brightness and colour are now controlled from separate
    %   columns/cells.  Displayed lights colour names are derived directly
    %   from the Hue xy values based on nearest match to CSS colour
    %   schemes.
    %   20260119 - v4.1 - only store extracted bridge data on startup.
    %   20260122 - v4.2 - added auto-refresh option upon motion detection.
    %   20260201 - v5.0 - prepare for Bridge Pro integration.  API v2.
    %   20260313 - v6.0 - Support for Bridge v2 and Bridge Pro.
    %   20260314 - v6.1 - removed unused functions.
    %   20260315 - v6.2-v6.3 - optimisation
    %   20260317 - v6.4 - optimised uistyle assignments
    %   20260320 - v6.5 - enhanced colour selection options
    %   20260324 - v6.7 - bug fixes & added sitches to devices/batteries
    %   20260328 - v6.8 - cater for v2 api updates clearing event data and
    %   cater for sensor zones.
    %   20260330 - v6.9 - added sensor movement count stats.
    %   20260331 - v7.0 - tidy-up
    %   20260405 - v7.1 - fixed lights zones & rooms display for Bridge
    %   Pro.
    %   20260408 - v7.2 - added safe Timer code to prvent MATLAB crashing.
    %   20260409 - v7.3 - further work to prevent timers crashing MATLAB
    %   20260411 - v7.4 - cater for Smart Plugs (no dimming).
    %   20260413 - v7.5 - disable lights toggling when in offline mode.
    %   20260420 - v7.6 - implement auto-refresh limit restart trigger.
    %   20260425 - v7.7 - add auto-restart count and save when exiting via
    %   the Exit button.

    % Properties that correspond to app components
    properties (Access = public)
        HueDashboardUIFigure           matlab.ui.Figure
        RestartCount                   matlab.ui.control.NumericEditField
        LIMITLabel                     matlab.ui.control.Label
        RefreshLimit                   matlab.ui.control.NumericEditField
        LaunchTime                     matlab.ui.control.Label
        version                        matlab.ui.control.Label
        Sunset                         matlab.ui.control.Label
        inTimesTriggeredOrderLabel     matlab.ui.control.Label
        OfflineMode                    matlab.ui.control.CheckBox
        BridgeDropDown                 matlab.ui.control.DropDown
        BridgeDropDownLabel            matlab.ui.control.Label
        AutoRefreshButton              matlab.ui.control.Button
        lightsOnRoomsOnlyCheckbox      matlab.ui.control.CheckBox
        noRoomsLightsOn                matlab.ui.control.Label
        RoomsLabel                     matlab.ui.control.Label
        inLabel                        matlab.ui.control.Label
        HueLogo                        matlab.ui.container.Panel
        ResponseLimitSwitch            matlab.ui.control.RockerSwitch
        HTMLLastMotion                 matlab.ui.control.HTML
        HTMLLightsOn                   matlab.ui.control.HTML
        HTMLTempTrend                  matlab.ui.control.HTML
        HTMLLowBatMsg                  matlab.ui.control.HTML
        WarningsPanel                  matlab.ui.container.Panel
        HTMLUnreachableLights          matlab.ui.control.HTML
        HTMLLowBatDevs                 matlab.ui.control.HTML
        LowBatteryDevicesHdr           matlab.ui.control.Label
        UnreachableLightsHdr           matlab.ui.control.Label
        onLabel                        matlab.ui.control.Label
        BridgeConfigurationandUsagePanel  matlab.ui.container.Panel
        InLabel                        matlab.ui.control.Label
        TotalDevices                   matlab.ui.control.Label
        devicesLabel                   matlab.ui.control.Label
        SwitchesLabel                  matlab.ui.control.Label
        DimmerSwitches                 matlab.ui.control.Label
        MotionSensorsLabel             matlab.ui.control.Label
        MotionSensors                  matlab.ui.control.Label
        PCSensors                      matlab.ui.control.NumericEditField
        BRSensors                      matlab.ui.control.NumericEditField
        AVSensors                      matlab.ui.control.NumericEditField
        SensorsLabel                   matlab.ui.control.Label
        PCResourceLinks                matlab.ui.control.NumericEditField
        BRResourceLinks                matlab.ui.control.NumericEditField
        AVResourceLinks                matlab.ui.control.NumericEditField
        ResourceLinksLabel             matlab.ui.control.Label
        ONSchedules                    matlab.ui.control.NumericEditField
        PCSchedules                    matlab.ui.control.NumericEditField
        BRSchedules                    matlab.ui.control.NumericEditField
        AVSchedules                    matlab.ui.control.NumericEditField
        SchedulesLabel                 matlab.ui.control.Label
        PCScenes                       matlab.ui.control.NumericEditField
        BRScenes                       matlab.ui.control.NumericEditField
        AVScenes                       matlab.ui.control.NumericEditField
        ScenesLabel                    matlab.ui.control.Label
        OnGroups                       matlab.ui.control.NumericEditField
        PCGroups                       matlab.ui.control.NumericEditField
        BRGroups                       matlab.ui.control.NumericEditField
        AVGroups                       matlab.ui.control.NumericEditField
        GroupsLabel                    matlab.ui.control.Label
        ONRules                        matlab.ui.control.NumericEditField
        PCRules                        matlab.ui.control.NumericEditField
        BRRules                        matlab.ui.control.NumericEditField
        AVRules                        matlab.ui.control.NumericEditField
        RulesLabel                     matlab.ui.control.Label
        ONLights                       matlab.ui.control.NumericEditField
        PCLights                       matlab.ui.control.NumericEditField
        BRLights                       matlab.ui.control.NumericEditField
        AVLights                       matlab.ui.control.NumericEditField
        LightsLabel                    matlab.ui.control.Label
        OnActiveLabel                  matlab.ui.control.Label
        UsedLabel                      matlab.ui.control.Label
        BridgeLimitLabel               matlab.ui.control.Label
        InstalledLabel                 matlab.ui.control.Label
        DiagnosticsTimeTextArea        matlab.ui.control.TextArea
        DiagnosticsMsgTextArea         matlab.ui.control.TextArea
        DiagnosticsTextAreaLabel       matlab.ui.control.Label
        TableSensors                   matlab.ui.control.Table
        TableLights                    matlab.ui.control.Table
        UnreachableLightsLabel         matlab.ui.control.Label
        RulesTable                     matlab.ui.control.Table
        NeverTriggered                 matlab.ui.control.NumericEditField
        NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel  matlab.ui.control.Label
        RulesLabel_2                   matlab.ui.control.Label
        LightsTable                    matlab.ui.control.Table
        AllLightsOn                    matlab.ui.control.Button
        AllLightsOff                   matlab.ui.control.Button
        ToggleALLLightsButton          matlab.ui.control.Button
        LightsOnLabel                  matlab.ui.control.Label
        SensorsTable                   matlab.ui.control.Table
        SensorsandSwitchesLabel        matlab.ui.control.Label
        SensorTemperaturesPanel        matlab.ui.container.Panel
        FRSensors                      matlab.ui.control.NumericEditField
        FreezingLable                  matlab.ui.control.Label
        ColdLable                      matlab.ui.control.Label
        CoolLabel                      matlab.ui.control.Label
        WarmLabel                      matlab.ui.control.Label
        HotLabel                       matlab.ui.control.Label
        HOSensors                      matlab.ui.control.NumericEditField
        WASensors                      matlab.ui.control.NumericEditField
        COSensors                      matlab.ui.control.NumericEditField
        CLSensors                      matlab.ui.control.NumericEditField
        CMSensors                      matlab.ui.control.NumericEditField
        CMSensorsLabel                 matlab.ui.control.Label
        SWUpDateAvail                  matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        SWversion                      matlab.ui.control.TextArea
        BridgeID                       matlab.ui.control.TextArea
        IPAddressLabel_2               matlab.ui.control.Label
        Response                       matlab.ui.control.NumericEditField
        LastBridgeDataRefreshTextArea  matlab.ui.control.TextArea
        AutoSWUpdate                   matlab.ui.control.TextArea
        LastFirmwareUpdate             matlab.ui.control.TextArea
        ZigbeeChannel                  matlab.ui.control.NumericEditField
        IPAddress                      matlab.ui.control.TextArea
        APIversion                     matlab.ui.control.TextArea
        ZigbeeChannelLabel_2           matlab.ui.control.Label
        PreviousStatusRefreshLabel     matlab.ui.control.Label
        IPAddressLabel_4               matlab.ui.control.Label
        LastFirmwareUpdateSoftwareVersionTextAreaLabel  matlab.ui.control.Label
        ZigbeeChannelLabel             matlab.ui.control.Label
        IPAddressLabel                 matlab.ui.control.Label
        IPAddressLabel_3               matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        PhilipsHueDashboardLabel       matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
    end


    properties (Access = private)
        fName
        lastHueIP char % last obtained IP
        hueIPAddressV2 char % Bridge v2 IP
        hueIPAddressPro char % Bridge Pro IP
        appID char % Bridge v2 Registration Key (whitelist)
        appKey % Bridge Pro Registration Key (whitelist)
        dataRepository
        newBri
        cellSelIndices
        prevCellSel
        prevEventName
        editGroup
        lastKey
        xyColour
        newColr
        successfulStart
        prevMaxTemp
        prevMinTemp
        lightsOn
        tGroups
        responseLimit double = 0
        refreshLimit double = 500
        restartMsg string = ""
        diagFileName
        appFolder
        Pulse
        HTMLPulse
        roomLightsOn double = 0
        lightsOnRoomsOnly logical = false
        colBri
        startup logical = true
        UpdateTimer   timer
        lastRefresh datetime = datetime("now",TimeZone="UTC")
        lastLaunch datetime = datetime("now",TimeZone="Europe/London")
        autoRefresh logical = true
        autoRefreshCount double = 0
        autoRestartCount double = 0
        sensorNameLastMovement
        vAPI double = 1 % v1 for Bridge v2 - v2 for Bridge Pro
        restoreWindowPos logical = true

        % API v2 - data start
        geolocationv2
        roomsv2
        zonesv2
        lightsv2
        bridgev2
        devicesv2
        sensorsv2
        sensorsTempv2
        sensorsMotionv2
        groupsv2
        scenesv2
        scriptsv2
        schedulesv2
        capabilitiesv2
        resourcelinksv2
        softwareUpdatesv2
        powerStatesv2
        switchesv2
        devicePowerv2
        connectivityv2
        %API v2 - data end

        allResources
        resourceMap
        bridgeSwitch logical = false
        offlineMode logical = false
        bridgeProMode logical = false % Real Bridge Pro processing
        tSensorsV2Template table
        tSensorsV2 = []
        tLightsV2Template table
        tLightsV2 = []
        tSchedulesV2Template table
        tSchedulesV2 = []
        tTempsV2Template table
        tTempsV2 = []
        tUnreachableV2Template table
        tUnreachableV2 = []
        tGroupsV2Template table
        tGroupsV2 = []
        itGroupslightsV2Template table
        itGroupslightsV2 = []
        lightsRoomsTemplateV2 table
        allLightsTableV2 = []
        roomLightsTableV2 = []
        tScenesV2Template table
        tScenesV2 = []
        tRulesV2Template table
        tRulesV2 = []
        tRlinksV2Template table
        tRlinksV2 = []
        tLightsProTemplate table
        tLightsPro = []
        tSensorsProTemplate table
        tSensorsPro = []
        tTempsProTemplate table
        tTempsPro = []
        tUnreachableProTemplate table
        tUnreachablePro = []
        tSchedulesProTemplate table
        tSchedulesPro = []
        sunset
        currentX
        currentY
        sensorStatsV2Template table
        sensorStatsV2 = []
        sensorStatsProTemplate table
        sensorStatsPro = []
        TimerBusy logical = false
    end


    methods (Access = private)

        function errCode = getStats(app, request)

            tic

            addpath(genpath(app.dataRepository));

            errCode = 0;

            switch request
                case 'refresh'

                    if ~ app.offlineMode
                        [errCode, app.lastHueIP, app.appID, allDevices, allLights,   ...
                            allRules, allScripts, allSensors, allSensorTemps, allSensorMotions, ...
                            allSwitches, allPowerStates, ...
                            allGroups, allSchedules, allCapabilities, allConnectivity, ...
                            allResourceLinks, allScenes, config] = ...
                            connectToBridge(app);
                        app.OfflineMode.Tooltip = 'In On-line Mode.';
                    else
                        dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                        strDt = string(dt);
                        app.DiagnosticsTimeTextArea.Value = strDt;
                        app.DiagnosticsMsgTextArea.Value = '** Restarted in Offline Mode **';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg");
                        [allDevices, allLights, allRules, allScripts, allSensors, ...
                            allSensorTemps, allSensorMotions, allSwitches, allGroups, ...
                            allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                            allPowerStates, config] = loadAllData(app);
                        app.startup = false; % Prevent re-saving data
                        app.offlineMode = true;
                        app.successfulStart = true;
                        app.autoRefresh = false;
                        app.OfflineMode.Tooltip = 'In Off-line Mode.';
                    end

                    switch errCode
                        case 777
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Unable to look up the curent Hue IP address using https://discovery.meethue.com... (If using a VPN turn it off for the duration of this process **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            [allDevices, allLights, allRules, allScripts, allSensors, ...
                                allSensorTemps, allSensorMotions, allSwitches, allGroups, ...
                                allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                                allPowerStates, config] = loadAllData(app);
                            app.startup = false; % Prevent re-saving data
                            app.offlineMode = true;
                            app.successfulStart = true;
                            app.OfflineMode.Value = 1;
                            app.autoRefresh = false;
                            app.OfflineMode.Tooltip = 'In Off-line Mode.';
                        case 888
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Not connected to Bridge **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            return;
                        case 999
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** No Internet **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            return;
                        otherwise
                    end

                case 'startup'
                    app.responseLimit = 0;
                    app.prevMaxTemp = -999;
                    app.prevMinTemp = 999;
                    if ispc
                        app.fName = [app.dataRepository '\userNameHue.mat'];
                        app.diagFileName = [app.appFolder '\hueAppDiagnostics.mat'];
                    else
                        app.fName = [app.dataRepository '/userNameHue.mat'];
                        app.diagFileName = [app.appFolder '/hueAppDiagnostics.mat'];
                    end

                    try
                        fprintf('Checking that %s is readable...', app.fName);
                        load(app.fName, "hueUser");  % userNameHue
                        app.appID = hueUser;
                        fprintf('OK. \n');
                    catch EM
                        disp(EM.message)
                        disp(EM.cause);
                        fprintf('PROBLEM...Creating temporary dummy ID...will need to pair with Bridge.\n');
                        hueUser = 'dummy-need-to-pair'; % app.appID
                        save([app.fName],"hueUser");
                    end

                    if createTableSchemasV2(app)
                        disp ('All V2 table schemas created.');
                    end
                    % app.LightsTable.Visible ="off";
                    app.LightsTable.Data = app.allLightsTableV2(:,1:9);
                    app.LightsTable.Data = app.LightsTable.Data([],:); % clear the table
                    if createTableSchemasPro(app)
                        disp ('All Pro table schemas created.');
                    end

                    return;
                otherwise
            end

            app.cellSelIndices = [0 0];
            app.newBri = 0;
            app.prevEventName = 'none';
            app.editGroup = '';
            cd (app.dataRepository)
            app.IPAddress.Value = app.lastHueIP;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%% START OF MAIN PROCESSING %%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if app.vAPI == 1 % Bridge v2

                %%%
                %%% Check whether V2 table schemas need to be created
                %%%

                %%%

                app.hueIPAddressV2 = app.lastHueIP;
                app.BridgeID.Value = config.bridgeid;
                app.ZigbeeChannel.Value = config.zigbeechannel;
                app.APIversion.Value = config.apiversion;
                if config.swupdate2.autoinstall.on
                    app.AutoSWUpdate.Value = 'On';
                else
                    app.AutoSWUpdate.Value = 'Off';
                end
                % Convert to datetime using input format
                dt = datetime(config.swupdate2.bridge.lastinstall, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss');
                dt.Format = 'dd-MMM-yyyy HH:mm:ss';
                app.LastFirmwareUpdate.Value = string(dt);
                app.SWversion.Value = config.swversion;
                app.SWUpDateAvail.Value = config.swupdate2.bridge.state;
                if config.swupdate2.bridge.state == "noupdates"
                    app.SWUpDateAvail.Value = 'No updates available';
                else
                    if contains(config.swupdate2.bridge.state,'readytoinstall')
                        app.SWUpDateAvail.Value = 'Updates available' ;
                    else
                        app.SWUpDateAvail.Value = 'Unknown';
                    end
                end
                %%% Reset in case previously hidden by Bridge Pro view
                %%%
                app.BRSensors.Visible = "on";
                app.PCSensors.Visible = "on";
                app.BRGroups.Visible = "on";
                app.PCGroups.Visible = "on";
                app.BRRules.Visible = "on";
                app.PCRules.Visible = "on";
                app.BRScenes.Visible = "on";
                app.PCScenes.Visible = "off";
                app.BRSchedules.Visible = "on";
                app.PCSchedules.Visible = "on";
                app.BRResourceLinks.Visible = "on";
                app.PCResourceLinks.Visible = "on";
                %%%
                app.AVLights.Value = allCapabilities.lights.total - allCapabilities.lights.available;
                app.BRLights.Value = allCapabilities.lights.total;
                app.PCLights.Value = (allCapabilities.lights.total - allCapabilities.lights.available) ...
                    * 100 / allCapabilities.lights.total;
                app.AVSensors.Value = allCapabilities.sensors.total - allCapabilities.sensors.available;
                app.BRSensors.Value = allCapabilities.sensors.total;
                app.PCSensors.Value = (allCapabilities.sensors.total - allCapabilities.sensors.available) ...
                    * 100 / allCapabilities.sensors.total;
                app.AVScenes.Value = allCapabilities.scenes.total - allCapabilities.scenes.available;
                app.BRScenes.Value = allCapabilities.scenes.total;
                app.PCScenes.Value = (allCapabilities.scenes.total - allCapabilities.scenes.available) ...
                    * 100 / allCapabilities.scenes.total;
                app.AVSchedules.Value = allCapabilities.schedules.total - allCapabilities.schedules.available;
                app.BRSchedules.Value = allCapabilities.schedules.total;
                app.PCSchedules.Value = (allCapabilities.schedules.total - allCapabilities.schedules.available) ...
                    * 100 / allCapabilities.schedules.total;
                app.AVRules.Value = allCapabilities.rules.total - allCapabilities.rules.available;
                app.RulesLabel.Text = 'Rules';
                app.BRRules.Value = allCapabilities.rules.total;
                app.PCRules.Value = (allCapabilities.rules.total - allCapabilities.rules.available) ...
                    * 100 / allCapabilities.rules.total;
                app.AVResourceLinks.Value = allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available;
                app.ResourceLinksLabel.Text = 'Resource Links';
                app.ResourceLinksLabel.FontSize = 14;
                app.BRResourceLinks.Value = allCapabilities.resourcelinks.total;
                app.PCResourceLinks.Value = (allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available) ...
                    * 100 / allCapabilities.resourcelinks.total;

                %%%%
                %%%%  Sensors
                %%%%

                tableSensors = struct2table(allSensors);
                noSensors = size(tableSensors);
                fieldX = fieldnames(allSensors(1,1));

                % Set table dimensions
                sz = noSensors(1,2);
                app.tSensorsV2(sz,:) = app.tSensorsV2(1,:);

                % check whether sensor stats table has already been populated..
                if ismissing(app.sensorStatsV2.sensorname(1))
                    app.sensorStatsV2(sz,:) = app.sensorStatsV2(1,:);
                end


                app.tSensorsV2.sensorno = str2double(extractAfter(fieldX, 'x'));
                lowBattery = 0;
                maxTemp = -99;
                maxRoom = [];
                minTemp = 99;
                minRoom = [];
                warm = 0;
                veryWarm = 0;
                cool = 0;
                cold = 0;
                freezing = 0;
                tgt = 0;
                noTemps = 0;
                noMotion = 0;
                noDimmers = 0;
                for i = 1:noSensors(1,2)
                    try
                        battery = tableSensors.(fieldX{i,1}).config.battery;
                        app.tSensorsV2.battery(i) = tableSensors.(fieldX{i,1}).config.battery;
                        app.tSensorsV2.productname(i) = tableSensors.(fieldX{i,1}).productname;
                        app.tSensorsV2.modelID(i) = tableSensors.(fieldX{i,1}).modelid;
                        app.tSensorsV2.uniqueid(i) = tableSensors.(fieldX{i,1}).uniqueid;
                        try
                            app.tSensorsV2.lastevent(i) = tableSensors.(fieldX{i,1}).state.lastupdated;
                        catch
                            app.tSensorsV2.lastevent(i) = tableSensors.(fieldX{i,1}).swupdate.lastinstall;
                        end
                        if contains(app.tSensorsV2.productname(i),'motion')
                            noMotion = noMotion + 1;
                            if battery < 30
                                lowBattery = lowBattery + 1;
                            end
                        end
                        if contains(app.tSensorsV2.productname(i),'dimmer')
                            noDimmers = noDimmers + 1;
                            if battery < 30
                                lowBattery = lowBattery + 1;
                            end
                        end
                    catch
                    end
                    app.tSensorsV2.sensorname(i) = tableSensors.(fieldX{i,1}).name;
                    app.tSensorsV2.type(i) = tableSensors.(fieldX{i,1}).type;
                    if  contains(app.tSensorsV2.type(i) ,"Temperature")
                        app.tSensorsV2.temp(i) = tableSensors.(fieldX{i,1}).state.temperature/100;
                        noTemps = noTemps + 1;
                        if app.tSensorsV2.temp(i) > maxTemp
                            maxTemp = app.tSensorsV2.temp(i);
                            maxRoom = extractBetween(app.tSensorsV2.sensorname(i),'Sensor','Temp');
                        end
                        if app.tSensorsV2.temp(i) < minTemp
                            minTemp = app.tSensorsV2.temp(i);
                            minRoom = extractBetween(app.tSensorsV2.sensorname(i),'Sensor','Temp');
                        end
                        if app.tSensorsV2.temp(i) > 30
                            veryWarm = veryWarm + 1;
                        elseif app.tSensorsV2.temp(i) > 21
                            warm = warm + 1;
                        elseif app.tSensorsV2.temp(i) < 0
                            freezing = freezing + 1;
                        elseif app.tSensorsV2.temp(i) < 10
                            cold = cold + 1;
                        elseif app.tSensorsV2.temp(i) < 18
                            cool = cool + 1;
                        else
                            tgt = tgt + 1;
                        end
                    end
                end

                app.WASensors.Value = warm;
                app.HOSensors.Value = veryWarm;
                app.CLSensors.Value = cool;
                app.COSensors.Value = cold;
                app.FRSensors.Value = freezing;
                app.CMSensors.Value = tgt;
                if app.prevMaxTemp == -999
                    app.prevMaxTemp = maxTemp;
                end
                if app.prevMinTemp == 999
                    app.prevMinTemp = minTemp;
                end
                if maxTemp > app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom,...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                elseif maxTemp < app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                else
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(maxTemp,'%+2.2f'));
                end

                if minTemp > app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                elseif minTemp < app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                else
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(minTemp,'%+2.2f'));
                end

                app.SensorTemperaturesPanel.Title = append('Sensor Temperatures           Max ',...
                    num2str(maxTemp,'%+2.2f'), ' <-> Min ', num2str(minTemp,'%+2.2f'));
                app.MotionSensors.Text = num2str(noMotion);
                app.DimmerSwitches.Text = num2str(noDimmers);
                app.TotalDevices.Text =num2str(noMotion + noDimmers);
                noTempsnDimmers = noTemps + noDimmers;
                if noMotion == 1
                    app.MotionSensorsLabel.Text = 'Motion Sensor';
                else
                    app.MotionSensorsLabel.Text = 'Motion Sensors';
                end
                if noDimmers == 1
                    app.SwitchesLabel.Text = 'Switch';
                else
                    app.SwitchesLabel.Text = 'Switches';
                end

                % Set table dimensions
                sz = noTempsnDimmers + noMotion;
                app.tTempsV2(sz,:) = app.tTempsV2(1,:);
                app.tTempsV2.lastevent.TimeZone = '';

                j = 0;
                lastMotionIdx = 0;
                for i = 1:noSensors(1,2)
                    if  contains(app.tSensorsV2.type(i) ,"Temperature")
                        j = j + 1;
                        app.tTempsV2.temp(j) = num2str(app.tSensorsV2.temp(i),'%+2.2f°C');
                        app.tTempsV2.battery(j) = app.tSensorsV2.battery(i);
                        app.tTempsV2.sensorname(j) = strcat(app.tSensorsV2.sensorname(i), ' (', app.tSensorsV2.modelID(i), ')');
                        app.tTempsV2.lastevent(j) = app.tSensorsV2.lastevent(i);
                    elseif contains(app.tSensorsV2.type(i) ,"Switch")
                        j = j+ 1;
                        app.tTempsV2.temp(j) = '';
                        app.tTempsV2.battery(j) = app.tSensorsV2.battery(i);
                        app.tTempsV2.sensorname(j) = strcat(app.tSensorsV2.sensorname(i), ' (', app.tSensorsV2.modelID(i), ')');
                        app.tTempsV2.lastevent(j) = app.tSensorsV2.lastevent(i);
                    elseif contains(app.tSensorsV2.type(i) ,"Presence")
                        j = j+ 1;
                        app.tTempsV2.temp(j) = '';
                        app.tTempsV2.battery(j) = app.tSensorsV2.battery(i);
                        app.tTempsV2.sensorname(j) = strcat(app.tSensorsV2.sensorname(i), '/motion (', app.tSensorsV2.modelID(i), ')');
                        app.tTempsV2.lastevent(j) = app.tSensorsV2.lastevent(i);
                        if lastMotionIdx == 0 || app.tSensorsV2.lastevent(i) > lastMotion
                            lastMotion = app.tSensorsV2.lastevent(i);
                            lastMotionIdx = i;
                        end
                        % Gather stats for number of times the motion sensor is triggered
                        for s = 1:noSensors(1,2)
                            if app.sensorStatsV2.sensorname(s) == app.tSensorsV2.sensorname(i)
                                if app.tSensorsV2.lastevent(i) > app.sensorStatsV2.lastevent(s)
                                    app.sensorStatsV2.lastevent(s) = app.tSensorsV2.lastevent(i);
                                    app.sensorStatsV2.trigger(s) = app.sensorStatsV2.trigger(s) + 1;
                                end
                                break
                            elseif ismissing(app.sensorStatsV2.sensorname(s))
                                app.sensorStatsV2.sensorname(s) = app.tSensorsV2.sensorname(i);
                                app.sensorStatsV2.lastevent(s) = app.tSensorsV2.lastevent(i);
                                app.sensorStatsV2.trigger(s) = 0;
                                break
                            end
                        end
                    end
                end

                if lastMotionIdx > 0
                    lastMotionTime = datetime(lastMotion,"TimeZone","UTC");
                    lastMotionTime.TimeZone = 'Europe/London';
                    app.sensorNameLastMovement = app.tSensorsV2.sensorname(lastMotionIdx);
                    motionDate = extractBefore(string(lastMotionTime), " ");
                    motionTime = extractAfter(string(lastMotionTime), " ");
                    lastMotionText  = append('Last Motion detected by ', app.tSensorsV2.sensorname(lastMotionIdx),...
                        newline, 'on ', motionDate, ' at ', motionTime);
                    if strlength(app.tSensorsV2.sensorname(lastMotionIdx)) > 25
                        lastMotionFontsize = '16px';
                    else
                        lastMotionFontsize = '18px';
                    end
                    lastMotionFontcol = 'cyan';
                    lastMotionFontweight = 'bold';
                else
                    lastMotionText = 'No Motions detected.';
                    lastMotionFontsize = 14;
                    lastMotionFontcol = 'cyan';
                    lastMotionFontweight= 'normal';
                end
                app.HTMLLastMotion.Data = struct( ...
                    'message', lastMotionText, ...
                    'color', lastMotionFontcol, ...
                    'fontsize', lastMotionFontsize, ...
                    'fontweight', lastMotionFontweight, ...
                    'lineheight', '0.75', ...
                    'width', '385px', ...
                    'height', '37px', ...
                    'speed', '2s');


                for i =1:noSensors(1,2)
                    if ismissing(app.sensorStatsV2.sensorname(i))
                        break
                    end
                    for j = 1:noSensors(1,2)
                        if contains(app.tTempsV2.sensorname(j),append(app.sensorStatsV2.sensorname(i),'/'),"IgnoreCase",true) ...
                                && contains(app.tTempsV2.sensorname(j),'motion','IgnoreCase',true)
                            % app.tTempsV2.temp(j) = app.sensorStatsV2.trigger(i);
                            app.tTempsV2.temp(j) = sprintf('%05d', app.sensorStatsV2.trigger(i));
                            break
                        end
                    end
                end

                app.tTempsV2 = sortrows(app.tTempsV2,{'temp','sensorname'},{'descend', 'ascend'});
                app.tTempsV2.lastevent.TimeZone = 'UTC';
                app.tTempsV2.lastevent.TimeZone = 'Europe/London';

                app.SensorsTable.Data = app.tTempsV2;

                app.SensorsandSwitchesLabel.Text = '<html><p style="line-height:0.75;">Sensors and<br>Switches</p></html>';
                app.Sunset.Visible ="off";
                if lowBattery > 0
                    sz = [lowBattery 3];
                    varTypes = ["double", "string", "string"];
                    varNames = ["battery", "modelID", "sensorname"];
                    tLowBat = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                    j = 0;
                    for i = 1:noSensors(1,2)
                        try
                            battery = tableSensors.(fieldX{i,1}).config.battery;
                            if (contains(app.tSensorsV2.productname(i),'motion') ...
                                    || contains(app.tSensorsV2.productname(i),'dimmer')) ...
                                    && battery < 30
                                j = j + 1;
                                tLowBat.battery(j) = battery;
                                tLowBat.modelID(j) = tableSensors.(fieldX{i,1}).modelid;
                                tLowBat.sensorname(j) = tableSensors.(fieldX{i,1}).name;
                            end
                        catch
                        end
                    end
                    app.TableSensors.Data = sortrows(tLowBat);
                    s1 = uistyle('BackgroundColor','red');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    addStyle(app.TableSensors,s1,'column',1);
                    addStyle(app.TableSensors,s2,'column',1);
                    app.TableSensors.Visible = "on";
                    app.HTMLLowBatMsg.Visible = "on";
                    % Fast blink (1 second per cycle)
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', num2str(lowBattery), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % pulseText.html..(medium cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '⚠ Devices with a Battery Level below 30% ⚠', ...
                        'speed', '5s');
                else
                    % No blinking
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    app.TableSensors.Visible = "off";
                    app.HTMLLowBatMsg.Visible = "on";
                    % pulseText.html..(very slow cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '✔ All devices have a Battery Level above 30% ✔', ...
                        'color', 'green', ...
                        'speed', '10s');
                    if contains(string(app.sunset), ":")
                        app.Sunset.Visible ="on";
                        app.Sunset.Text = append("Sunset ", extractBetween(app.sunset,1,5));
                    end
                end

                %%%%
                %%%% Lights
                %%%%

                tableLights = struct2table(allLights);
                noLights = size(tableLights);
                fieldX = fieldnames(allLights(1,1));

                % Set table dimensions
                sz = noLights(1,2);
                app.tLightsV2(sz,:) = app.tLightsV2(1,:);
                app.tLightsV2.lightno = str2double(extractAfter(fieldX, 'x'));

                lightsReachable = 0;
                app.lightsOn = 0;

                for i = 1:noLights(1,2)
                    app.tLightsV2.model(i) = tableLights.(fieldX{i,1}).modelid;
                    app.tLightsV2.productname(i) = tableLights.(fieldX{i,1}).productname;
                    app.tLightsV2.lightname(i) = tableLights.(fieldX{i,1}).name;
                    app.tLightsV2.type(i) = tableLights.(fieldX{i,1}).type;
                    app.tLightsV2.reachable(i) = tableLights.(fieldX{i,1}).state.reachable;
                    app.tLightsV2.uniqueid(i) = tableLights.(fieldX{i,1}).uniqueid;
                    app.tLightsV2.bright(i) = tableLights.(fieldX{i,1}).state.bri;
                    try
                        app.tLightsV2.colormode(i) = tableLights.(fieldX{i,1}).state.colormode;
                    catch
                        app.tLightsV2.colormode(i) = "white";
                    end
                    % xy colours..
                    if app.tLightsV2.colormode(i) == "xy" || app.tLightsV2.colormode(i) == "hs"
                        app.tLightsV2.x(i) = tableLights.(fieldX{i,1}).state.xy(1,1);
                        app.tLightsV2.y(i) = tableLights.(fieldX{i,1}).state.xy(2,1);
                    else % not a colour bulb so default to cool white
                        app.tLightsV2.colormode(i) = "white";
                        app.tLightsV2.x(i) = 0.3127;
                        app.tLightsV2.y(i) = 0.3290;
                    end
                    if app.tLightsV2.reachable(i)
                        lightsReachable = lightsReachable + 1;
                    end
                    app.tLightsV2.on(i) = tableLights.(fieldX{i,1}).state.on;
                    if app.tLightsV2.on(i)
                        app.lightsOn = app.lightsOn + 1;
                    end
                end

                unreachable = noLights(1,2) - lightsReachable;
                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);

                if unreachable > 0

                    % Set table dimensions
                    sz = unreachable;
                    app.tUnreachableV2(sz,:) = app.tUnreachableV2(1,:);

                    j = 0;
                    for i = 1:noLights(1,2)
                        if ~ app.tLightsV2.reachable(i)
                            j = j + 1;
                            app.tUnreachableV2.ID(j) = app.tLightsV2.model(i);
                            app.tUnreachableV2.lightname(j) =  app.tLightsV2.lightname(i);
                        end
                    end

                    app.TableLights.Data = app.tUnreachableV2;
                    s1 = uistyle('BackgroundColor','white');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    s2.FontWeight = 'bold';
                    addStyle(app.TableLights,s1,'column',1);
                    addStyle(app.TableLights,s2,'column',1);
                    app.TableLights.Visible = "on";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.Interpreter = 'none';
                    app.UnreachableLightsLabel.Tooltip =  'This section is only presented when there ARE Unreachable Lights.';
                    % Fast blink (1 second per cycle)
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', num2str(unreachable), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % Fast blink (1 second per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '⚠ Unreachable Lights ⚠', ...
                        'color', 'red', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '1s');
                else
                    app.TableLights.Visible = "off";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.FontColor = "white";
                    app.UnreachableLightsLabel.Tooltip = 'This section is only presented when there are NO Unreachable Lights.';
                    app.UnreachableLightsLabel.BackgroundColor = "none";
                    app.UnreachableLightsLabel.FontWeight = "bold";
                    app.UnreachableLightsLabel.FontSize = 16;
                    app.UnreachableLightsLabel.HorizontalAlignment = 'left';
                    app.UnreachableLightsLabel.Interpreter = 'html';
                    app.UnreachableLightsLabel.Text  = ...
                        append('<html><p style="line-height:1;"><font color="white"><b><br>', ...
                        maxTempText, '<br><br>', ...
                        minTempText, '</b></font></p>');
                    % No blinking
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    % Very slow blink (10 seconds per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '℃ Temperature trends since last Refresh ℃', ...
                        'color', 'white', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '10s');
                end

                %%%%
                %%%% Schedules
                %%%%

                tableSchedules = struct2table(allSchedules);
                noSchedules = size(tableSchedules);
                fieldX = fieldnames(allSchedules(1,1));

                % Set table dimensions
                sz = noSchedules(1,2);
                app.tSchedulesV2(sz,:) = app.tSchedulesV2(1,:);


                app.tSchedulesV2.scheduleno = str2double(extractAfter(fieldX, 'x'));
                enabledSchedules = 0;
                for i = 1:noSchedules(1,2)
                    app.tSchedulesV2.desc(i) = tableSchedules.(fieldX{i,1}).description;
                    status = tableSchedules.(fieldX{i,1}).status;
                    if status == "enabled"
                        enabledSchedules = enabledSchedules + 1;
                    end
                    app.tSchedulesV2.status(i) = tableSchedules.(fieldX{i,1}).status;
                    app.tSchedulesV2.schedulename(i) = tableSchedules.(fieldX{i,1}).name;
                    app.tSchedulesV2.created(i) = tableSchedules.(fieldX{i,1}).created;
                    try
                        app.tSchedulesV2.starttime(i) = tableSchedules.(fieldX{i,1}).starttime;
                    catch
                    end
                end
                app.ONSchedules.Value = enabledSchedules;

                %%%%
                %%%% Groups
                %%%%

                tableGroups = struct2table(allGroups);
                noGroups = size(tableGroups);
                fieldX = fieldnames(allGroups(1,1));

                app.AVGroups.Value = noGroups(1,2); % the actual number of groups in use rather than the value from allCapabilities
                app.BRGroups.Value = allCapabilities.groups.total;
                app.PCGroups.Value =  app.AVGroups.Value * 100 / app.BRGroups.Value;

                % Set table dimensions
                sz = noGroups(1,2);
                app.tGroupsV2(sz,:) = app.tGroupsV2(1,:);

                app.tGroupsV2.groupno = str2double(extractAfter(fieldX, 'x'));
                noGroupLights = 0;
                grplightsOn = 0;
                app.roomLightsOn = 0;
                for i = 1:noGroups(1,2)
                    try
                        app.tGroupsV2.lights(i) = strjoin(tableGroups.(fieldX{i,1}).lights, ', ');
                        x = size(tableGroups.(fieldX{i,1}).lights);
                        app.tGroupsV2.nolights(i) = x(1,1);
                        noGroupLights = noGroupLights + x(1,1);
                    catch
                        app.tGroupsV2.lights(i) = "none";
                    end
                    app.tGroupsV2.type(i) = tableGroups.(fieldX{i,1}).type;
                    app.tGroupsV2.groupname(i) = tableGroups.(fieldX{i,1}).name;
                    app.tGroupsV2.class(i) = tableGroups.(fieldX{i,1}).class;
                    app.tGroupsV2.allon(i) = tableGroups.(fieldX{i,1}).state.all_on;
                    app.tGroupsV2.anyon(i) = tableGroups.(fieldX{i,1}).state.any_on;
                    if app.tGroupsV2.anyon(i)
                        grplightsOn = grplightsOn + 1;
                        if app.tGroupsV2.type(i) == "Room"
                            app.roomLightsOn = app.roomLightsOn + 1;
                        end
                    end
                end

                % Set table dimensions
                sz = noGroupLights;
                app.itGroupslightsV2(sz,:) = app.itGroupslightsV2(1,:);

                l = 0;
                for i = 1:noGroups(1,2)
                    if app.tGroupsV2.nolights(i,1) > 0
                        for j = 1:app.tGroupsV2.nolights(i)
                            l = l + 1;
                            app.itGroupslightsV2.groupno(l) = app.tGroupsV2.groupno(i,1);
                            app.itGroupslightsV2.groupname(l) = app.tGroupsV2.groupname(i,1);
                            app.itGroupslightsV2.grouptype(l) = app.tGroupsV2.type(i,1);
                            app.itGroupslightsV2.lightno(l) =  str2double(tableGroups.(fieldX{i,1}).lights{j,1});
                        end
                    end
                end

                tGroupsLights = join(app.itGroupslightsV2, app.tLightsV2);

                app.OnGroups.Value = grplightsOn;

                tGroupsLights = sortrows(tGroupsLights,[10 11 2],["descend" "descend" "ascend"]);

                % Set table dimensions
                sz = noGroupLights;
                app.allLightsTableV2(sz,:) = app.allLightsTableV2(1,:);

                sz = app.roomLightsOn;
                if sz > 0 % skip if no lights on
                    app.roomLightsTableV2 = app.lightsRoomsTemplateV2;
                    app.roomLightsTableV2(sz,:) = app.roomLightsTableV2(1,:);
                else
                    app.roomLightsTableV2(:,:) = [];
                end


                app.allLightsTableV2.groupname = tGroupsLights.groupname;
                app.allLightsTableV2.grouptype = tGroupsLights.grouptype;
                app.allLightsTableV2.lightno = tGroupsLights.lightno;
                app.allLightsTableV2.lightname = tGroupsLights.lightname;
                app.allLightsTableV2.productname = tGroupsLights.productname;
                app.allLightsTableV2.model = tGroupsLights.model;
                app.allLightsTableV2.state = tGroupsLights.on;
                app.allLightsTableV2.colour(:) = "white";
                app.allLightsTableV2.bright = max(round(tGroupsLights.bright/2.54),1);
                app.allLightsTableV2.x = tGroupsLights.x;
                app.allLightsTableV2.y = tGroupsLights.y;
                app.allLightsTableV2.colormode = tGroupsLights.colormode;

                j = 0;
                for i = 1:noGroupLights
                    if app.allLightsTableV2.grouptype(i) == "Room" && app.allLightsTableV2.state(i) == true
                        j = j + 1;
                        app.roomLightsTableV2(j,:) = app.allLightsTableV2(i,:);
                    end
                end

                if app.lightsOnRoomsOnly
                    app.roomLightsTableV2 = sortrows(app.roomLightsTableV2);
                    app.LightsTable.Data = app.roomLightsTableV2(:,1:9);
                else
                    app.allLightsTableV2 = sortrows(app.allLightsTableV2);
                    app.LightsTable.Data = app.allLightsTableV2(:,1:9);
                end

                % Clear all previous styles (To prevent performance degredation)
                removeStyle(app.LightsTable);

                updateONGroupsRooms(app);

                fmt = '%02d';

                lightsInGrid = size(app.LightsTable.Data,1);

                % Pre-create reusable style objects
                styleWhiteBG   = uistyle('BackgroundColor','white');
                styleBlackFont = uistyle('FontColor','black');
                styleWhiteFont = uistyle('FontColor','white');

                for i = 1:lightsInGrid

                    if app.lightsOnRoomsOnly
                        mode = app.roomLightsTableV2.colormode(i);
                        x    = app.roomLightsTableV2.x(i);
                        y    = app.roomLightsTableV2.y(i);
                        bri  = max(round(app.roomLightsTableV2.bright(i) * 2.54),1);
                    else
                        mode = app.allLightsTableV2.colormode(i);
                        x    = app.allLightsTableV2.x(i);
                        y    = app.allLightsTableV2.y(i);
                        bri  = max(round(app.allLightsTableV2.bright(i) * 2.54),1);
                    end

                    if mode ~= "white"

                        rgb = hueXYtoRGB(app,x,y,bri);
                        [~, colourName] = findNearestCssColourName(app, rgb);
                        app.LightsTable.Data{i,9} = append( ...
                            colourName, ...
                            ' [', num2str(round(x*100,0),fmt), ...
                            ',', num2str(round(y*100,0),fmt), ']' );

                        % Reuse style object for background
                        sBG = uistyle('BackgroundColor', rgb);
                        addStyle(app.LightsTable, sBG, 'cell', [i 9]);

                        % Reuse font styles
                        if sum(rgb) == 0
                            addStyle(app.LightsTable, styleWhiteFont, 'cell', [i 8]);
                        else
                            addStyle(app.LightsTable, styleBlackFont, 'cell', [i 8]);
                        end

                    else
                        % White mode
                        addStyle(app.LightsTable, styleWhiteBG, 'cell', [i 9]);
                    end
                end

                highlightOnLights(app);


                %%%%
                %%%% Scenes
                %%%%

                tableScenes = struct2table(allScenes);
                noScenes = size(tableScenes);
                fieldX = fieldnames(allScenes(1,1));

                % Set table dimensions
                sz = noScenes(1,2);
                app.tScenesV2(sz,:) = app.tScenesV2(1,:);

                app.tScenesV2.sceneID = replace(fieldX, '_', '-');

                for i = 1:noScenes(1,2)
                    app.tScenesV2.scenename(i) = tableScenes.(fieldX{i,1}).name;
                    app.tScenesV2.type(i) = tableScenes.(fieldX{i,1}).type;
                    app.tScenesV2.lastupdate(i) = tableScenes.(fieldX{i,1}).lastupdated;
                    try
                        app.tScenesV2.group(i) = tableScenes.(fieldX{i,1}).group;
                    catch
                        app.tScenesV2.group(i) = 'n/a';
                    end
                    try
                        app.tScenesV2.lights(i) = strjoin(tableScenes.(fieldX{i,1}).lights, ', ');
                    catch
                        app.tScenesV2.lights(i) = 'none';
                    end
                end

                %%%%
                %%%% Rules
                %%%%

                tableRules = struct2table(allRules);
                noRules = size(tableRules);

                fieldX = fieldnames(allRules(1,1));

                % Set table dimensions
                sz = noRules(1,2);
                app.tRulesV2(sz,:) = app.tRulesV2(1,:);

                app.tRulesV2.ruleno = str2double(extractAfter(fieldX, 'x'));
                rulesEnabled = 0;
                rulesNotTrig = 0;

                for i = 1:noRules(1,2)
                    app.tRulesV2.created(i) = tableRules.(fieldX{i,1}).created;
                    if tableRules.(fieldX{i,1}).lasttriggered ~= "none"
                        app.tRulesV2.lasttriggered(i) = tableRules.(fieldX{i,1}).lasttriggered;
                    else
                        app.tRulesV2.lasttriggered(i) = tableRules.(fieldX{i,1}).created; % default to date created
                    end
                    app.tRulesV2.timestriggered(i) = tableRules.(fieldX{i,1}).timestriggered;
                    if app.tRulesV2.timestriggered(i) == 0
                        rulesNotTrig = rulesNotTrig + 1;
                    end
                    status = tableRules.(fieldX{i,1}).status;
                    if status == "enabled"
                        rulesEnabled = rulesEnabled + 1;
                    end
                    app.tRulesV2.status(i) = tableRules.(fieldX{i,1}).status;
                    app.tRulesV2.action(i) = tableRules.(fieldX{i,1}).actions.address;
                    app.tRulesV2.rulename(i) = tableRules.(fieldX{i,1}).name;
                    app.tRulesV2.groupno(i)  = 0;
                    app.tRulesV2.sensorno(i)  = 0;
                    app.tRulesV2.scheduleno(i) = 0;

                    grF = count(app.tRulesV2.action(i),"groups/");
                    grFx = count(app.tRulesV2.rulename(i),"Group");
                    scF = count(app.tRulesV2.action(i),"scenes/");
                    seF = count(app.tRulesV2.action(i),"sensors/");
                    seFx = count(app.tRulesV2.rulename(i),"Sensor");
                    diF = count(app.tRulesV2.rulename(i),"Dimmer");
                    shF = count(app.tRulesV2.action(i),"schedules/");
                    xfdF = count(app.tRulesV2.rulename(i),"XFD");

                    %
                    % Default in case no better Description extracted in following section
                    %
                    if seF > 0
                        app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.action(i),"sensors/","/");
                    end

                    if grF > 0
                        app.tRulesV2.groupno(i) = extractBetween(app.tRulesV2.action(i),"groups/","/action");
                        if app.tRulesV2.groupno(i) == 0
                            if seFx > 0
                                app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"Sensor ",".");
                            elseif diF > 0
                                app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"Dimmer","Btn");
                            end
                        end
                    elseif grFx > 0
                        app.tRulesV2.groupno(i) = extractAfter(app.tRulesV2.rulename(i),"Group");
                    elseif scF > 0
                        if seFx > 0
                            app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"Sensor ",".");
                        end
                    elseif seF > 0
                        if seFx > 0
                            app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"Sensor ",".");
                        elseif diF > 0
                            if contains(app.tRulesV2.rulename(i),"Switch")
                                app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"Switch "," ");
                            else
                                app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"Dimmer ",".");
                            end
                        elseif xfdF > 0
                            if contains(app.tRulesV2.rulename(i),"XFDMotion")
                                try
                                    app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"XFDMotion",".");
                                catch
                                    app.tRulesV2.sensorno(i)  = extractAfter(app.tRulesV2.rulename(i),"XFDMotion");
                                end
                            end
                        end
                    elseif shF > 0
                        app.tRulesV2.scheduleno(i)  = extractAfter(app.tRulesV2.action(i),"schedules/");
                        if contains(app.tRulesV2.rulename(i),"Dimmer Switch")
                            app.tRulesV2.sensorno(i)  = extractBetween(app.tRulesV2.rulename(i),"Switch "," ");
                        end
                    end
                end

                %%%%
                %%%% Resource Links
                %%%%

                tableResourceLinks = struct2table(allResourceLinks);
                noRLs = size(tableResourceLinks);
                fieldX = fieldnames(allResourceLinks(1,1));

                % Set table dimensions
                sz = noRLs(1,2);
                app.tRlinksV2(sz,:) = app.tRlinksV2(1,:);

                app.tRlinksV2.rlId = fieldX;

                for i = 1:noRLs(1,2)
                    app.tRlinksV2.rlname(i) = tableResourceLinks.(fieldX{i,1}).name;
                    app.tRlinksV2.desc(i) = tableResourceLinks.(fieldX{i,1}).description;
                    app.tRlinksV2.links(i) = strjoin(tableResourceLinks.(fieldX{i,1}).links, ', ');
                    app.tRlinksV2.links(i) = strcat(app.tRlinksV2.links(i), ','); % add a final delimiter
                end

                %%%
                %%% Expand Rules table with Groups, Sensors, Schedules, Scenes & Resource Links data
                %%%

                for i = 1:noRules(1,2)

                    app.tRulesV2.RL(i) = '*';
                    rlMatch = ['/rules/' num2str(app.tRulesV2.ruleno(i)) ','];
                    for n = 1:noRLs(1,2)
                        if contains(app.tRlinksV2.links(n), rlMatch)
                            app.tRulesV2.RL(i) = app.tRlinksV2.desc(n);
                            break;
                        end
                    end

                    nxt = false;
                    for j= 1:noGroups(1,2)
                        if app.tRulesV2.groupno(i) > 0 && app.tRulesV2.groupno(i) == app.tGroupsV2.groupno(j)
                            app.tRulesV2.groupdesc(i) = app.tGroupsV2.groupname(j);
                            app.tRulesV2.target(i) = "Group";
                            app.tRulesV2.id(i) = app.tGroupsV2.groupno(j);
                            app.tRulesV2.description(i) = app.tGroupsV2.groupname(j);
                            app.tRulesV2.location(i) = app.tGroupsV2.groupname(j);
                            app.tRulesV2.sensordesc(i) = '*';
                            app.tRulesV2.scheduledesc(i) = '*';
                            app.tRulesV2.scenedesc(i) = '*';
                            nxt = true;
                            break; % only need one match.. (optimisation)
                        end
                    end
                    if nxt
                        continue; % process next rule.. (optimisation)
                    end
                    for k = 1:noSensors(1,2)
                        if app.tRulesV2.sensorno(i) == app.tSensorsV2.sensorno(k)
                            app.tRulesV2.sensordesc(i) = app.tSensorsV2.sensorname(k);
                            if contains(app.tSensorsV2.sensorname(k),"Dimmer")
                                app.tRulesV2.target(i) = "Dimmer";
                                app.tRulesV2.location(i) = extractAfter(app.tSensorsV2.sensorname(k),"Dimmer ");
                            else
                                app.tRulesV2.target(i) = "Sensor";
                                app.tRulesV2.location(i) = extractAfter(app.tSensorsV2.sensorname(k),"Sensor ");
                            end
                            if contains(app.tRulesV2.location(i), digitsPattern)
                                app.tRulesV2.location(i) = extractAfter(app.tRulesV2.RL(i),"Dimmer ");
                            end
                            if ismissing(app.tRulesV2.location(i))
                                app.tRulesV2.location(i) = extractAfter(app.tRulesV2.RL(i),"Dimmer ");
                            end
                            if ismissing(app.tRulesV2.location(i))
                                app.tRulesV2.location(i) = extractAfter(app.tRulesV2.RL(i),"Sensor ");
                            end
                            app.tRulesV2.id(i) = app.tSensorsV2.sensorno(k);
                            app.tRulesV2.description(i) = app.tSensorsV2.sensorname(k);
                            if ismissing(app.tRulesV2.location(i))
                                app.tRulesV2.location(i) = app.tRulesV2.description(i);
                            end
                            app.tRulesV2.groupdesc(i) = '*';
                            app.tRulesV2.scheduledesc(i) = '*';
                            app.tRulesV2.scenedesc(i) = '*';
                            nxt = true;
                            break;
                        end
                    end
                    if nxt
                        continue;
                    end
                    for l = 1:noSchedules(1,2)
                        if app.tRulesV2.scheduleno(i) == app.tSchedulesV2.scheduleno(l)
                            app.tRulesV2.scheduledesc(i) = app.tSchedulesV2.schedulename(l);
                            app.tRulesV2.target(i) = "Schedule";
                            app.tRulesV2.id(i) = app.tSchedulesV2.scheduleno(l);
                            app.tRulesV2.description(i) = app.tSchedulesV2.schedulename(l);
                            app.tRulesV2.sensordesc(i) = '*';
                            app.tRulesV2.groupdesc(i) = '*';
                            app.tRulesV2.scenedesc(i) = '*';
                            nxt = true;
                            break;
                        end
                    end
                    if nxt
                        continue;
                    end
                    for m = 1:noScenes(1,2)
                        if extractAfter(app.tRulesV2.action(i), '/scenes/') == app.tScenesV2.sceneID(m)
                            app.tRulesV2.scenedesc(i) = strcat(app.tScenesV2.scenename(m)," / ",app.tScenesV2.type(m));
                            app.tRulesV2.target(i) = "Scene";
                            app.tRulesV2.id(i) = app.tScenesV2.sceneID(m);
                            app.tRulesV2.description(i) = strcat(app.tScenesV2.scenename(m)," / ",app.tScenesV2.type(m));
                            app.tRulesV2.sensordesc(i) = '*';
                            app.tRulesV2.groupdesc(i) = '*';
                            app.tRulesV2.scheduledesc(i) = '*';
                            nxt = true;
                            break;
                        end
                    end
                    if not(nxt) % no match found for any Rules category..
                        app.tRulesV2.target(i) = "none";
                        app.tRulesV2.id(i) = 0;
                        app.tRulesV2.description(i) = '*';
                        app.tRulesV2.location(i) = '*';
                        app.tRulesV2.sensordesc(i) = '*';
                        app.tRulesV2.groupdesc(i) = '*';
                        app.tRulesV2.scheduledesc(i) = '*';
                        app.tRulesV2.scenedesc(i) = '*';
                    end
                end

                tRsorted = sortrows(app.tRulesV2,{'timestriggered','lasttriggered','created'},{'descend', 'descend','descend'});
                app.RulesTable.Data = tRsorted(:,1:12);
                app.ONRules.Value = rulesEnabled;
                app.ONRules.Visible = "on";
                app.NeverTriggered.Value = rulesNotTrig;
                app.RulesLabel_2.Text = "Rules";
                app.RulesLabel_2.FontSize = 36;
                app.inTimesTriggeredOrderLabel.Visible = "on";
                app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Visible = "on";
                app.NeverTriggered.Visible = "on";

                % Only save extracted Bridge data at startup
                if app.startup
                    app.startup = false;
                    saveAllData (app, [], allLights, allRules, [], allSensors, [], [], [], allGroups, ...
                        allSchedules, allCapabilities, allConnectivity, allResourceLinks, ...
                        allScenes, [], config)
                else
                    dt = datetime('now', 'Format', 'dd-MMM-yyyy HH:mm:ss');
                    strDt = string(dt);
                    app.LastBridgeDataRefreshTextArea.Value = strDt;
                end
            else

                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
                %%% >>> Bridge Pro
                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>


                %%%

                app.hueIPAddressPro = app.lastHueIP;
                app.appKey = app.appID; % keep them separate
                if ~ app.offlineMode
                    config = getBridgeConfigPro(app, app.lastHueIP);
                    if ispc
                        save([app.dataRepository '\config.mat'],"config");
                    else
                        save([app.dataRepository '/config.mat'],"config");
                    end
                end
                app.BridgeID.Value = config.bridge_id;
                app.SWversion.Value = config.sw_version;
                app.ZigbeeChannel.Value = str2double(config.zigbee_channel);
                app.AVLights.Value = size(allLights, 1);
                app.BRLights.Value = 186;
                app.PCLights.Value = app.AVLights.Value * 100 / 186;
                states = cellfun(@(x) x.on.on, allLights);
                app.ONLights.Value = sum(states);
                app.AVSensors.Value = size(allSensors,1);
                app.BRSensors.Value = 0;
                app.PCSensors.Value = 0;
                app.AVGroups.Value = size(allGroups,1);
                app.OnGroups.Value = 0;
                app.BRGroups.Value = 0;
                app.PCGroups.Value = 0;
                app.AVScenes.Value = 0;
                app.BRScenes.Value = 0;
                app.PCScenes.Value = 0;
                app.AVSchedules.Value = 0;
                app.BRSchedules.Value = 0;
                app.ONSchedules.Value = 0;
                app.PCSchedules.Value = 0;
                app.AVRules.Value = size(allScripts,1);
                app.RulesLabel.Text = 'Scripts';
                app.BRRules.Value = 0;
                app.ONRules.Value = 0;
                app.PCRules.Value = 0;
                app.ResourceLinksLabel.FontSize = 12;
                app.BRResourceLinks.Value = 0;
                app.PCResourceLinks.Value = 0;

                %%% Hide Bridge Pro stats that are (currently) not
                %%% disclosed via v2 API
                app.BRSensors.Visible = "off";
                app.PCSensors.Visible = "off";
                app.BRGroups.Visible = "off";
                app.PCGroups.Visible = "off";
                app.BRRules.Visible = "off";
                app.PCRules.Visible = "off";
                app.ONRules.Visible = "off";
                app.BRScenes.Visible = "off";
                app.PCScenes.Visible = "off";
                app.BRSchedules.Visible = "off";
                app.PCSchedules.Visible = "off";
                app.BRResourceLinks.Visible = "off";
                app.PCResourceLinks.Visible = "off";
                %%%

                noSensorsTemp = size(allSensorTemps,1);
                noSensorsMotion = size(allSensorMotions,1);
                noPowerStates = size(allPowerStates,1);
                noDevices = size(allDevices,1);
                noConnections = size(allConnectivity,1);
                noGroups = size(allGroups,1);
                noSwitches = size(allSwitches,1);


                %%%%
                %%%%  Sensors Bridge Pro
                %%%%

                lowBattery = 0;

                % for i = 1:noDevices
                %     if contains(allDevices{i}.product_data.product_name, 'dimmer','IgnoreCase',true)
                %         noDimmers = noDimmers + 1;
                %     end
                % end

                noDimmers = sum(contains(string(cellfun(@(d) d.product_data.product_name, allDevices, ...
                    'UniformOutput', false)), "dimmer", 'IgnoreCase', true));

                noSensors = noSensorsTemp + noSensorsMotion + noDimmers;

                % Set table dimensions
                app.tSensorsPro(noSensors,:) = app.tSensorsPro(1,:);

                % check whether sensor stats table has already been populated..
                if ismissing(app.sensorStatsPro.sensorname(1))
                    app.sensorStatsPro(noSensors,:) = app.sensorStatsPro(1,:);
                    app.sensorStatsPro.lastevent.TimeZone = 'UTC';
                end

                dIdx = 0;
                app.tSensorsPro.lastevent.TimeZone = 'UTC';
                app.tTempsPro.lastevent.TimeZone = 'Europe/London';
                for i = 1:noDevices
                    if contains(allDevices{i}.product_data.product_name, 'dimmer','IgnoreCase',true)
                        dIdx = dIdx + 1;
                        for s = 1:size(allDevices{i}.services,1)
                            if allDevices{i}.services(s).rtype == "device_power"
                                app.tSensorsPro.uniqueid(dIdx) = allDevices{i}.services(s).rid;
                                break;
                            end
                        end
                        app.tSensorsPro.type(dIdx) =  "switch";
                        app.tSensorsPro.productname(dIdx) = allDevices{i}.product_data.product_name;
                        app.tSensorsPro.modelID(dIdx) = allDevices{i}.product_data.model_id;
                        app.tSensorsPro.sensorname(dIdx) = allDevices{i}.metadata.name;

                        lastButtonEvent = "1900-01-01T00:00:00.000Z";

                        for s = 1:noSwitches
                            if allSwitches{s}.owner.rid == allDevices{i}.id
                                %
                                % Details are lost after Hue v2 API software update
                                % ..but rebuilt after any subsequent button push
                                %
                                try
                                    updated = string(allSwitches{s}.button.button_report.updated);
                                    if updated > lastButtonEvent
                                        lastButtonEvent = updated;
                                    end
                                catch
                                end
                            end
                        end
                        dt = datetime(lastButtonEvent, ...
                            'InputFormat','yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', ...
                            'TimeZone','UTC');
                        dt.TimeZone = 'Europe/London';
                        app.tSensorsPro.lastevent(dIdx) = dt;

                        for p = 1:noPowerStates
                            if allPowerStates{p}.id == app.tSensorsPro.uniqueid(dIdx)
                                app.tSensorsPro.battery(dIdx) =  allPowerStates{p}.power_state.battery_level;
                                if app.tSensorsPro.battery(dIdx) < 30
                                    lowBattery = lowBattery + 1;
                                end
                                break;
                            end
                        end
                    end
                end

                maxTemp = -99;
                maxRoom = [];
                minTemp = 99;
                minRoom = [];
                warm = 0;
                veryWarm = 0;
                cool = 0;
                cold = 0;
                freezing = 0;
                tgt = 0;
                noTemps = 0;

                app.MotionSensors.Text = num2str(noSensorsMotion);
                if noSensorsMotion == 1
                    app.MotionSensorsLabel.Text = 'Motion Sensor';
                else
                    app.MotionSensorsLabel.Text = 'Motion Sensors';
                end
                app.DimmerSwitches.Text = num2str(noDimmers);
                if noDimmers == 1
                    app.SwitchesLabel.Text = 'Switch';
                else
                    app.SwitchesLabel.Text = 'Switches';
                end
                app.TotalDevices.Text =num2str(noSensorsMotion + noDimmers);

                % Set table dimensions
                app.tTempsPro(noSensors,:) = app.tTempsPro(1,:);

                app.tSensorsPro.lastevent.TimeZone = 'UTC';
                app.tTempsPro.lastevent.TimeZone = 'Europe/London';

                for i = 1:noSensorsTemp
                    for d = 1:noDevices
                        if allDevices{d}.id == allSensorTemps{i}.owner.rid
                            dIdx = dIdx + 1;
                            app.tSensorsPro.productname(dIdx) = allDevices{d}.product_data.product_name;
                            app.tSensorsPro.modelID(dIdx) = allDevices{d}.product_data.model_id;
                            app.tSensorsPro.sensorname(dIdx) = allDevices{d}.metadata.name;
                            break;
                        end
                    end
                    app.tSensorsPro.uniqueid(dIdx) = allSensorTemps{i}.owner.rid;
                    for p = 1:noPowerStates
                        if allPowerStates{p}.owner.rid == allSensorTemps{i}.owner.rid
                            app.tSensorsPro.battery(dIdx) =  allPowerStates{p}.power_state.battery_level;
                            if app.tSensorsPro.battery(dIdx) < 30
                                lowBattery = lowBattery + 1;
                            end
                            break;
                        end
                    end
                    app.tSensorsPro.lastevent(dIdx) = datetime( ...
                        allSensorTemps{i}.temperature.temperature_report.changed, ...
                        'InputFormat','yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', ...
                        'TimeZone','UTC', ...
                        'Format','dd/MM/yy HH:mm:ss');
                    app.tSensorsPro.type(dIdx) =  allSensorTemps{i}.type;
                    app.tSensorsPro.temp(dIdx) = allSensorTemps{i}.temperature.temperature;
                    noTemps = noTemps + 1;
                    if app.tSensorsPro.temp(dIdx) > maxTemp
                        maxTemp = app.tSensorsPro.temp(dIdx);
                        maxRoom = extractAfter(app.tSensorsPro.sensorname(dIdx),'Sensor ');
                    end
                    if app.tSensorsPro.temp(dIdx) < minTemp
                        minTemp = app.tSensorsPro.temp(dIdx);
                        minRoom = extractAfter(app.tSensorsPro.sensorname(dIdx),'Sensor ');
                    end
                    if app.tSensorsPro.temp(dIdx) > 30
                        veryWarm = veryWarm + 1;
                    elseif app.tSensorsPro.temp(dIdx) > 21
                        warm = warm + 1;
                    elseif app.tSensorsPro.temp(dIdx) < 0
                        freezing = freezing + 1;
                    elseif app.tSensorsPro.temp(dIdx) < 10
                        cold = cold + 1;
                    elseif app.tSensorsPro.temp(dIdx) < 18
                        cool = cool + 1;
                    else
                        tgt = tgt + 1;
                    end
                end

                app.WASensors.Value = warm;
                app.HOSensors.Value = veryWarm;
                app.CLSensors.Value = cool;
                app.COSensors.Value = cold;
                app.FRSensors.Value = freezing;
                app.CMSensors.Value = tgt;
                if app.prevMaxTemp == -999
                    app.prevMaxTemp = maxTemp;
                end
                if app.prevMinTemp == 999
                    app.prevMinTemp = minTemp;
                end
                if maxTemp > app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom,...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                elseif maxTemp < app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                else
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(maxTemp,'%+2.2f'));
                end

                if minTemp > app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                elseif minTemp < app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                else
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(minTemp,'%+2.2f'));
                end

                app.SensorTemperaturesPanel.Title = append('Sensor Temperatures           Max ',...
                    num2str(maxTemp,'%+2.2f'), ' <-> Min ', num2str(minTemp,'%+2.2f'));

                j = dIdx;
                for i = 1:noSensorsMotion
                    j = j +1;
                    for d = 1:noDevices
                        if allDevices{d}.id == allSensorMotions{i}.owner.rid
                            app.tSensorsPro.productname(j) = allDevices{d}.product_data.product_name;
                            app.tSensorsPro.modelID(j) = allDevices{d}.product_data.model_id;
                            app.tSensorsPro.sensorname(j) = allDevices{d}.metadata.name;
                            break;
                        end
                    end
                    app.tSensorsPro.uniqueid(j) = allSensorMotions{i}.owner.rid;
                    app.tSensorsPro.lastevent(j) = datetime( ...
                        allSensorMotions{i}.motion.motion_report.changed, ...
                        'InputFormat','yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', ...
                        'TimeZone','UTC', ...
                        'Format','dd/MM/yy HH:mm:ss');
                    for p = 1:noPowerStates
                        if allPowerStates{p}.owner.rid == allSensorMotions{i}.owner.rid
                            app.tSensorsPro.battery(j) =  allPowerStates{p}.power_state.battery_level;
                            if app.tSensorsPro.battery(j) < 30
                                lowBattery = lowBattery + 1;
                            end
                            break;
                        end
                    end
                    app.tSensorsPro.type(j) = allSensorMotions{i}.type;
                end
                lastMotionIdx = 0;
                j = 0;
                for i = 1:noSensors
                    if  contains(app.tSensorsPro.type(i) ,"temperature")
                        j = j + 1;
                        app.tTempsPro.temp(j) = num2str(app.tSensorsPro.temp(i),'%+2.2f°C');
                        app.tTempsPro.battery(j) = app.tSensorsPro.battery(i);
                        app.tTempsPro.sensorname(j) = strcat(app.tSensorsPro.sensorname(i), '/', app.tSensorsPro.type(i), ' (', app.tSensorsPro.modelID(i),')');
                        app.tTempsPro.lastevent(j) = app.tSensorsPro.lastevent(i);
                    elseif contains(app.tSensorsPro.type(i) ,"motion")
                        j = j+ 1;
                        app.tTempsPro.temp(j) = ' ';
                        app.tTempsPro.battery(j) = app.tSensorsPro.battery(i);
                        app.tTempsPro.sensorname(j) = strcat(app.tSensorsPro.sensorname(i), '/', app.tSensorsPro.type(i), ' (', app.tSensorsPro.modelID(i),')');
                        app.tTempsPro.lastevent(j) = app.tSensorsPro.lastevent(i);
                        if lastMotionIdx == 0 || app.tSensorsPro.lastevent(i) > lastMotion
                            lastMotion = app.tSensorsPro.lastevent(i);
                            lastMotionIdx = i;
                        end
                        % Gather stats for number of times the motion sensor is triggered
                        for s = 1:noSensors
                            if app.sensorStatsPro.sensorname(s) == app.tSensorsPro.sensorname(i)
                                if app.tSensorsPro.lastevent(i) > app.sensorStatsPro.lastevent(s)
                                    app.sensorStatsPro.lastevent(s) = app.tSensorsPro.lastevent(i);
                                    app.sensorStatsPro.trigger(s) = app.sensorStatsPro.trigger(s) + 1;
                                end
                                break
                            else
                                if ismissing(app.sensorStatsPro.sensorname(s))
                                    app.sensorStatsPro.sensorname(s) = app.tSensorsPro.sensorname(i);
                                    app.sensorStatsPro.lastevent(s) = app.tSensorsPro.lastevent(i);
                                    app.sensorStatsPro.trigger(s) = 0;
                                    break
                                end
                            end
                        end
                    elseif app.tSensorsPro.type(i) == "switch"
                        j = j+ 1;
                        app.tTempsPro.temp(j) = ' ';
                        app.tTempsPro.battery(j) = app.tSensorsPro.battery(i);
                        app.tTempsPro.sensorname(j) = strcat(app.tSensorsPro.sensorname(i), '/', app.tSensorsPro.type(i), ' (', app.tSensorsPro.modelID(i),')');
                        app.tTempsPro.lastevent(j) = app.tSensorsPro.lastevent(i);
                    end
                end

                if lastMotionIdx > 0
                    app.sensorNameLastMovement = app.tSensorsPro.sensorname(lastMotionIdx);
                    lastMotionTime = datetime(lastMotion,"TimeZone","UTC");
                    lastMotionTime.TimeZone = 'Europe/London';
                    motionDate = extractBefore(string(lastMotionTime), " ");
                    motionTime = extractAfter(string(lastMotionTime), " ");
                    lastMotionText  = append('Last Motion detected by ', app.tSensorsPro.sensorname(lastMotionIdx),...
                        newline, 'on ', motionDate, ' at ', motionTime);
                    if strlength(app.tSensorsPro.sensorname(lastMotionIdx)) > 25
                        lastMotionFontsize = '16px';
                    else
                        lastMotionFontsize = '18px';
                    end
                    lastMotionFontcol = 'cyan';
                    lastMotionFontweight = 'bold';
                else
                    lastMotionText = 'No Motions detected.';
                    lastMotionFontsize = 14;
                    lastMotionFontcol = 'cyan';
                    lastMotionFontweight= 'normal';
                end
                app.HTMLLastMotion.Data = struct( ...
                    'message', lastMotionText, ...
                    'color', lastMotionFontcol, ...
                    'fontsize', lastMotionFontsize, ...
                    'fontweight', lastMotionFontweight, ...
                    'lineheight', '0.75', ...
                    'width', '385px', ...
                    'height', '37px', ...
                    'speed', '2s');

                for i =1:noSensors
                    if ismissing(app.sensorStatsPro.sensorname(i))
                        break
                    end
                    for j = 1:noSensors
                        if contains(app.tTempsPro.sensorname(j),app.sensorStatsPro.sensorname(i),"IgnoreCase",true) ...
                                && contains(app.tTempsPro.sensorname(j),'motion','IgnoreCase',true)
                            app.tTempsPro.temp(j) = sprintf('%05d', app.sensorStatsPro.trigger(i));
                            break
                        end
                    end
                end

                app.tTempsPro = sortrows(app.tTempsPro,{'temp','sensorname'},{'descend', 'ascend'});
                app.SensorsTable.Data = app.tTempsPro;


                app.SensorsandSwitchesLabel.Text = '<html><p style="line-height:0.75;">Sensors and<br>Switches</p></html>';


                if lowBattery > 0
                    sz = [lowBattery 3];
                    varTypes = ["double", "string", "string"];
                    varNames = ["battery", "modelID", "sensorname"];
                    tLowBat = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                    j = 0;
                    for i = 1:noSensors
                        if app.tSensorsPro.battery(j) < 30
                            j = j + 1;
                            tLowBat.battery(j) = app.tSensorsPro.battery(j);
                            tLowBat.modelID(j) = app.tSensorsPro.modelID(j);
                            tLowBat.sensorname(j) = app.tSensorsPro.sensorname(j);
                        end

                    end
                    app.TableSensors.Data = sortrows(tLowBat);
                    s1 = uistyle('BackgroundColor','red');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    addStyle(app.TableSensors,s1,'column',1);
                    addStyle(app.TableSensors,s2,'column',1);
                    app.TableSensors.Visible = "on";
                    app.HTMLLowBatMsg.Visible = "on";
                    app.Sunset.Visible ="off";
                    % Fast blink (1 second per cycle)
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', num2str(lowBattery), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % pulseText.html..(medium cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '⚠ Devices with a Battery Level below 30% ⚠', ...
                        'speed', '5s');
                else
                    % No blinking
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    % pulseText.html..(very slow cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '✔ All devices have a Battery Level above 30% ✔', ...
                        'color', 'green', ...
                        'speed', '10s');
                    app.TableSensors.Visible = "off";
                    app.HTMLLowBatMsg.Visible = "on";
                    app.Sunset.Visible ="on";
                    app.Sunset.Text = append("Sunset ", extractBetween(app.sunset,1,5));
                end

                % >>> Assign to UI
                app.tTempsPro = sortrows(app.tTempsPro,{'temp','sensorname'},{'descend', 'ascend'});
                app.SensorsTable.Data = app.tTempsPro;

                %%%%
                %%%% Lights Bridge Pro
                %%%%

                noLights = size(allLights,1);

                % Set table dimensions
                app.tLightsPro(noLights,:) = app.tLightsPro(1,:);

                lightsReachable = 0;
                app.lightsOn = 0;
                lightNo = 0;

                for i = 1:noLights
                    lightNo = lightNo + 1;
                    if lightNo > noLights
                        app.tLightsPro(lightNo,:) = app.tLightsPro(1,:);
                    end
                    app.tLightsPro.lightno(lightNo) = i;
                    for d = 1:noDevices
                        if allDevices{d}.id == allLights{i}.owner.rid
                            app.tLightsPro.model(lightNo) = allDevices{d}.product_data.model_id;
                            app.tLightsPro.productname(lightNo) = allDevices{d}.product_data.product_name;
                            app.tLightsPro.type(lightNo) = allDevices{d}.product_data.product_archetype;
                            break;
                        end
                    end


                    app.tLightsPro.lightname(lightNo) = allLights{i}.metadata.name;

                    % app.tLights.reachable(lightNo) = get connectivity..
                    app.tLightsPro.uniqueid(lightNo) = allLights{i}.id;
                    try
                        app.tLightsPro.bright(lightNo) = round(allLights{i}.dimming.brightness,0);
                    catch
                        app.tLightsPro.bright(lightNo) = 100;
                    end

                    try % xy colours..
                        app.tLightsPro.x(lightNo) = allLights{i}.color.xy.x;
                        app.tLightsPro.y(lightNo) = allLights{i}.color.xy.y;
                        app.tLightsPro.colormode(lightNo) = "xy";
                    catch % not a colour bulb so default to cool white
                        app.tLightsPro.colormode(lightNo) = "white";
                        app.tLightsPro.x(lightNo) = 0.3127;
                        app.tLightsPro.y(lightNo) = 0.3290;
                    end

                    for c = 1:noConnections
                        if  allConnectivity{c}.owner.rid == allLights{i}.owner.rid
                            if allConnectivity{c}.status == "connected"
                                lightsReachable = lightsReachable + 1;
                                app.tLightsPro.reachable(lightNo) = true;
                            else
                                app.tLightsPro.reachable(lightNo) = false;
                            end
                            break;
                        end
                    end
                    app.tLightsPro.on(lightNo) = allLights{i}.on.on;
                    if app.tLightsPro.on(lightNo)
                        app.lightsOn = app.lightsOn + 1;
                    end

                    lightGrpFound = false;
                    for g = 1:noGroups
                        noChildren = numel(allGroups{g}.children);
                        for gc = 1:noChildren
                            if allGroups{g}.children(gc).rid == allLights{i}.owner.rid
                                if lightGrpFound
                                    lightNo = lightNo + 1;
                                    app.tLightsPro(lightNo,:) = app.tLightsPro(lightNo - 1,:);
                                end
                                app.tLightsPro.groupno(lightNo) = g;
                                app.tLightsPro.groupname(lightNo) = allGroups{g}.metadata.name;
                                app.tLightsPro.grouptype(lightNo) = allGroups{g}.type;
                                lightGrpFound = true;
                            elseif allGroups{g}.children(gc).rid == allLights{i}.id
                                if lightGrpFound
                                    lightNo = lightNo + 1;
                                    app.tLightsPro(lightNo,:) = app.tLightsPro(lightNo - 1,:);
                                end
                                app.tLightsPro.groupno(lightNo) = g;
                                app.tLightsPro.groupname(lightNo) = allGroups{g}.metadata.name;
                                app.tLightsPro.grouptype(lightNo) = allGroups{g}.type;
                                lightGrpFound = true;
                            end
                        end
                    end
                end

                unreachable = noLights - lightsReachable;

                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);

                if unreachable > 0

                    % Set table dimensions
                    app.tUnreachablePro(unreachable,:) = app.tUnreachablePro(1,:);

                    j = 0;
                    for i = 1:noLights
                        if ~ app.tLightsPro.reachable(i)
                            j = j + 1;
                            app.tUnreachablePro.ID(j) = app.tLightsPro.model(i);
                            app.tUnreachablePro.lightname(j) =  app.tLightsPro.lightname(i);
                        end
                    end

                    app.TableLights.Data = app.tUnreachablePro;
                    s1 = uistyle('BackgroundColor','white');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    s2.FontWeight = 'bold';
                    addStyle(app.TableLights,s1,'column',1);
                    addStyle(app.TableLights,s2,'column',1);
                    app.TableLights.Visible = "on";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.Interpreter = 'none';
                    app.UnreachableLightsLabel.Tooltip =  'This section is only presented when there ARE Unreachable Lights.';
                    % Fast blink (1 second per cycle)
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', num2str(unreachable), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % Fast blink (1 second per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '⚠ Unreachable Lights ⚠', ...
                        'color', 'red', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '1s');
                else
                    app.TableLights.Visible = "off";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.FontColor = "white";
                    app.UnreachableLightsLabel.Tooltip = 'This section is only presented when there are NO Unreachable Lights.';
                    app.UnreachableLightsLabel.BackgroundColor = "none";
                    app.UnreachableLightsLabel.FontWeight = "bold";
                    app.UnreachableLightsLabel.FontSize = 16;
                    app.UnreachableLightsLabel.HorizontalAlignment = 'left';
                    app.UnreachableLightsLabel.Interpreter = 'html';
                    app.UnreachableLightsLabel.Text  = ...
                        append('<html><p style="line-height:1;"><font color="white"><b><br>', ...
                        maxTempText, '<br><br>', ...
                        minTempText, '</b></font></p>');
                    % No blinking
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    % Very slow blink (10 seconds per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '℃ Temperature trends since last Refresh ℃', ...
                        'color', 'white', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '10s');
                end

                app.tLightsPro = sortrows(app.tLightsPro,[1 2 3],["ascend" "ascend" "ascend"]);

                app.roomLightsOn = 0;
                app.LightsTable.Data = app.LightsTable.Data([],:); % clear the table
                fmt = '%02d';

                % Clear all previous styles (To prevent performance degredation)
                removeStyle(app.LightsTable);

                noLights = size(app.tLightsPro,1);

                % Pre-create reusable style objects
                styleWhiteBG   = uistyle('BackgroundColor','white');
                styleBlackFont = uistyle('FontColor','black');
                styleWhiteFont = uistyle('FontColor','white');

                grplightsOn = 0;
                prevGrpNo = -1;
                lT = 0;

                for l = 1:noLights

                    if ~ app.lightsOnRoomsOnly || ...
                            (app.tLightsPro.on(l) &&  ...
                            contains(app.tLightsPro.grouptype(l), "room","IgnoreCase",true))
                        lT = lT + 1;
                        if app.tLightsPro.on(l)
                            if app.tLightsPro.groupno(l) ~= prevGrpNo
                                grplightsOn = grplightsOn + 1;
                                prevGrpNo = app.tLightsPro.groupno(l);
                            end
                        end

                        % Colour mode
                        if app.tLightsPro.colormode(l) ~= "white"
                            x = app.tLightsPro.x(l);
                            y = app.tLightsPro.y(l);
                            bri = app.tLightsPro.bright(l);

                            rgb = hueXYtoRGB(app, x, y, bri);
                            [~,colourName] = findNearestCssColourName(app, rgb);
                            % Update colour text
                            app.tLightsPro.colour(l) = append( ...
                                colourName, ...
                                ' [', num2str(round(x*100,0),fmt), ...
                                ',', num2str(round(y*100,0),fmt), ']' );

                            % Background style (dynamic)
                            sBG = uistyle('BackgroundColor', rgb);
                            addStyle(app.LightsTable, sBG, 'cell', [lT 9]);

                            % Font style (reused)
                            if sum(rgb) == 0
                                addStyle(app.LightsTable, styleWhiteFont, 'cell', [lT 8]);
                            else
                                addStyle(app.LightsTable, styleBlackFont, 'cell', [lT 8]);
                            end

                        else
                            % White mode
                            app.tLightsPro.colour(l) = 'white';
                            addStyle(app.LightsTable, styleWhiteBG, 'cell', [lT 9]);
                        end
                        app.LightsTable.Data(lT,1:10) = app.tLightsPro(l,1:10);  % thinks app.LightsTable.Data is an array
                    end
                end


                app.OnGroups.Value = grplightsOn;

                updateONGroupsRooms(app);
                highlightOnLights(app);


                %%%
                %%% Schedules, Scripts & Entertainment Bridge Pro
                %%%

                noSchedules = size(allSchedules,1);
                app.AVSchedules.Value = noSchedules;
                noScenes = size(allScenes,1);
                app.AVScenes.Value = noScenes;
                noActiveSchedules = 0;
                noEntConfigs = 0;
                app.ResourceLinksLabel.Text = 'Entertainment Configurations';

                for i = 1:size(allCapabilities,1)
                    if allCapabilities{i}.type == "entertainment_configuration"
                        noEntConfigs = noEntConfigs + 1;
                    end
                end
                app.AVResourceLinks.Value = noEntConfigs;

                noSchedsScenesEnts = noSchedules + noScenes + noEntConfigs;

                % Set table dimensions
                app.tSchedulesPro(noSchedsScenesEnts,:) = app.tSchedulesPro(1,:);

                for i = 1:noSchedules
                    app.tSchedulesPro.scheduleseqno(i) = i;
                    app.tSchedulesPro.action(i) = "Automation";  % default
                    try
                        app.tSchedulesPro.location(i) = allSchedules{i}.configuration.where.group.rtype;
                    catch
                        try
                            app.tSchedulesPro.location(i) = allSchedules{i}.configuration.motion.where.group.rtype;
                        catch
                            app.tSchedulesPro.location(i) = allSchedules{i}.state.model_id;
                        end
                    end
                    app.tSchedulesPro.schedulename(i) = append(allSchedules{i}.type , ': ', allSchedules{i}.metadata.name);
                    app.tSchedulesPro.target(i) = '*';
                    app.tSchedulesPro.description(i) = '*';
                    app.tSchedulesPro.RL(i) = '*';
                    app.tSchedulesPro.status(i) = allSchedules{i}.status;
                    if allSchedules{i}.status == "running"
                        noActiveSchedules = noActiveSchedules + 1;
                    end

                    try
                        room = allSchedules{i}.configuration.motion.where.group.rid;
                        for r = 1:noGroups
                            if allGroups{r}.id == room
                                app.tSchedulesPro.location(i) = allGroups{r}.metadata.name;
                                break;
                            end
                        end
                    catch
                    end
                    try
                        device = allSchedules{i}.configuration.device.rid;
                        for d = 1:noDevices
                            if allDevices{d}.id == device
                                app.tSchedulesPro.action(i) = append(allDevices{d}.product_data.model_id, ...
                                    '/', allDevices{d}.product_data.product_name);
                                break;
                            end
                        end
                    catch
                        try
                            match = false;
                            device = allSchedules{i}.configuration.motion.motion_service.rid;
                            for d = 1:noDevices
                                for s = 1:size(allDevices{d}.services,1)
                                    if allDevices{d}.services(s).rid == device
                                        app.tSchedulesPro.action(i) = append(allDevices{d}.product_data.model_id, ...
                                            '/', allDevices{d}.product_data.product_name);
                                        match = true;
                                        break;
                                    end
                                end
                                if match
                                    break;
                                end
                            end
                        catch
                        end
                    end
                    try
                        match = false;
                        for d = 1:size(allSchedules{i}.dependees,1)
                            room = allSchedules{i}.dependees(d).target.rid;
                            for r = 1:noGroups
                                if allGroups{r}.id == room
                                    app.tSchedulesPro.location(i) = allGroups{r}.metadata.name;
                                    match = true;
                                else
                                    for c = 1:size(allGroups{r}.children,1)
                                        if allGroups{r}.children(c).rid == room
                                            app.tSchedulesPro.location(i) = allGroups{r}.metadata.name;
                                            match = true;
                                            break;
                                        end
                                    end
                                end
                                if match
                                    break;
                                end
                            end
                        end
                    catch
                    end
                    try % Start of schedule
                        noSlots = size(allSchedules{i}.configuration.motion.when.timeslots,1);
                        app.tSchedulesPro.RL(i) = '';
                        for t = 1:noSlots
                            startHour = compose("%02d",allSchedules{i}.configuration.motion.when.timeslots(t).start_time.time.hour);
                            startMinute = compose("%02d",allSchedules{i}.configuration.motion.when.timeslots(t).start_time.time.minute);
                            app.tSchedulesPro.RL(i) = append(app.tSchedulesPro.RL(i),'Start(', num2str(t), ') @ ', startHour, ':', startMinute, ' ');
                        end
                        app.tSchedulesPro.description(i) = allSchedules{i}.configuration.motion.when.timeslots(1).on_motion.recall_single.action;
                        app.tSchedulesPro.id(i) = allSchedules{i}.configuration.motion.when.timeslots(1).on_no_motion.after.minutes;
                        app.tSchedulesPro.target(i) = allSchedules{i}.configuration.motion.when.timeslots(1).on_no_motion.recall_single.action;
                    catch
                    end
                    try
                        startTime = allSchedules{i}.configuration.when_extended.start_at.time_point.type;
                        endTime = allSchedules{i}.configuration.when_extended.end_at.time_point.type;
                        app.tSchedulesPro.RL(i) = append(startTime,'-', endTime);
                    catch
                    end

                end

                %%%
                %%% Scenes
                %%%

                seqNo = noSchedules;
                for i = 1:noScenes
                    seqNo = seqNo + 1;
                    app.tSchedulesPro.scheduleseqno(seqNo) = seqNo;
                    app.tSchedulesPro.target(seqNo) = '*';
                    app.tSchedulesPro.RL(seqNo) = '*';
                    app.tSchedulesPro.schedulename(seqNo) = append(allScenes{i}.type , ': ', allScenes{i}.metadata.name);
                    app.tSchedulesPro.status(seqNo) = allScenes{i}.status.active;
                    noActs = size(allScenes{i}.actions,1);
                    app.tSchedulesPro.action(seqNo) = append(allScenes{i}.actions(1).target.rtype,' x ', num2str(noActs), ':');

                    for a = 1:noActs
                        for l = 1:noLights
                            if  allScenes{i}.actions(a).target.rid == allLights{l}.id
                                app.tSchedulesPro.action(seqNo) = append (app.tSchedulesPro.action(seqNo), ' (', num2str(a), ')', allLights{l}.metadata.name);
                                break;
                            end
                        end
                    end

                    if allScenes{i}.actions(1).action.on.on
                        app.tSchedulesPro.description(seqNo) = append('ON - Brigntness = ', ...
                            num2str(allScenes{i}.actions(1).action.dimming.brightness));
                    end
                    if allScenes{i}.group.rtype == "room" || allScenes{i}.group.rtype == "zone"
                        for j = 1:noGroups
                            if allGroups{j}.id == allScenes{i}.group.rid
                                app.tSchedulesPro.location(seqNo) = allGroups{j}.metadata.name;
                                break
                            end
                        end
                    end
                end

                %%%
                %%% Entertainment Configs
                %%%

                matchCount = 0;
                for i = 1:size(allCapabilities,1)
                    if allCapabilities{i}.type == "entertainment_configuration"
                        matchCount = matchCount + 1;
                        seqNo = seqNo + 1;
                        app.tSchedulesPro.scheduleseqno(seqNo) = seqNo;
                        app.tSchedulesPro.target(seqNo) = '*';
                        app.tSchedulesPro.RL(seqNo) = '*';
                        app.tSchedulesPro.description(seqNo) = '*';
                        app.tSchedulesPro.schedulename(seqNo) = append(allCapabilities{i}.type , ': ', allCapabilities{i}.configuration_type);
                        app.tSchedulesPro.status(seqNo) = allCapabilities{i}.status;
                        noActs = size(allCapabilities{i}.light_services,1);
                        app.tSchedulesPro.action(seqNo) = append(allCapabilities{i}.light_services(1).rtype,' x ', num2str(noActs), ':');
                        app.tSchedulesPro.location(seqNo) = allCapabilities{i}.metadata.name;
                        for a = 1:noActs
                            for l = 1:noLights
                                if  allCapabilities{i}.light_services(a).rid == allLights{l}.id
                                    app.tSchedulesPro.action(seqNo) = append (app.tSchedulesPro.action(seqNo), ' (', num2str(a), ')', allLights{l}.metadata.name);
                                    break;
                                end
                            end
                        end
                        if matchCount == noEntConfigs
                            break
                        end
                    end
                end

                app.RulesTable.Data = app.RulesTable.Data([],:); % clear the table
                app.RulesTable.Data = app.tSchedulesPro(:,1:12);
                app.RulesLabel_2.Text = "Schedules, Scenes & Entertainment Configurations";
                app.ONSchedules.Value = noActiveSchedules;
                % app.RulesLabel_2.FontSize = 12;
                app.inTimesTriggeredOrderLabel.Visible = "off";
                app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Visible = "off";
                app.NeverTriggered.Visible = "off";
                app.NeverTriggered.Value = 0;

            end
            app.Pulse.setSpeed(10);
            app.Response.Value = toc;
        end

        %%%
        %%% Toggle Lights on/off - if On then switch Off & vice versa
        %%%

        function onOff = toggleLights(app, lightID, index, singleLight)
            if app.vAPI == 1
                onOff = toggleLightsV1(app, lightID, index, singleLight);
            else % API v2
                %% need to set to lightID to app.tLights.uniqueid(x)
                for i = 1:numel(app.LightsTable.Data)
                    if app.LightsTable.Data{i,3} == lightID
                        lightUUID = app.LightsTable.Data{i,10};
                        onOff = toggleLightsV2(app, lightUUID, index, singleLight);
                        app.LightsTable.Data{i,7} = onOff;
                        break;
                    end
                end
            end
        end

        function onOff = toggleLightsV1(app, numLight, index, singleLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
            if app.LightsTable.Data{index,2} == "Room" || singleLight
                if app.LightsTable.Data{index,7} == 1
                    state = struct('on', false);
                    app.lightsOn = app.lightsOn - 1;
                else
                    state = struct('on',true);
                    app.lightsOn = app.lightsOn + 1;
                end
                webwrite(wrURL, state, options);
                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);
            end
            if app.LightsTable.Data{index,7} == 1
                app.LightsTable.Data{index,7} = 0;
                onOff = 0;
            else
                app.LightsTable.Data{index,7} = 1;
                onOff = 1;
            end
        end


        function onOff = toggleLightsV2(app, lightUUID, index, singleLight)

            if contains(app.LightsTable.Data{index,2}, "Room","IgnoreCase",true ) || singleLight
                isOn = app.LightsTable.Data{index,7} == 1;
                if isOn
                    body = struct('on', struct('on', false));
                    app.lightsOn = app.lightsOn - 1;
                else
                    body = struct('on', struct('on', true));
                    app.lightsOn = app.lightsOn + 1;
                end

                path = "/clip/v2/resource/light/" + lightUUID;
                method = 'PUT';
                hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);

                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);
            end
            if app.LightsTable.Data{index,7} == 1
                app.LightsTable.Data{index,7} = 0;
                onOff = 0;
            else
                app.LightsTable.Data{index,7} = 1;
                onOff = 1;
            end
        end


        %%%
        %%% Apply change to individual Light Brightness
        %%%

        function updBrightness(app, numLight)
            if app.vAPI == 1
                updBrightnessv1(app, numLight)
            else % API v2
                %% need to set to lightID to app.tLights.uniqueid(x)
                for i = 1:numel(app.LightsTable.Data)
                    if app.LightsTable.Data{i,3} == numLight
                        lightUUID = app.LightsTable.Data{i,10};
                        updBrightnessv2(app, lightUUID)
                        break;
                    end
                end
            end
        end

        function updBrightnessv1(app, numLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
            nbri = max(round(app.newBri * 2.54),1);
            state = struct('bri', nbri);
            webwrite(wrURL, state, options);
        end

        function updBrightnessv2(app, lightUUID)
            % Convert v1 bri (0–254) → v2 brightness (0–100)
            briPct = round(app.newBri,0);

            % API v2 brightness payload
            body = struct( ...
                'dimming', struct('brightness', briPct) ...
                );

            path = "/clip/v2/resource/light/" + lightUUID;
            method = 'PUT';
            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);
        end

        function highlightOnLights(app)
            noLights = size(app.LightsTable.Data,1);

            % Pre-create reusable style objects
            styleWhiteBG = uistyle('BackgroundColor','white');
            styleBlackBG = uistyle('BackgroundColor','black');

            for i = 1:noLights
                if app.LightsTable.Data{i,7} == 1
                    addStyle(app.LightsTable, styleWhiteBG, 'cell', [i 7]);
                else
                    addStyle(app.LightsTable, styleBlackBG, 'cell', [i 7]);
                end
            end
        end

        %%%
        %%% Process Brightness change for Groups of Lights
        %%%

        function groupBrightness(app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    numLight = app.LightsTable.Data{i,3};
                    for j = 1:noLights(1,1)
                        if app.LightsTable.Data{j,3} == numLight ...
                                && app.LightsTable.Data{j,8} ~= app.newBri
                            updBrightness (app, numLight);
                            for k = 1:noLights(1,1) % update all brightness table entries for this light
                                if app.LightsTable.Data{k,3} == numLight
                                    app.LightsTable.Data{k,8} = app.newBri;
                                end
                            end
                            break
                        end
                    end
                end
            end
        end

        %%%
        %%%  Process changes to Light Brightness
        %%%

        function results = newBrightness(app, event)
            indices = event.Indices;
            try
                if event.NewData == event.PreviousData
                    results = "Brightness edited but not changed.";
                    return;
                end
                newData = event.NewData;
                if newData > 100
                    newData = 100;
                elseif newData < 1
                    newData = 1;
                end
            catch EM
                results = "Brightness selected.";
                return;
            end
            app.newBri = newData;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                groupBrightness (app, noLights);
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights brightness changed.");
            elseif indices(1,2) == 8 % Single Brightness Changed
                numLight = app.LightsTable.Data{indices(1,1),3};
                updBrightness (app, numLight);
                for j = 1:noLights(1,1)
                    if app.LightsTable.Data{j,3} == numLight
                        app.LightsTable.Data{j,8} = newData;
                    end
                end
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light brightness changed.");
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Update Light Colour
        %%%

        function updColour(app, numLight)
            if app.vAPI == 1
                updColourv1(app, numLight)
            else % API v2
                for i = 1:numel(app.LightsTable.Data)
                    if app.LightsTable.Data{i,3} == numLight
                        lightUUID = app.LightsTable.Data{i,10};
                        updColourv2(app, lightUUID, numLight)
                        break;
                    end
                end
            end
        end

        function updColourv1(app, numLight) % API v1
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
            state = struct('xy', app.xyColour);
            webwrite(wrURL, state, options);
            fmt = '%02d';
            lightsTableSize = size(app.LightsTable.Data);
            x = app.xyColour(1,1);
            y = app.xyColour(1,2);
            app.colBri = max(round(app.colBri * 2.54),1);
            rgb = hueXYtoRGB(app,x,y,app.colBri);
            [~,colourName] = findNearestCssColourName(app, rgb);
            s1 = uistyle('BackgroundColor',rgb);
            s2 = uistyle;
            for i=1:lightsTableSize(1,1)
                if app.LightsTable.Data{i,3} == numLight
                    app.LightsTable.Data{i,9} = append(colourName, ' [', num2str(round(x * 100,0),fmt), ',', num2str(round(y * 100,0),fmt),']');
                    addStyle(app.LightsTable,s1,'cell',[i,9]);
                    if sum(rgb) == 0
                        s2.FontColor = 'white';
                    else
                        s2.FontColor = 'black';
                    end
                    addStyle(app.LightsTable,s2,'cell',[i 9]);
                end
            end
        end


        function updColourv2(app, lightUUID, numLight) % API v2
            x = app.xyColour(1,1);
            y = app.xyColour(1,2);

            % v1 bri (0–254) → v2 brightness (0–100)
            briPct = round(app.colBri,0);
            % fprintf('\nBrightness: %d\n', briPct);

            body = struct( ...
                'color', struct('xy', struct('x', x, 'y', y)), ...
                'dimming', struct('brightness', briPct) ...
                );

            path = "/clip/v2/resource/light/" + lightUUID;
            method = 'PUT';
            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);

            lightsTableSize = size(app.LightsTable.Data);

            rgb = hueXYtoRGB(app, x, y, briPct);
            [~,colourName] = findNearestCssColourName(app, rgb);
            s1 = uistyle('BackgroundColor', rgb);
            s2 = uistyle;
            fmt = '%02d';
            for i = 1:lightsTableSize(1)
                if app.LightsTable.Data{i,3} == numLight
                    app.LightsTable.Data{i,9} = append(colourName, ' [', num2str(round(x * 100,0),fmt), ',', num2str(round(y * 100,0),fmt),']');
                    addStyle(app.LightsTable, s1, 'cell', [i,9]);

                    if sum(rgb) == 0
                        s2.FontColor = 'white';
                    else
                        s2.FontColor = 'black';
                    end

                    addStyle(app.LightsTable, s2, 'cell', [i,9]);
                end
            end
        end

        function coloursChanged = groupColour (app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            coloursChanged = 0;
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    if contains(app.LightsTable.Data{i,6},'c','IgnoreCase',true) || ...
                            contains(app.LightsTable.Data{i,5},'col','IgnoreCase',true)
                        coloursChanged = coloursChanged + 1;
                        numLight = app.LightsTable.Data{i,3};
                        updColour(app, numLight);
                    end
                end
            end
        end

        function results = newColour(app, event)
            indices = event.Indices;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                coloursChanged = groupColour(app, noLights);
                if coloursChanged > 0
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights colour set to ", app.newColr,".");
                else
                    app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                    results = "No colour lights found in selected Group.  Change ignored,";
                end
            elseif indices(1,2) == 9 % Single Colour Changed
                if contains(app.LightsTable.Data{indices(1,1),6},'c','IgnoreCase',true) || ...
                        contains(app.LightsTable.Data{indices(1,1),5},'col','IgnoreCase',true)
                    numLight = app.LightsTable.Data{indices(1,1),3};
                    updColour(app, numLight);
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light colour set to ", app.newColr, ".");
                else
                    app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " is not a colour light.  Change ignored.");
                end
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Connect to Hue Bridge
        %%%

        function [errCode, hueIPAddress, appID, allDevices, allLights,   ...
                allRules, allScripts, allSensors, allSensorTemps, allSensorMotions, allSwitches, allPowerStates, ...
                allGroups, allSchedules, allCapabilities, allConnectivity,...
                allResourceLinks, allScenes, config] = ...
                connectToBridge(app)

            % Initialise all returned data items

            appID = '';
            allLights = [];
            allRules = [];
            allScripts = [];
            allSensors = [];
            allGroups = [];
            allSchedules = [];
            allCapabilities = [];
            allResourceLinks = [];
            allScenes = [];
            config = [];
            allDevices = [];
            allSensorTemps = [];
            allSensorMotions = [];
            allSwitches = [];
            allPowerStates = [];
            allConnectivity = [];

            % fprintf('Looking up IP address...');
            [errCode, hueIPAddress] = getBridgeIP(app);

            if errCode ~= 0
                return
            end

            % whos hueIPAddress
            % fprintf('; Hue IP = %s\n',hueIPAddress);

            if ispc
                app.fName = [app.dataRepository '\userNameHue.mat'];
            else
                app.fName = [app.dataRepository '/userNameHue.mat'];
            end

            if app.vAPI == 1

                % fprintf('\nChecking if already registered to the Hue Bridge...')
                hueUser = [];
                try
                    load(app.fName);  % userNameHue
                    appID     = hueUser;
                    url        = sprintf('http://%s/api/%s/lights', hueIPAddress,appID);
                    response = webread(url); % attempt to read lights to check if authorised
                    notInBridgeWhitelist = isfield(response,'error');
                catch MSG
                    % Cater for lack of internet..
                    if contains(MSG.message,'not connect to server')
                        errCode = 999;
                        warning('No internet connection.');
                        return;
                    end
                    notInBridgeWhitelist = true;
                end

                if notInBridgeWhitelist
                    fprintf('Not registered with Bridge. Attempting connection.\n')
                    appID = registerWithBridgeV2(app, hueIPAddress, app.fName);
                    if ~isempty(appID)
                        fprintf('Connection with Hue Bridge completed. Added to Whitelist. \n')
                    else
                        fprintf('Connection process terminated. \n')
                        errCode = 888;
                        return;
                    end
                else
                    % fprintf('Connection with Hue Bridge established; using registered user-name (appID)\n')
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Gather Bridge data to return for further processing..
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                app.DiagnosticsMsgTextArea.Value = 'Gathering Bridge v2 data.';
                app.LastBridgeDataRefreshTextArea.Value = 'Updating..';
                if app.noRoomsLightsOn.Text ~= "99" % first time in startup
                    drawnow limitrate
                end

                % Use previously retrieved lights data (from above Bridge registration check) -
                % saves duplicate reads..
                if ~isfield(response,'error')
                    allLights = response;
                else % warning for first read failure to aid diagnosis - error for any subsequent failures
                    warning ('** Unable to access Lights details **');
                    save([app.dataRepository 'diagnostics.mat'],"response");
                end

                url = sprintf('http://%s/api/%s/rules', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allRules = response;
                else
                    error ('** Unable to access Rules details **');
                end

                url = sprintf('http://%s/api/%s/sensors', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allSensors = response;
                else
                    error ('** Unable to access Sensors details **');
                end

                url = sprintf('http://%s/api/%s/groups', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allGroups = response;
                else
                    error ('** Unable to access Groups details **');
                end

                url = sprintf('http://%s/api/%s/schedules', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allSchedules = response;
                else
                    error ('** Unable to access Schedules details **');
                end

                url = sprintf('http://%s/api/%s/capabilities', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allCapabilities = response;
                else
                    error ('** Unable to access Capabilities details **');
                end


                url = sprintf('http://%s/api/%s/config', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    config = response;
                else
                    error ('** Unable to access Config details **');
                end

                url = sprintf('http://%s/api/%s/resourcelinks', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allResourceLinks = response;
                else
                    error ('** Unable to access Resourcelinks details **');
                end

                url = sprintf('http://%s/api/%s/scenes', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allScenes = response;
                else
                    error ('** Unable to access Scenes details **');
                end

            else % API v2 (Bridge Pro)

                fprintf('\nChecking if already registered to the Hue Bridge Pro (API v2)...\n');

                if ispc
                    app.fName = [app.dataRepository '\userNameHuePro.mat'];
                else
                    app.fName = [app.dataRepository '/userNameHuePro.mat'];
                end

                try
                    % Load stored application key
                    load(app.fName);   % loads Bridge Pro appKey
                    appID = hueAppKey;
                    registeredV2 = validateProKey(app,hueIPAddress, appID);
                catch
                    registeredV2 = false;
                end

                if ~ registeredV2
                    fprintf('Not registered with Bridge Pro. Attempting connection.\n')
                    appID = registerWithBridgePro(app, hueIPAddress, app.fName);
                    if ~isempty(appID)
                        fprintf('Connection with Hue Bridge Pro completed. Added to Whitelist. \n')
                    else
                        fprintf('Connection process terminated. \n')
                        errCode = 888;
                        return;
                    end
                else
                    fprintf('Connection with Hue Bridge established; using registered user-name (appID)\n')
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Gather Bridge Pro data
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                if app.bridgeProMode
                    app.DiagnosticsMsgTextArea.Value = 'Gathering Bridge Pro data.';
                    app.LastBridgeDataRefreshTextArea.Value = 'Updating..';
                else
                    app.DiagnosticsMsgTextArea.Value = 'Gathering Bridge v2 data via API v2 - simulating Bridge Pro processing..';
                end
                drawnow limitrate

                app.appKey = appID;

                loadAllResourcesPro(app,hueIPAddress, app.appKey);
                config = app.bridgev2;

                % allRooms        = app.roomsv2;
                geolocation = app.geolocationv2.';
                allDevices = app.devicesv2.'; % transpose AxB to BxA
                allLights = app.lightsv2.';
                allGroups = app.groupsv2.';
                allScenes = app.scenesv2.';
                allScripts = app.scriptsv2.';
                allSchedules = app.schedulesv2.';
                allCapabilities = app.capabilitiesv2.';
                allResourceLinks = app.resourcelinksv2.';
                allSensors = app.sensorsv2.';
                allSensorTemps = app.sensorsTempv2.';
                allSensorMotions = app.sensorsMotionv2.';
                allSwitches = app.switchesv2.';
                allPowerStates = app.devicePowerv2.';
                allConnectivity = app.connectivityv2.';
                % Only save extracted Bridge data at startup
                if app.startup
                    app.startup = false;
                    saveAllData (app, allDevices, allLights, [], allScripts, allSensors, ...
                        allSensorTemps, allSensorMotions, allSwitches, allGroups, ...
                        allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                        allPowerStates, config)
                    if ispc
                        save([app.dataRepository '\geolocation.mat'],"geolocation");
                    else
                        save([app.dataRepository '/geolocation.mat'],"geolocation");
                    end
                else
                    dt = datetime('now', 'Format', 'dd-MMM-yyyy HH:mm:ss');
                    strDt = string(dt);
                    app.LastBridgeDataRefreshTextArea.Value = strDt;
                end

                app.sunset = geolocation{1}.sun_today(1).sunset_time;

            end
        end

        %%%
        %%% New Bridge registration - process for adding new user to
        %%% WhiteList for Bridge V2
        %%%

        function appID = registerWithBridgeV2(app, hueIPAddress, fNameSAVE)
            appID = [];
            app.DiagnosticsMsgTextArea.Value = 'Attempting to pair with the Bridge..';
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            fprintf('\nAttempting to pair with the Bridge..');
            url  = sprintf('http://%s/api', hueIPAddress);
            options = weboptions('MediaType', 'application/json');
            deviceType = struct('devicetype', 'SPF_Matlab_API');
            response = webwrite (url, deviceType, options);

            if isfield(response,'success')
                finished = true;
            else
                finished = false;
            end

            if ~finished

                Msgbox = uifigure;
                message = "Need to obtain a valid Username to connect to Bridge v2; Proceed with Pairing? (Will need to press the link button on the bridge.)";
                selection1 = uiconfirm(Msgbox,message,"Bridge Registration Process", ...
                    "Options",["Yes","No (Cancel)"],...
                    "DefaultOption",1,"CancelOption",2,....
                    "Icon","question");
                switch selection1
                    case 'Yes'
                        message = "Press the Link Button on the Hue Bridge v2 within 60 seconds.";
                        selection2 = uiconfirm(Msgbox,message,"Bridge v2 Registration Process", ...
                            "Options",["Continue","Cancel"],...
                            "DefaultOption",1,"CancelOption",2,....
                            "Icon","info");
                        switch selection2
                            case 'Continue'
                                uialert(Msgbox,"Press the Link button on the Bridge v2","OK");
                                app.DiagnosticsMsgTextArea.Value = 'Waiting for Link Button press..';
                                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                                save (app.diagFileName,"lastDiagMsg");
                                drawnow limitrate
                            case 'Cancel'
                                app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                                save (app.diagFileName,"lastDiagMsg");
                                drawnow limitrate
                                pause(3)
                                delete(Msgbox);
                                return
                        end
                    case 'No (Cancel)'
                        app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg");
                        drawnow limitrate
                        pause(3)
                        delete(Msgbox);
                        return
                end
            end

            tic
            while ~finished && toc < 60
                try
                    response = webwrite (url, deviceType, options);
                    if isfield(response,'success')
                        finished = true;
                    end
                catch % wait for link button being pressed, unless it's a real error
                    if contains(response.error.description, 'link button not pressed')
                        pause(5)
                    else
                        fprintf('\n* Unable to communicate with the Bridge v2 *\n');
                        app.DiagnosticsMsgTextArea.Value = 'Unable to communicate with the Bridge v2.  Not paired.';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg","response");
                        pause(3)
                        delete(Msgbox);
                        return
                    end
                end
            end

            if toc >= 60
                fprintf('\n* Link button on Bridge v2 not pressed within 60 seconds.  Timed out. *\n');
                app.DiagnosticsMsgTextArea.Value = 'Link button on Bridge v2 not pressed within 60 seconds.  Timed out.';
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                drawnow limitrate
                pause(3)
                delete(Msgbox);
                return
            end

            appID = response.success.username;
            hueUser = appID;
            save(fNameSAVE,'hueUser');
            app.DiagnosticsMsgTextArea.Value = 'Bridge v2 registration successful (added to Whitelist).  Restarting the App in 5 seconds...';
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            pause(5)
            delete(Msgbox);
            restart(app);
        end

        %%%
        %%% Obtain API V2 application key for Bridge Bridge Pro
        %%%

        function appKey = registerWithBridgePro(app, hueIPAddress, fName)

            import matlab.net.http.*
            import matlab.net.http.field.*

            % ------------------------------------------------------------
            % Correct endpoint for pairing on ALL Hue bridges (v1 + v2)
            % ------------------------------------------------------------
            url = "https://" + hueIPAddress + "/api";

            % Correct pairing body for Hue API v2
            body = struct('devicetype', 'MATLAB_App');

            % No application key header during pairing
            req = RequestMessage('post', [], body);

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % ignore CA validation
                );

            fprintf('Press the link button on the Bridge Pro...\n');
            app.DiagnosticsMsgTextArea.Value = ...
                'Press the link button on the Bridge Pro within 30 seconds...';
            drawnow limitrate
            pause(30);

            try
                resp = req.send(url, opts);
                fprintf('Bridge registration response code: %s\n', resp.StatusCode);

                if resp.StatusCode == matlab.net.http.StatusCode.OK

                    % Response is a cell array containing a struct
                    data = resp.Body.Data;
                    if iscell(data)
                        data = data{1};
                    end

                    if isfield(data, "success") && isfield(data.success, "username")
                        appKey = data.success.username;
                        hueAppKey = appKey;
                        save(fName, "hueAppKey");

                        app.DiagnosticsMsgTextArea.Value = ...
                            'Bridge Pro registration successful. Restarting the App...';
                        drawnow limitrate
                        pause(5);
                        restart(app);
                        return
                    end
                end

                % If we reach here, pairing failed
                appKey = [];
                disp(resp.Body.Data)

            catch ME
                % ------------------------------------------------------------
                % Handle pairing errors (button not pressed, timeout, etc.)
                % ------------------------------------------------------------
                appKey = [];

                try
                    err = resp.Body.Data(1).error.description;

                    if contains(err, 'link button not pressed', 'IgnoreCase', true)
                        fprintf('\n* Link button not pressed within 30 seconds. *\n');
                        app.DiagnosticsMsgTextArea.Value = ...
                            'Link button not pressed within 30 seconds. Timed out.';
                    else
                        fprintf('\n* Undetermined error during registration. *\n');
                        app.DiagnosticsMsgTextArea.Value = ...
                            'Undetermined error during Bridge Pro registration.';
                    end

                catch
                    fprintf('\n* Unable to communicate with the Bridge Pro *\n');
                    app.DiagnosticsMsgTextArea.Value = ...
                        'Unable to communicate with the Bridge Pro. Not paired.';
                end

                drawnow limitrate
                pause(10)

                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save(app.diagFileName, "lastDiagMsg");
            end
        end


        %%%
        %%% Get the Bridge IP address - if not available or no longer valid then obtain a new one..
        %%%

        function [errCode, hueIPAddress] = getBridgeIP(app)

            % First try to use previously stored IP address to save unnecessary external webreads
            errCode = 0;

            if app.bridgeProMode % API v2 for Bridge Pro
                if ispc
                    IPFile = [app.dataRepository '\hueIPAddressPro.mat'];
                else
                    IPFile = [app.dataRepository '/hueIPAddressPro.mat'];
                end
                app.fName = IPFile;
                try
                    load(IPFile,"pro");
                    hueIPAddress = pro;
                    fprintf('Retrieved previously stored IP address %s from %s.',hueIPAddress,IPFile);
                    % API v2
                    if app.isValidHueIP(hueIPAddress)
                        return; % still valid
                    end
                catch MSG
                    disp(MSG)
                    hueIPAddress = 'IP file not found';
                end
                % Previous IP no longer valid
                % Try to get fresh details from the web
                fprintf('\nBridge Pro IP address changed.  Obtaining new IP address..');
                bridgeModel = 'BSB003';
                [pro, errCode] = discoverBridges(app, bridgeModel);
                if errCode ~= 0
                    fprintf('\nBridge Pro IP address not returned - errCode = %d',errCode);
                    return;
                end
                hueIPAddress = pro;
                save(IPFile,"pro");
                fprintf('\nNew Bridge Pro IP address = %s',hueIPAddress);
            else % either API v1 or API v2 for Bridge v2
                if ispc
                    IPFile = [app.dataRepository '\hueIPAddress.mat'];
                    userFile = [app.dataRepository '\userNameHue.mat'];
                else
                    IPFile = [app.dataRepository '/hueIPAddress.mat'];
                    userFile = [app.dataRepository '/userNameHue.mat'];
                end
                app.fName = IPFile;
                try
                    load(IPFile,"v2");
                    hueIPAddress = v2;
                    load(userFile,"hueUser");
                    app.appID = hueUser;
                    % fprintf('Using previously stored IP address from %s.',IPFile);
                    if app.vAPI == 1
                        try
                            url = sprintf('http://%s/api/%s/config', hueIPAddress,app.appID);
                            % check that the details still work..
                            webread(url);
                            return; % still valid
                        catch MSG
                            disp(MSG.message)
                        end
                    else % API v2
                        if app.isValidHueIP(hueIPAddress)
                            return; % still valid
                        end
                    end
                catch MSG
                    disp(MSG)
                    hueIPAddress = 'IP file not found';
                end

                % Previous IP no longer valid
                % Try to get fresh details from the web
                fprintf('\nBridge v2 IP address changed.  Obtaining new IP address..');
                bridgeModel = 'BSB002';
                [v2, errCode] = discoverBridges(app, bridgeModel);
                if errCode ~= 0
                    return;
                end
                hueIPAddress = v2;
                save(IPFile,"v2");
                fprintf('\nNew Bridge v2 IP address = %s',hueIPAddress);
            end
        end

        %%%
        %%% Saves all Bridge data for external analysis
        %%%

        function saveAllData(app, allDevices, allLights, allRules, allScripts, allSensors, sensorsTemp, ...
                sensorsMotion, allSwitches, allGroups, ...
                allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                allPowerStates, config)
            if ispc
                % Save all data for subsequent analysis
                save([app.dataRepository '\allDevicesData.mat'],"allDevices");
                save([app.dataRepository '\allLightsData.mat'],"allLights");
                save([app.dataRepository '\allRulesData.mat'],"allRules");
                save([app.dataRepository '\allScriptsData.mat'],"allScripts");
                save([app.dataRepository '\allSensorsData.mat'],"allSensors");
                save([app.dataRepository '\temperaturesData.mat'],"sensorsTemp");
                save([app.dataRepository '\motionsData.mat'],"sensorsMotion");
                save([app.dataRepository '\allSwitchesData.mat'],"allSwitches");
                save([app.dataRepository '\allGroupsData.mat'],"allGroups");
                save([app.dataRepository '\allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '\allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '\allConnectivityData.mat'],"allConnectivity");
                save([app.dataRepository '\allScenesData.mat'],"allScenes");
                save([app.dataRepository '\allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '\allPowerStatesData.mat'],"allPowerStates");
                save([app.dataRepository '\config.mat'],"config");
            else
                save([app.dataRepository '/allDevicesData.mat'],"allDevices");
                save([app.dataRepository '/allLightsData.mat'],"allLights");
                save([app.dataRepository '/allRulesData.mat'],"allRules");
                save([app.dataRepository '/allScriptsData.mat'],"allScripts");
                save([app.dataRepository '/allSensorsData.mat'],"allSensors");
                save([app.dataRepository '/temperaturesData.mat'],"sensorsTemp");
                save([app.dataRepository '/motionsData.mat'],"sensorsMotion");
                save([app.dataRepository '/allSwitchesData.mat'],"allSwitches");
                save([app.dataRepository '/allGroupsData.mat'],"allGroups");
                save([app.dataRepository '/allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '/allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '/allConnectivityData.mat'],"allConnectivity");
                save([app.dataRepository '/allScenesData.mat'],"allScenes");
                save([app.dataRepository '/allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '/allPowerStatesData.mat'],"allPowerStates");
                save([app.dataRepository '/config.mat'],"config");
            end

            listing = dir("**");
            tbl = struct2table(listing);
            tbl.date = datetime(tbl.datenum,ConvertFrom="datenum");
            tbl = removevars(tbl,"datenum");
            dets = tbl(tbl.name == "allCapabilitiesData.mat",3);
            app.LastBridgeDataRefreshTextArea.Value = string(dets.date(1,1));
        end

        %%%
        %%% Load all saved Bridge data
        %%%

        function [allDevices, allLights, allRules, allScripts, allSensors, ...
                sensorsTemp, sensorsMotion, allSwitches, allGroups, ...
                allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                allPowerStates, config] ...
                = loadAllData(app)


            if ispc
                % Load all saved data for processing
                load([app.dataRepository '\allDevicesData.mat'],"allDevices");
                load([app.dataRepository '\allLightsData.mat'],"allLights");
                load([app.dataRepository '\allRulesData.mat'],"allRules");
                load([app.dataRepository '\allScriptsData.mat'],"allScripts");
                load([app.dataRepository '\allSensorsData.mat'],"allSensors");
                load([app.dataRepository '\temperaturesData.mat'],"sensorsTemp");
                load([app.dataRepository '\motionsData.mat'],"sensorsMotion");
                load([app.dataRepository '\allSwitchesData.mat'],"allSwitches");
                load([app.dataRepository '\allGroupsData.mat'],"allGroups");
                load([app.dataRepository '\allSchedulesData.mat'],"allSchedules");
                load([app.dataRepository '\allCapabilitiesData.mat'],"allCapabilities");
                load([app.dataRepository '\allConnectivityData.mat'],"allConnectivity");
                load([app.dataRepository '\allScenesData.mat'],"allScenes");
                load([app.dataRepository '\allResourceLinksData.mat'],"allResourceLinks");
                load([app.dataRepository '\allPowerStatesData.mat'],"allPowerStates");
                load([app.dataRepository '\config.mat'],"config");
            else
                load([app.dataRepository '/allDevicesData.mat'],"allDevices");
                load([app.dataRepository '/allLightsData.mat'],"allLights");
                load([app.dataRepository '/allRulesData.mat'],"allRules");
                load([app.dataRepository '/allScriptsData.mat'],"allScripts");
                load([app.dataRepository '/allSensorsData.mat'],"allSensors");
                load([app.dataRepository '/temperaturesData.mat'],"sensorsTemp");
                load([app.dataRepository '/motionsData.mat'],"sensorsMotion");
                load([app.dataRepository '/allSwitchesData.mat'],"allSwitches");
                load([app.dataRepository '/allGroupsData.mat'],"allGroups");
                load([app.dataRepository '/allSchedulesData.mat'],"allSchedules");
                load([app.dataRepository '/allCapabilitiesData.mat'],"allCapabilities");
                load([app.dataRepository '/allConnectivityData.mat'],"allConnectivity");
                load([app.dataRepository '/allScenesData.mat'],"allScenes");
                load([app.dataRepository '/allResourceLinksData.mat'],"allResourceLinks");
                load([app.dataRepository '/allPowerStatesData.mat'],"allPowerStates");
                load([app.dataRepository '/config.mat'],"config");
            end

            listing = dir("**");
            tbl = struct2table(listing);
            tbl.date = datetime(tbl.datenum,ConvertFrom="datenum");
            tbl = removevars(tbl,"datenum");
            dets = tbl(tbl.name == "allCapabilitiesData.mat",3);
            app.LastBridgeDataRefreshTextArea.Value = string(dets.date(1,1));
        end

        function [returnFile] = load1File(app, loadFile)
            if ispc
                disp(whos("-file",append(app.dataRepository, '\', loadFile, '.mat')));
                returnFile = load(append(app.dataRepository, '\', loadFile, '.mat'));
            else
                returnFile = load(append(app.dataRepository, '/', loadFile, '.mat'));
            end
        end

        %%%
        %%% Restart the app after whitelist addition or IP address refresh
        %%%

        function restart(app)
            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            catch
            end
            app.restoreWindowPos = false;
            startupFcn(app);              % Launch a new instance of the app
        end

        %%%
        %%% Blink the number of lights on value
        %%%

        function blinkLightsOn(app)

            % Fast-ish blink (3 seconds per cycle)
            app.HTMLLightsOn.Data = struct( ...
                'message', num2str(app.lightsOn), ...
                'color', 'white', ...
                'fontsize', '36px', ...
                'fontweight', 'bold', ...
                'lineheight', '0', ...
                'width', '55px', ...
                'height', '45px', ...
                'speed', '3s');
        end

        % Capture new sequence of Lights details when sorted via UI
        function LightsTableDisplayDataChanged(app, event)
            % Get the displayed (sorted/filtered) data
            newData = event.DisplayData;
            % Update the underlying Data property
            app.LightsTable.Data = newData;
        end

        %%%
        %%% Determine how many Groups and Rooms have at least one Light lit after Toggling
        %%%

        function updateONGroupsRooms(app)

            app.OnGroups.Value = 0;
            app.roomLightsOn = 0;
            noLights = size(app.LightsTable.Data,1);
            sz = [app.AVGroups.Value 3];
            varTypes = ["string", "string", "logical"];
            varNames = [ "groupname", "type","on"];
            lGroups = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            lGroups.groupname(:) = "";
            for i = 1:noLights
                if app.LightsTable.Data{i,7} == 1
                    for j = 1:app.AVGroups.Value
                        if app.LightsTable.Data{i,1} == lGroups.groupname(j)
                            break;
                        end
                        if lGroups.groupname(j) == ""
                            lGroups.groupname(j) = app.LightsTable.Data{i,1};
                            lGroups.type(j) = app.LightsTable.Data{i,2};
                            lGroups.on(j)= true;
                            break;
                        end
                    end
                end
            end
            for i = 1:app.AVGroups.Value
                if lGroups.on(i)
                    app.OnGroups.Value = app.OnGroups.Value + 1;
                    if contains(lGroups.type(i),"Room","Ignorecase",true)
                        app.roomLightsOn = app.roomLightsOn + 1;
                    end
                end
            end
            app.noRoomsLightsOn.Text = num2str(app.roomLightsOn);
            if app.roomLightsOn == 1
                app.RoomsLabel.Text = 'Room';
            else
                app.RoomsLabel.Text = 'Rooms';
            end
        end


        %%%
        %%% Full close-down and restart
        %%%

        function restartApp(app)
            if app.offlineMode
                return
            end
            cd (app.appFolder)
            warning('on', 'all');
            saveWindowState(app);
            appName = mfilename;
            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            catch
            end
            delete(app);
            feval(appName);
        end

        %%%
        %%% Convert Philips Hue xy + brightness to MATLAB RGB (0–1)
        %%%

        function rgb = hueXYtoRGB(~,x, y, bri)
            % Convert Philips Hue xy + brightness to App-style RGB (0–255)

            if y == 0
                rgb = [0 0 0];
                return;
            end

            % Step 1: xy + brightness → XYZ
            Y = bri / 254;          % Normalised luminance
            X = (Y / y) * x;
            Z = (Y / y) * (1 - x - y);
            XYZ = [X; Y; Z];

            % Step 2: XYZ → linear RGB (sRGB matrix)
            M = [ 3.2406 -1.5372 -0.4986;
                -0.9689  1.8758  0.0415;
                0.0557 -0.2040  1.0570 ];

            rgb_lin = M * XYZ;

            % Step 3: Clip negative values
            rgb_lin = max(rgb_lin, 0);

            % Step 4: sRGB gamma correction
            gammaCorrect = @(c) (c <= 0.0031308) .* (12.92 * c) + ...
                (c > 0.0031308)  .* (1.055 * c .^ (1/2.4) - 0.055);

            rgb = gammaCorrect(rgb_lin);

            % Step 5: Scale to 0–255
            rgb = uint8(round(255 * rgb / max(rgb)));
        end

        %%%
        %%% Convert MATLAB RGB (0–1) to Philips Hue xy + brightness
        %%%

        function [x, y] = rgb2xy(~, rgb)
            % 1. Clamp and convert sRGB to linear RGB
            rgb = max(rgb, 0);
            rgb = min(rgb, 1);

            mask = (rgb <= 0.04045);
            rgb(mask)  = rgb(mask) / 12.92;
            rgb(~mask) = ((rgb(~mask) + 0.055) / 1.055) .^ 2.4;

            % 2. Linear RGB → XYZ (D65)
            M = [ ...
                0.4124564  0.3575761  0.1804375
                0.2126729  0.7151522  0.0721750
                0.0193339  0.1191920  0.9503041 ];

            XYZ = M * rgb(:);

            X = XYZ(1);
            Y = XYZ(2);
            Z = XYZ(3);

            % 3. XYZ → xy
            denom = (X + Y + Z);

            if denom == 0
                % fallback to D65 white
                x = 0.3127;
                y = 0.3290;
            else
                x = X / denom;
                y = Y / denom;
            end
        end

        %%%
        %%% Validate XY (xx,yy) format Lights colour input and return X,Y
        %%%

        function [isValid, x, y] = validateXYcolour(~, str)
            %   Accepts "NN,NN" or "0.34,0.21" and normalises to Hue xy.
            %
            %   Strict format "12,34" becomes x=0.12, y=0.34.
            %   Flexible format "0.12,0.34" is used as-is.
            %
            %   mode = "strict"   → strict NN,NN detected and normalised
            %   mode = "flexible" → real Hue xy detected
            %   mode = "none"     → invalid

            arguments
                ~
                str {mustBeTextScalar}
            end

            x = NaN;
            y = NaN;
            mode = "none";

            % Strict: exactly two digits, comma, two digits
            strictTok = regexp(str, '^(\d{2}),(\d{2})$', 'tokens', 'once');

            if ~isempty(strictTok)
                xs = str2double(strictTok{1});
                ys = str2double(strictTok{2});

                % Normalise to 0–1 Hue xy
                x = xs / 100;
                y = ys / 100;

                mode = "strict";
            else
                % Flexible: decimals, variable digits, whitespace
                flexTok = regexp(str, '^\s*([0-9]*\.?[0-9]+)\s*,\s*([0-9]*\.?[0-9]+)\s*$', ...
                    'tokens', 'once');

                if ~isempty(flexTok)
                    x = str2double(flexTok{1});
                    y = str2double(flexTok{2});
                    mode = "flexible";
                end
            end

            if mode == "none"
                isValid = false;
                return;
            end

            % Hue xy range check
            isValid = x>=0 && x<=1 && y>=0 && y<=1;
        end

        %%%
        %%% Save and restore UIFigure placement
        %%%

        function saveWindowState(app)
            appName = class(app);
            fig = app.getAppFigure;
            setpref(appName, "WindowPosition", fig.Position);
            setpref(appName, "WindowState", fig.WindowState);
        end

        function restoreWindowState(app)
            appName = class(app);
            fig = app.getAppFigure;

            % Restore window state
            if ispref(appName, "WindowState")
                fig.WindowState = getpref(appName, "WindowState");
            end

            % Restore position only if not maximized
            if ispref(appName, "WindowPosition") && fig.WindowState == "normal"
                pos = getpref(appName, "WindowPosition");

                % Get true minimum size
                [minW, minH] = app.getMinimumAppSize;

                % Enforce minimum size
                pos(3) = max(pos(3), minW);
                pos(4) = max(pos(4), minH);

                % Avoid off-screen placement
                screens = get(0, "MonitorPositions");
                if any(pos(1) >= screens(:,1) & pos(1) <= screens(:,1)+screens(:,3))
                    fig.Position = pos;
                end
            end
        end

        function fig = getAppFigure(app)
            % Find the UIFigure property dynamically
            props = properties(app);

            for i = 1:numel(props)
                val = app.(props{i});
                if isa(val, 'matlab.ui.Figure')
                    fig = val;
                    return
                end
            end
            error('No UIFigure found in app.');
        end

        function [minW, minH] = getMinimumAppSize(app)
            fig = app.getAppFigure;

            % Temporarily shrink the window to its smallest allowed size
            originalPos = fig.Position;

            % Try shrinking width
            fig.Position(3) = 1;
            drawnow limitrate
            minW = fig.Position(3);

            % Try shrinking height
            fig.Position(4) = 1;
            drawnow limitrate
            minH = fig.Position(4);

            % Restore original size
            fig.Position = originalPos;
        end

        %%%
        %%% Convert RGB to nearest matching colour (basic)
        %%%

        function name = nearestColourName(~, rgb)

            % Ensure double
            rgb = double(rgb);

            % rgb can be 0–1 or 0–255
            if max(rgb) > 1
                rgb = rgb / 255;
            end

            % Basic CSS-style colour list
            names = { ...
                'black','white','red','green','blue','yellow','cyan','magenta', ...
                'gray','orange','pink','purple','brown','lime','navy','teal'};

            values = [ ...
                0   0   0;      % black
                1   1   1;      % white
                1   0   0;      % red
                0   1   0;      % green
                0   0   1;      % blue
                1   1   0;      % yellow
                0   1   1;      % cyan
                1   0   1;      % magenta
                0.5 0.5 0.5;    % gray
                1   0.5 0;      % orange
                1   0.75 0.8;   % pink
                0.5 0   0.5;    % purple
                0.6 0.3 0;      % brown
                0.75 1 0;       % lime
                0   0   0.5;    % navy
                0   0.5 0.5];   % teal

            % Compute Euclidean distance
            rgb = reshape(rgb, 1, 3);   % ensure 1×3
            diffs = values - rgb(ones(size(values,1),1), :);
            dist = sqrt(sum(diffs.^2,2));

            [~, idx] = min(dist);
            name = names{idx};
        end


        %%%
        %%% Convert RGB to nearest matching CSS colour (detailed)
        %%%

        function [matched, name, rgbOut] = findNearestCssColourName(~, rgb, colourName)

            % CSS Level 4 colour names and RGB values
            data = {
                'aliceblue',            [240 248 255]
                'antiquewhite',         [250 235 215]
                'aqua',                 [  0 255 255]
                'aquamarine',           [127 255 212]
                'azure',                [240 255 255]
                'beige',                [245 245 220]
                'bisque',               [255 228 196]
                'black',                [  0   0   0]
                'blanchedalmond',       [255 235 205]
                'blue',                 [  0   0 255]
                'blueviolet',           [138  43 226]
                'brown',                [165  42  42]
                'burlywood',            [222 184 135]
                'cadetblue',            [ 95 158 160]
                'chartreuse',           [127 255   0]
                'chocolate',            [210 105  30]
                'coral',                [255 127  80]
                'cornflowerblue',       [100 149 237]
                'cornsilk',             [255 248 220]
                'crimson',              [220  20  60]
                'cyan',                 [  0 255 255]
                'darkblue',             [  0   0 139]
                'darkcyan',             [  0 139 139]
                'darkgoldenrod',        [184 134  11]
                'darkgray',             [169 169 169]
                'darkgreen',            [  0 100   0]
                'darkgrey',             [169 169 169]
                'darkkhaki',            [189 183 107]
                'darkmagenta',          [139   0 139]
                'darkolivegreen',       [ 85 107  47]
                'darkorange',           [255 140   0]
                'darkorchid',           [153  50 204]
                'darkred',              [139   0   0]
                'darksalmon',           [233 150 122]
                'darkseagreen',         [143 188 143]
                'darkslateblue',        [ 72  61 139]
                'darkslategray',        [ 47  79  79]
                'darkslategrey',        [ 47  79  79]
                'darkturquoise',        [  0 206 209]
                'darkviolet',           [148   0 211]
                'deeppink',             [255  20 147]
                'deepskyblue',          [  0 191 255]
                'dimgray',              [105 105 105]
                'dimgrey',              [105 105 105]
                'dodgerblue',           [ 30 144 255]
                'firebrick',            [178  34  34]
                'floralwhite',          [255 250 240]
                'forestgreen',          [ 34 139  34]
                'fuchsia',              [255   0 255]
                'gainsboro',            [220 220 220]
                'ghostwhite',           [248 248 255]
                'gold',                 [255 215   0]
                'goldenrod',            [218 165  32]
                'gray',                 [128 128 128]
                'green',                [  0 128   0]
                'greenyellow',          [173 255  47]
                'grey',                 [128 128 128]
                'honeydew',             [240 255 240]
                'hotpink',              [255 105 180]
                'indianred',            [205  92  92]
                'indigo',               [ 75   0 130]
                'ivory',                [255 255 240]
                'khaki',                [240 230 140]
                'lavender',             [230 230 250]
                'lavenderblush',        [255 240 245]
                'lawngreen',            [124 252   0]
                'lemonchiffon',         [255 250 205]
                'lightblue',            [173 216 230]
                'lightcoral',           [240 128 128]
                'lightcyan',            [224 255 255]
                'lightgoldenrodyellow', [250 250 210]
                'lightgray',            [211 211 211]
                'lightgreen',           [144 238 144]
                'lightgrey',            [211 211 211]
                'lightpink',            [255 182 193]
                'lightsalmon',          [255 160 122]
                'lightseagreen',        [ 32 178 170]
                'lightskyblue',         [135 206 250]
                'lightslategray',       [119 136 153]
                'lightslategrey',       [119 136 153]
                'lightsteelblue',       [176 196 222]
                'lightyellow',          [255 255 224]
                'lime',                 [  0 255   0]
                'limegreen',            [ 50 205  50]
                'linen',                [250 240 230]
                'magenta',              [255   0 255]
                'maroon',               [128   0   0]
                'mediumaquamarine',     [102 205 170]
                'mediumblue',           [  0   0 205]
                'mediumorchid',         [186  85 211]
                'mediumpurple',         [147 112 219]
                'mediumseagreen',       [ 60 179 113]
                'mediumslateblue',      [123 104 238]
                'mediumspringgreen',    [  0 250 154]
                'mediumturquoise',      [ 72 209 204]
                'mediumvioletred',      [199  21 133]
                'midnightblue',         [ 25  25 112]
                'mintcream',            [245 255 250]
                'mistyrose',            [255 228 225]
                'moccasin',             [255 228 181]
                'navajowhite',          [255 222 173]
                'navy',                 [  0   0 128]
                'oldlace',              [253 245 230]
                'olive',                [128 128   0]
                'olivedrab',            [107 142  35]
                'orange',               [255 165   0]
                'orangered',            [255  69   0]
                'orchid',               [218 112 214]
                'palegoldenrod',        [238 232 170]
                'palegreen',            [152 251 152]
                'paleturquoise',        [175 238 238]
                'palevioletred',        [219 112 147]
                'papayawhip',           [255 239 213]
                'peachpuff',            [255 218 185]
                'peru',                 [205 133  63]
                'pink',                 [255 192 203]
                'plum',                 [221 160 221]
                'powderblue',           [176 224 230]
                'purple',               [128   0 128]
                'rebeccapurple',        [102  51 153]
                'red',                  [255   0   0]
                'rosybrown',            [188 143 143]
                'royalblue',            [ 65 105 225]
                'saddlebrown',          [139  69  19]
                'salmon',               [250 128 114]
                'sandybrown',           [244 164  96]
                'seagreen',             [ 46 139  87]
                'seashell',             [255 245 238]
                'sienna',               [160  82  45]
                'silver',               [192 192 192]
                'skyblue',              [135 206 235]
                'slateblue',            [106  90 205]
                'slategray',            [112 128 144]
                'slategrey',            [112 128 144]
                'snow',                 [255 250 250]
                'springgreen',          [  0 255 127]
                'steelblue',            [ 70 130 180]
                'tan',                  [210 180 140]
                'teal',                 [  0 128 128]
                'thistle',              [216 191 216]
                'tomato',               [255  99  71]
                'turquoise',            [ 64 224 208]
                'violet',               [238 130 238]
                'wheat',                [245 222 179]
                'white',                [255 255 255]
                'whitesmoke',           [245 245 245]
                'yellow',               [255 255   0]
                'yellowgreen',          [154 205  50]
                };

            names  = string(data(:,1));
            values = double(cell2mat(data(:,2))) / 255;
            matched = 0;

            % ------------------------------------------------------------
            % CASE 1 — User supplied a colour name (full or partial)
            % ------------------------------------------------------------
            if nargin == 3
                q = lower(string(colourName));
                n = lower(names);

                match = matches(n, q); % 1. match whole name
                if ~any(match)
                    match = startsWith(n, q); % 2. match beginning of name
                    if ~any(match)
                        match = contains(n, q); % 3. match any part of name
                        if ~any(match)
                            name = 'not found';
                            rgbOut = '';
                            return
                        end
                    end
                end

                matched = sum(match);

                idx = find(match,1);
                name   = names(idx);
                rgbOut = values(idx, :);
                return
            end

            % ------------------------------------------------------------
            % CASE 2 — User supplied an RGB triplet
            % ------------------------------------------------------------
            rgb = double(rgb);
            if max(rgb) > 1
                rgb = rgb / 255;
            end

            rgb = reshape(rgb, 1, 3);

            diffs = values - rgb;
            dist  = sqrt(sum(diffs.^2, 2));

            [~, idx] = min(dist);

            name   = names(idx);
            rgbOut = values(idx, :);
        end

        %%%
        %%% Check if any motions detected since last Refresh.  If so, get fresh Bridge data.
        %%%

        function timerRefresh(app)
            if app.autoRefreshCount > app.refreshLimit
                fprintf('** Restarting  after reaching auto-refresh limit of %d. **\n', app.refreshLimit);
                restartDetails = append('Restarted after reaching auto-refresh limit of ', num2str(app.refreshLimit),'.');
                restartV = app.vAPI;
                limit = app.refreshLimit;
                lightsOnOnly = app.lightsOnRoomsOnly;
                app.autoRestartCount = app.autoRestartCount + 1;
                restartCount = app.autoRestartCount;
                if ispc
                    save([app.appFolder '\hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
                else
                    save([app.appFolder '/hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
                end
                restartApp(app);
                return
            end
            if app.vAPI == 1
                try
                    url = sprintf('http://%s/api/%s/sensors', app.hueIPAddressV2,app.appID);
                    response = webread(url);
                    if ~isfield(response,'error')
                        allSensors = response;
                    else
                        error ('** Unable to access Sensors details 2 **');
                    end
                    tableSensors = struct2table(allSensors);
                    noSensors = size(tableSensors);
                    fieldX = fieldnames(allSensors(1,1));
                    newEvent = false;
                    for i = 1:noSensors(1,2)
                        try
                            lastEvent = tableSensors.(fieldX{i,1}).state.lastupdated;
                            productName = tableSensors.(fieldX{i,1}).productname;
                            sensorType = tableSensors.(fieldX{i,1}).type;
                            sensorName = tableSensors.(fieldX{i,1}).name;
                            if contains(productName,'motion') && contains(sensorType,"Presence")
                                if lastEvent > app.lastRefresh
                                    app.sensorNameLastMovement = sensorName;
                                    newEvent = true;
                                    break;
                                end
                            end
                        catch
                        end
                    end
                    if newEvent
                        refreshDetails(app);
                        app.autoRefreshCount = app.autoRefreshCount + 1;
                        app.lastRefresh = datetime("now",TimeZone="UTC");
                        app.DiagnosticsMsgTextArea.Value = append('Auto-refresh ',num2str(app.autoRefreshCount),' triggered by ',sensorName, ' movement detection.');
                    end
                catch EM
                    disp(EM)
                end
            else
                path = "/clip/v2/resource/motion";
                method = 'GET';
                resp = hueRequest(app, app.hueIPAddressPro, app.appKey, method, path);
                app.sensorsMotionv2 = resp.data;
                allSensorMotions = app.sensorsMotionv2.';
                noSensorsMotion = size(allSensorMotions);
                newEvent = false;
                for i = 1:noSensorsMotion(1,2)
                    lastEvent = datetime(allSensorMotions(i).motion.motion_report.changed, ...
                        'InputFormat','yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', ...
                        'TimeZone','UTC');
                    if lastEvent > app.lastRefresh
                        for j = 1:size(app.tSensorsPro,1)
                            if app.tSensorsPro.uniqueid(j) == allSensorMotions(i).owner.rid
                                sensorName = app.tSensorsPro.sensorname(j);
                                break
                            end
                        end
                        app.sensorNameLastMovement = sensorName;
                        newEvent = true;
                        break
                    end
                end
                if newEvent
                    refreshDetails(app);
                    app.autoRefreshCount = app.autoRefreshCount + 1;
                    app.lastRefresh = datetime("now",TimeZone="UTC");
                    app.DiagnosticsMsgTextArea.Value = append('Auto-refresh ',num2str(app.autoRefreshCount),' triggered by ',sensorName, ' movement detection.');
                end
            end
        end

        %%%
        %%% Refresh Bridge Details
        %%%

        function refreshDetails(app)
            clc
            stopTimer(app);
            delete(app.UpdateTimer);
            startTimer(app);
            % Change speed (faster pulse)
            app.Pulse.setSpeed(0.5);

            if app.responseLimit == 0
                app.responseLimit = round(app.Response.Value * 1.25);
            end
            app.RefreshButton.Visible ="off";
            app.DiagnosticsMsgTextArea.Value = '';
            app.LastBridgeDataRefreshTextArea.Value = 'Updating..';
            prevColour = app.LastBridgeDataRefreshTextArea.FontColor;
            app.LastBridgeDataRefreshTextArea.FontColor = "white";
            if app.ResponseLimitSwitch.Value == "On" && ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = append('Extracting details from the Bridge..(Response Limit set to ',num2str(app.responseLimit), ' seconds.)');
            else
                if app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = 'Loading previously saved details..';
                    % else
                    %     app.DiagnosticsMsgTextArea.Value = 'Extracting details from the Bridge..';
                end
            end
            drawnow limitrate
            errCode = getStats (app, 'refresh');
            if app.ResponseLimitSwitch.Value == "On"
                if toc > app.responseLimit
                    fprintf('** Restarting to renew resources after exceeding %d seconds response limit by %d second(s). **\n', app.responseLimit,round(toc-app.responseLimit));
                    restartDetails= append('Restarted to renew resources after exceeding previous ',num2str(app.responseLimit), 's response limit.');
                    restartV = app.vAPI;
                    limit = app.refreshLimit;
                    lightsOnOnly = app.lightsOnRoomsOnly;
                    app.autoRestartCount = app.autoRestartCount + 1;
                    restartCount = app.autoRestartCount;
                    if ispc
                        save([app.appFolder '\hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
                    else
                        save([app.appFolder '/hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
                    end
                    restartApp(app);
                    return
                end
            end

            app.LastBridgeDataRefreshTextArea.FontColor = prevColour;
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            if errCode == 0
                if app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = 'Retrieved previously saved Bridge data.';
                else
                    app.DiagnosticsMsgTextArea.Value = 'Fresh data retrieved from the Bridge.';
                end
            elseif ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Refresh failed.'];
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                app.successfulStart = false;
            end
            if ~ app.offlineMode
                app.RefreshButton.Visible = "on";
                app.RefreshButton.Tooltip = '';
                app.lastRefresh = datetime("now","TimeZone","UTC");
            end
        end

        %%%
        %%% Timer functions
        %%%

        function startTimer(app)
            app.UpdateTimer = timer( ...
                'ExecutionMode','fixedSpacing', ...
                'Period',5.0, ...
                'BusyMode','drop', ...        % prevents reentrancy
                'TimerFcn',@(~,~)app.onTimerTick );
            start(app.UpdateTimer)
        end

        function stopTimer(app)
            try
                if ~isempty(app.UpdateTimer) && isvalid(app.UpdateTimer)
                    app.UpdateTimer.TimerFcn = '';   % prevents new callbacks
                    stop(app.UpdateTimer)
                    delete(app.UpdateTimer)
                end
            catch
            end
        end

        function onTimerTick(app, ~, ~)
            if ~ app.autoRefresh
                return
            end
            if isempty(app.UpdateTimer) || ~isvalid(app.UpdateTimer)
                return
            end
            % If previous tick still running, skip
            if app.TimerBusy
                return
            end
            app.TimerBusy = true;
            try
                app.timerRefresh();
            catch ME
                % Log the error but DO NOT leave TimerBusy stuck
                % warning("Timer error: %s", ME.message);
            end
            % Reset, even if errors occurred
            if isvalid(app)
                app.TimerBusy = false;
            end
            drawnow limitrate
        end


        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        %%% >>  API v2 and Bridge Pro processing follows
        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

        %%%
        %%% Determine which API version to use depending on Bridge
        %%%

        function apiVersion = detectHueApiVersion(app) % NOT USED
            fprintf('detectHueApiVersion\n');

            import matlab.net.http.*
            import matlab.net.http.field.*

            % Try API v2 endpoint (HTTPS)
            urlV2 = "https://" + app.hueIPAddressV2 + "/clip/v2/resource/bridge";

            try
                req = RequestMessage('get', HeaderField("hue-application-key", app.appID));
                opts = HTTPOptions( ...
                    'VerifyServerName', false, ...
                    'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                    );

                resp = req.send(urlV2, opts);

                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    apiVersion = 2;
                    return
                end
            catch
                % ignore, fall through to v1
            end

            % Try API v1 endpoint (HTTP)
            urlV1 = "http://" + app.hueIPAddressV2 + "/api/" + app.appID + "/config";

            try
                cfg = webread(urlV1);
                if isfield(cfg, 'bridgeid')
                    apiVersion = 1;
                    return
                end
            catch
                % ignore
            end

            error("Unable to detect Hue API version");

        end


        %%%
        %%%
        %%%

        function tf = deviceHasSensorServices(~,device)
            fprintf('deviceHasSensorServices\n');
            sensorTypes = ["motion","light_level","temperature","device_power","button","contact"];
            rtypes = string({device.services.rtype});
            tf = any(ismember(rtypes, sensorTypes));
        end

        %%%
        %%% Test for valid IP Addres using API v2
        %%%

        function tf = isValidHueIP(app, ip)
            % disp(class(app))
            fprintf('\nValidating IP: %s',ip);

            % 1) Try description.xml (works on all bridges)
            try
                url = "https://" + ip + "/description.xml";
                resp = app.sendRequest(url, "GET");

                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    tf = true;
                    fprintf(' - OK.\n');
                    return
                end
            catch EM
                disp(EM)
                % ignore and try next
            end

            % 2) Try /api/config (works on all bridges)
            try
                url = "https://" + ip + "/api/config";
                resp = app.sendRequest(url, "GET");

                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    tf = true;
                    fprintf(' - OK.\n');
                    return
                end
            catch EM
                disp(EM)
                % ignore
            end
            tf = false;
            fprintf(' - FAILED.\n');
        end


        %%%
        %%% Check which Bridges are connected
        %%%

        function [ip, errCode] = discoverBridges(~, bridgeModel)
            fprintf('discoverBridges for bridgeModel: %s\n', bridgeModel);

            % Returns IP address for requested Bridge Model
            % Uses the Hue discovery service to find all bridges on the LAN

            errCode = 0;
            ip = "";

            % --- Query Hue discovery service (still safe to use webread here) ---
            try
                allBridges = webread('https://discovery.meethue.com');
            catch
                warning('Unable to reach Hue discovery service.');
                errCode = 1;
                return;
            end

            % disp(allBridges);

            % --- Prepare HTTP options for UTC bridge calls ---
            import matlab.net.*
            import matlab.net.http.*

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...   % bypass hostname mismatch
                'CertificateFilename', '' );     % bypass CA validation

            req = RequestMessage;

            % --- Loop through discovered bridges ---
            for k = 1:numel(allBridges)
                ip = allBridges(k).internalipaddress;

                % ================================================================
                % 1) Try description.xml (v2 square bridge)
                % ================================================================
                try
                    uri = URI(['https://' ip '/description.xml']);
                    resp = req.send(uri, opts);
                    if resp.StatusCode == StatusCode.OK
                        desc = char(resp.Body.Data);
                        if contains(desc, bridgeModel)
                            return
                        end
                    end
                catch
                    % Ignore — could be Bridge Pro or just unreachable
                end

                % ================================================================
                % 2) Try /api/config (works for all bridges)
                % ================================================================
                try
                    uri = URI(['https://' ip '/api/config']);
                    resp = req.send(uri, opts);
                    if resp.StatusCode ~= StatusCode.OK
                        continue
                    end
                    cfg = resp.Body.Data;
                    if cfg.modelid == bridgeModel
                        return
                    end
                catch
                    continue
                end
            end
            % No matching bridge was found
            errCode = 2;
        end

        %%%
        %%% Resource mapping for API v2 data
        %%%

        function s = getService(~, device, rtype, resourceMap)
            fprintf('getService: rtype=%s\n', rtype);
            s = [];

            % Unwrap device if it's a cell
            if iscell(device)
                if isempty(device)
                    return
                end
                device = device{1};
            end

            % If services field missing or empty, nothing to do
            if ~isfield(device, "services") || isempty(device.services)
                return
            end

            % Normalise services to a cell array
            if isstruct(device.services)
                svcList = num2cell(device.services);
            elseif iscell(device.services)
                svcList = device.services;
            else
                return
            end

            % Iterate safely
            for i = 1:numel(svcList)
                svc = svcList{i};

                if isfield(svc, "rtype") && svc.rtype == rtype
                    rid = svc.rid;

                    if isKey(resourceMap, rid)
                        s = resourceMap(rid);
                    end
                    return
                end
            end
        end

        %%%
        %%% api v2 to api v1 converters
        %%%

        % --------------------------------------------------------------
        % Load all v2 resources and split into categories
        % --------------------------------------------------------------
        function loadAllResourcesPro(app, hueIPAddress, appKey)
            % fprintf('loadAllResourcesv2\n');

            fetchAllResourcesPro(app, hueIPAddress, appKey);
            app.resourceMap = buildResourceMap(app, app.allResources);

            keys(app.resourceMap);

            % Split resources by type
            app.geolocationv2   = filterByType(app,"geolocation");
            app.roomsv2         = filterByType(app,"room");
            app.zonesv2         = filterByType(app,"zone");
            app.lightsv2        = filterByType(app,"light");
            app.bridgev2        = filterByType(app,"bridge");
            app.devicesv2       = filterByType(app,"device");
            app.sensorsv2 = filterByType(app,["temperature","motion"]);
            app.switchesv2 = filterByType(app, "button");
            app.sensorsTempv2 = filterByType(app,"temperature");
            app.sensorsMotionv2 = filterByType(app,"motion");
            app.powerStatesv2 = filterByType(app,"power");
            app.groupsv2        = filterByType(app,["zone", "room"]);
            app.scenesv2        = filterByType(app,"scene");
            app.scriptsv2         = filterByType(app,"behavior_script");
            app.schedulesv2     = filterByType(app,"behavior_instance");
            app.capabilitiesv2  = filterByType(app,["entertainment_configuration", "entertainment"]);
            app.resourcelinksv2 = filterByType(app,"resource_link");
            app.softwareUpdatesv2 = filterByType(app,"device_software_update");
            app.devicePowerv2 = filterByType(app,"device_power");
            app.connectivityv2 = filterByType(app,"zigbee_connectivity");

        end

        % --------------------------------------------------------------
        % Build RID → resource struct map
        % --------------------------------------------------------------
        function map = buildResourceMap(~, data)
            % fprintf('buildResourceMap\n');
            map = containers.Map;
            for i = 1:numel(data)
                r = data{i};
                map(r.id) = r;
            end
        end

        % --------------------------------------------------------------
        % Filter resources by type
        % --------------------------------------------------------------
        function out = filterByType(app, types)
            % fprintf('filterByType: %s\n', types);
            out = {};   % cell array avoids struct concatenation issues
            idx = 1;
            for i = 1:numel(app.allResources)
                r = app.allResources{i};
                if any(r.type == types)
                    out{idx} = r;
                    idx = idx + 1;
                end
            end
            % fprintf('filterByType: matched  %d\n', idx-1);
        end

        %%%
        %%% VCheck for valid API v2 application key
        %%%


        function ok = validateProKey(~, ip, key)
            % fprintf('\nvalidateProKey\n');
            import matlab.net.http.*

            url = "https://" + ip + "/clip/v2/resource";

            % IMPORTANT: use comma-separated row vector
            headers = [ ...
                HeaderField('hue-application-key', key), ...
                HeaderField('Accept', '*/*') ...
                ];

            req = RequestMessage('get', headers);

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            try
                resp = req.send(url, opts);

                % Valid key → 200 OK
                ok = resp.StatusCode == matlab.net.http.StatusCode.OK;

            catch ME
                disp(ME)
                ok = false;
            end
        end


        function appKey = performProPairing(app, ip)
            import matlab.net.http.*

            url = "https://" + ip + "/clip/v2/resource";

            body = struct( ...
                'type', 'application', ...
                'name', 'matlab-app' ...
                );

            headers = [ ...
                HeaderField('Accept','application/json'), ...
                HeaderField('Content-Type','application/json'), ...
                HeaderField('hue-application-key','dummy') ...  % <-- critical fix
                ];

            req = RequestMessage( ...
                'post', ...
                headers, ...
                MessageBody(jsonencode(body)) ...
                );

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '', ...
                'UseProxy', false ...
                );

            fprintf('Press the link button on the Bridge Pro...\n');
            app.DiagnosticsMsgTextArea.Value = 'Press the link button on the Bridge Pro within 30 seconds..';
            drawnow limitrate
            pause(30);

            % try
            resp = req.send(url, opts);

            if resp.StatusCode == matlab.net.http.StatusCode.Created
                data = resp.Body.Data.data;
                appKey = data(1).username;
                hueAppKey = appKey;
                save(app.fName, "hueAppKey");
                app.DiagnosticsMsgTextArea.Value = 'Bridge Pro (API v2) registration successful (added to Whitelist).  Restarting the App in 5 seconds...';
                drawnow limitrate
                pause(5);
                restart(app);
            end
            % catch
            appKey = [];
            try
                if contains(resp.error.description, 'link button not pressed')
                    fprintf('\n* Link button on Bridge Pro not pressed within 30 seconds.  Timed out. *\n');
                    app.DiagnosticsMsgTextArea.Value = 'Link button on Bridge Pro not pressed within 30 seconds.  Timed out.';
                else
                    fprintf('\n* Undetermined error during Bridge Pro (API v2) registration. *\n');
                    app.DiagnosticsMsgTextArea.Value = 'Undetermined error during Bridge Pro (API v2) registration.';
                end
                drawnow limitrate
                pause(10)
            catch
                fprintf('\n* Unable to communicate with the Bridge Pro *\n');
                app.DiagnosticsMsgTextArea.Value = 'Unable to communicate with the Bridge Pro.  Not paired.';
                drawnow limitrate
                pause(10);
            end
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            % end
        end

        %%%
        %%% Retrieve Bridge Pro metadata
        %%%

        function config = getBridgeConfigPro(app, hueIPAddress)
            % fprintf('getBridgeConfigPro\n');
            %
            % Returns a struct with:
            %   bridge_id, bridge_name, timezone, api_version, sw_version,
            %   product_name, manufacturer, model_id, serial_number

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + hueIPAddress + "/clip/v2/resource";

            header = HeaderField("hue-application-key", app.appKey);
            req = RequestMessage('get', header);

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            resp = req.send(url, opts);

            if resp.StatusCode ~= matlab.net.http.StatusCode.OK
                error("Hue Bridge returned HTTP %s", string(resp.StatusCode));
            end

            raw = resp.Body.Data;

            % --- Validate presence of "data" field ---
            if ~isfield(raw, "data") || isempty(raw.data)
                error("Hue Bridge returned no resource data. Check app key or permissions.");
            end

            data = raw.data;

            % --- Normalise: extract only valid struct entries ---

            if iscell(data)
                flat = {};
                for i = 1:numel(data)
                    item = data{i};
                    if isstruct(item)
                        flat{end+1} = item; %#ok<AGROW>
                    elseif iscell(item)
                        % nested cell: extract any structs inside it
                        for j = 1:numel(item)
                            if isstruct(item{j})
                                flat{end+1} = item{j}; %#ok<AGROW>
                            end
                        end
                    end
                end

                if isempty(flat)
                    error("No valid struct entries found in resource list. Unexpected bridge response.");
                end

                % --- Unify field sets across all structs ---
                % Collect all field names that appear in any struct
                allFields = {};
                for i = 1:numel(flat)
                    allFields = union(allFields, fieldnames(flat{i}));
                end

                % Add missing fields to each struct
                for i = 1:numel(flat)
                    f = fieldnames(flat{i});
                    missing = setdiff(allFields, f);
                    for m = 1:numel(missing)
                        flat{i}.(missing{m}) = [];   % fill missing fields
                    end
                end

                % Now safe to concatenate
                data = [flat{:}];
            end

            % --- Validate struct array ---
            if ~isstruct(data)
                error("Unexpected resource format: expected struct array, got %s", class(data));
            end

            % --- Extract entries by type ---
            types = {data.type};

            bridgeEntry = data(strcmp(types, "bridge"));
            deviceEntry = data(strcmp(types, "device"));
            zigbeeEntry = data(strcmp(types, "zigbee_connectivity"));
            % --- Build output struct ---
            config = struct();

            % Zigbee details
            if ~isempty(zigbeeEntry)
                for zz=1:numel(zigbeeEntry)
                    z = zigbeeEntry(zz);
                    config.zigbee_channel  = safeField(app, z, ["channel", "value"]);
                    if contains(config.zigbee_channel,'channel','IgnoreCase',true)
                        config.zigbee_channel = extractAfter(config.zigbee_channel,'_');
                        break;
                    end
                end
            end

            % Bridge-level info
            if ~isempty(bridgeEntry)
                b = bridgeEntry(1);  % Should only be one
                config.bridge_id    = safeField(app,b, "bridge_id");
                config.timezone     = safeField(app,b, ["time_zone","time_zone"]);
            end

            % Device-level info (manufacturer, model, serial)
            if ~isempty(deviceEntry)
                for dd=1:numel(deviceEntry)
                    d = deviceEntry(dd);
                    config.product_name = safeField(app,d, ["product_data","product_name"]);
                    config.manufacturer = safeField(app,d, ["product_data","manufacturer_name"]);
                    config.model_id = safeField(app,d, ["product_data","model_id"]);
                    config.serial_number = safeField(app,d, ["product_data","serial_number"]);
                    config.sw_version = safeField(app,d, ["product_data","software_version"]);
                    config.model_id = safeField(app,d, ["product_data","model_id"]);
                    config.bridge_name = safeField(app,d, ["metadata","name"]);
                    if contains(config.product_name,'bridge','IgnoreCase',true)
                        break;
                    end
                end
            end
        end


        % -------------------------------------------------------------------------
        % SAFE NESTED FIELD ACCESSOR
        % -------------------------------------------------------------------------
        function out = safeField(~,s, path)
            % fprintf('safeField\n');
            %SAFEFIELD  Safely extract nested fields without throwing errors.
            % Example: safeField(entry, ["metadata","name"])

            try
                tmp = s;
                for p = path
                    if isfield(tmp, p)
                        tmp = tmp.(p);
                    else
                        out = "";
                        return
                    end
                end
                out = char(tmp);
            catch
                out = "";
            end
        end


        function fetchAllResourcesPro(app, hueIPAddress, appKey)
            % fprintf('fetchAllResources\n');

            path = "/clip/v2/resource/clip";
            method = 'GET';
            resp = hueRequest(app, hueIPAddress, appKey, method, path);
            clip = resp.data;

            path = "/clip/v2/resource";
            method = 'GET';
            resp = hueRequest(app, hueIPAddress, appKey, method, path);
            bridge = resp.data;

            if app.startup % only at startup
                % Save all resource types (clip) and bridge pro data
                if ispc
                    save([app.dataRepository '\clip.mat'],"clip");
                    save([app.dataRepository '\allBridgeProData.mat'],"bridge");
                else
                    save([app.dataRepository '/clip.mat'],"clip");
                    save([app.dataRepository '/allBridgeProData.mat'],"bridge");
                end
            end

            app.allResources = resp.data;

        end

        % %%%
        % %%% Generic API v2 request function
        % %%%

        function out = hueRequest(~, ip, appKey, method, path, body)
            %H  Send a request to a Philips Hue Pro bridge.
            %
            %   out = hueRequest(ip, appKey, method, path)
            %   out = hueRequest(ip, appKey, method, path, body)
            %
            %   ip     - Bridge IP address (string)
            %   appKey - Hue v2 application key (string)
            %   method - 'GET', 'PUT', 'POST', 'DELETE'
            %   path   - API path, e.g. '/clip/v2/resource/bridge'
            %   body   - (optional) MATLAB struct to send as JSON

            import matlab.net.*
            import matlab.net.http.*

            % Build URI

            uri = matlab.net.URI("https://" + ip + path);

            opts = matlab.net.http.HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            headers = matlab.net.http.HeaderField("hue-application-key", appKey);


            % Select HTTP method
            switch upper(method)
                case 'GET'
                    verb = RequestMethod.GET;
                    reqBody = [];
                case 'DELETE'
                    verb = RequestMethod.DELETE;
                    reqBody = [];
                case 'PUT'
                    verb = RequestMethod.PUT;
                    reqBody = jsonencode(body);
                case 'POST'
                    verb = RequestMethod.POST;
                    reqBody = jsonencode(body);
                otherwise
                    error('HueRequest:InvalidMethod', 'Unsupported method: %s', method);
            end

            % Build request message
            if isempty(reqBody)
                req = matlab.net.http.RequestMessage(verb, headers);
            else
                req = matlab.net.http.RequestMessage(verb, headers, reqBody);
            end
            resp = req.send(uri, opts);

            % Error handling
            if resp.StatusCode ~= matlab.net.http.StatusCode.OK && ...
                    resp.StatusCode ~= matlab.net.http.StatusCode.Created

                body = resp.Body.Data;

                % Convert body to readable text for the error message
                if isa(body, 'uint8')
                    msg = char(body);
                elseif ischar(body)
                    msg = body;
                elseif isstring(body)
                    msg = char(body);
                elseif isstruct(body)
                    % Struct → JSON string for readability
                    msg = jsonencode(body);
                else
                    msg = "Unrecognised response body type";
                end
                if contains(msg, 'not found','IgnoreCase',true)
                    out = "Light Not Found.";
                    return
                end
                if contains(msg, 'communication issue','IgnoreCase',true)
                    out = "Light Communication Issues.";
                    return
                end

                error('HueRequest:HTTPError', ...
                    'Bridge returned %s\n%s', ...
                    string(resp.StatusCode), msg);
            end

            % Decode JSON
            body = resp.Body.Data;

            if isa(body, 'uint8')
                out = jsondecode(char(body));
            elseif ischar(body)
                out = jsondecode(body);
            elseif isstring(body)
                out = jsondecode(char(body));
            elseif isstruct(body)
                out = body;   % already decoded
            else
                error("HueRequest:UnexpectedBodyType", ...
                    "Unexpected response body type: %s", class(body));
            end
        end


        %%%%%
        %%%%% Unified API v2 HTTP request Processing methods (start)
        %%%%%

        %%%
        %%% Generate preconfigured API v2 HTTP Client (1)
        %%%

        function [req, opts] = makeRequestObjects(~)
            import matlab.net.*
            import matlab.net.http.*

            req = RequestMessage;

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...   % ignore hostname mismatch
                'CertificateFilename', '' );     % ignore CA trust chain
        end

        %%%
        %%% Wrapper for sending requests (2)
        %%%

        function resp = sendRequest(app, url, method, body)
            import matlab.net.*
            import matlab.net.http.*

            if nargin < 4
                body = [];
            end

            [req, opts] = app.makeRequestObjects();

            if nargin >= 3 && ~isempty(method)
                req.Method = method;
            end

            if ~isempty(body)
                req.Body = Body(body);
            end

            uri = URI(url);
            resp = req.send(uri, opts);
        end

        %%%%%
        %%%%% Unified API v2 HTTP request Processing methods (end)
        %%%%%

        function created = createTableSchemasV2(app)
            created = false;
            % Create a standard format table of Sensors
            if isempty(app.tSensorsV2) || ~ istable(app.tSensorsV2)
                % Create new table Schema
                tSensorsVarTypes = ["double", "string", "string", "string", "string", "double", "double","datetime","string"];
                tSensorsVarNames = ["sensorno", "sensorname", "modelID", "productname", "type", "battery", "temp","lastevent","uniqueid"];
                app.tSensorsV2Template = table('Size',[1 9], ...
                    'VariableTypes', tSensorsVarTypes, ...
                    'VariableNames', tSensorsVarNames);
                app.tSensorsV2 = app.tSensorsV2Template;
                % disp('creating template for app.tSensorsV2');
                created = true;
            else
                app.tSensorsV2 = app.tSensorsV2Template; % clear the table
            end
            % Create table of Sensor temperatures
            if isempty(app.tTempsV2) || ~ istable(app.tTempsV2)
                % Create new table Schema
                tTempsVarTypes = ["string", "double", "string","datetime"];
                tTempsVarNames = ["temp", "battery", "sensorname","lastevent"];
                app.tTempsV2Template = table('Size',[1 4], ...
                    'VariableTypes', tTempsVarTypes, ...
                    'VariableNames', tTempsVarNames);
                app.tTempsV2 = app.tTempsV2Template;
                % disp('creating template for app.tTempsV2');
                created = true;
            else
                app.tTempsV2 = app.tTempsV2Template; % clear the table
            end
            % Create table of Sensor stats
            if isempty(app.sensorStatsV2) || ~ istable(app.sensorStatsV2)
                % Create new table Schema
                statsVarTypes = ["string","datetime","double"];
                statsVarNames = ["sensorname","lastevent","triggered"];
                app.sensorStatsV2Template = table('Size',[1 3], ...
                    'VariableTypes', statsVarTypes, ...
                    'VariableNames', statsVarNames);
                app.sensorStatsV2 = app.sensorStatsV2Template;
                % disp('creating template for app.sensorStatsV2');
                created = true;
            else
                % app.sensorStatsV2 = app.sensorStatsV2Template; % clear the table
            end
            % Create a table of unreachable Lights
            if isempty(app.tUnreachableV2) || ~ istable(app.tUnreachableV2)
                tUnreachablevarTypes = ["string", "string"];
                tUnreachablevarNames = ["ID", "lightname"];
                app.tUnreachableV2Template = table('Size',[1 2], ...
                    'VariableTypes', tUnreachablevarTypes, ...
                    'VariableNames', tUnreachablevarNames);
                app.tUnreachableV2 = app.tUnreachableV2Template;
                % disp('creating template for app.tUnreachableV2');
                created = true;
            else
                app.tUnreachableV2 = app.tUnreachableV2Template; % clear the table
            end
            % Create a standard format table of Lights
            if isempty(app.tLightsV2) || ~ istable(app.tLightsV2)
                % Create new table Schema
                tLightsVarTypes = ["double", "string", "string", "string","string","double", ...
                    "double","double","logical","string","logical","string"];
                tLightsVarNames =  ["lightno", "lightname", "productname", "model", "type","bright", ...
                    "x","y","on","uniqueid","reachable","colormode"];
                app.tLightsV2Template = table('Size',[1 12], ...
                    'VariableTypes', tLightsVarTypes, ...
                    'VariableNames', tLightsVarNames);
                app.tLightsV2 = app.tLightsV2Template;
                % disp('creating template for app.tLightsV2');
                created = true;
            else
                app.tLightsV2 = app.tLightsV2Template; % clear the table
            end
            % Create a standard format table of Schedules
            if isempty(app.tSchedulesV2) || ~ istable(app.tSchedulesV2)
                % Create new table Schema
                tSchedVarTypes = ["double", "string", "string","string","datetime","datetime"];
                tSchedVarNames = ["scheduleno", "schedulename", "desc", "status","created","starttime"];
                app.tSchedulesV2Template = table('Size',[1 6], ...
                    'VariableTypes', tSchedVarTypes, ...
                    'VariableNames', tSchedVarNames);
                app.tSchedulesV2 = app.tSchedulesV2Template;
                % disp('creating template for app.tSchedulesV2');
                created = true;
            else
                app.tSchedulesV2 = app.tSchedulesV2Template; % clear the table
            end
            % Create a standard format table of Groups
            if isempty(app.tGroupsV2) || ~ istable(app.tGroupsV2)
                % Create new table Schema
                tGrpVarTypes = ["double", "string", "string", "double", "string","string","logical","logical"];
                tGrpVarNames = ["groupno", "groupname", "type", "nolights","lights","class","allon","anyon"];
                app.tGroupsV2Template = table('Size',[1 8], ...
                    'VariableTypes', tGrpVarTypes, ...
                    'VariableNames', tGrpVarNames);
                app.tGroupsV2 = app.tGroupsV2Template;
                % disp('creating template for app.tGroupsV2');
                created = true;
            else
                app.tGroupsV2 = app.tGroupsV2Template; % clear the table
            end
            % Create an intersection table of Groups and Lights
            if isempty(app.itGroupslightsV2) || ~ istable(app.itGroupslightsV2)
                % Create new table Schema
                tGrpVarTypes = ["double", "string","string","double"];
                tGrpVarNames = ["groupno", "groupname", "grouptype", "lightno"];
                app.itGroupslightsV2Template = table('Size',[1 4], ...
                    'VariableTypes', tGrpVarTypes, ...
                    'VariableNames', tGrpVarNames);
                app.itGroupslightsV2 = app.itGroupslightsV2Template;
                % disp('creating template for app.itGroupslightsV2');
                created = true;
            else
                app.itGroupslightsV2 = app.itGroupslightsV2Template; % clear the table
            end
            % Create an expanded table of Lights and their attributes
            if isempty(app.allLightsTableV2) || ~ istable(app.allLightsTableV2)
                % Create new table Schema
                tLightsVarTypes = ["string", "string", "double","string","string","string","logical", ...
                    "double","string","double","double","string"];
                tLightsVarNames = ["groupname", "grouptype", "lightno","lightname","productname","model","state", ...
                    "bright","colour","x","y","colormode"];
                app.lightsRoomsTemplateV2 = table('Size',[1 12], ...
                    'VariableTypes', tLightsVarTypes, ...
                    'VariableNames', tLightsVarNames);
                app.allLightsTableV2 = app.lightsRoomsTemplateV2;
                app.roomLightsTableV2 = app.lightsRoomsTemplateV2;
                % disp('creating template for app.allLightsTableV2');
                % disp('creating template for app.roomLightsTableV2');
                created = true;
            else
                app.allLightsTableV2 = app.lightsRoomsTemplateV2; % clear the table
                app.roomLightsTableV2 = app.lightsRoomsTemplateV2; % clear the table
            end
            % Create a standard format table of Scenes
            if isempty(app.tScenesV2) || ~ istable(app.tScenesV2)
                % Create new table Schema
                tScenesVarTypes = ["string", "string", "string", "datetime", "string","string"];
                tScenesVarNames = ["sceneID", "scenename", "type", "lastupdate", "group", "lights"];
                app.tScenesV2Template = table('Size',[1 6], ...
                    'VariableTypes', tScenesVarTypes, ...
                    'VariableNames', tScenesVarNames);
                app.tScenesV2 = app.tScenesV2Template;
                % disp('creating template for app.tScenesV2');
                created = true;
            else
                app.tScenesV2 = app.tScenesV2Template; % clear the table
            end
            % Create a standard format table of Rules
            if isempty(app.tRulesV2) || ~ istable(app.tRulesV2)
                % Create new table Schema
                tRulesVarTypes = ["double", "string", "string", "datetime", "datetime", "double", "string","string", ...
                    "string", "double", "string", "string", ...
                    "double", "string", ...
                    "double", "string", "double", "string", "string"];
                tRulesVarNames = ["ruleno", "location", "rulename", "created", "lasttriggered", "timestriggered", "status", "action", ...
                    "target", "id", "description", "RL", ...
                    "groupno", "groupdesc", ...
                    "sensorno", "sensordesc","scheduleno", "scheduledesc","scenedesc"];
                app.tRulesV2Template = table('Size',[1 19], ...
                    'VariableTypes', tRulesVarTypes, ...
                    'VariableNames', tRulesVarNames);
                app.tRulesV2 = app.tRulesV2Template;
                % disp('creating template for app.tRulesV2');
                created = true;
            else
                app.tRulesV2 = app.tRulesV2Template; % clear the table
            end
            % Create a standard format table of Resource Links
            if isempty(app.tRlinksV2) || ~ istable(app.tRlinksV2)
                % Create new table Schema
                tRLVarTypes = ["string", "string", "string", "string"];
                tRLVarNames = ["rlId", "rlname", "desc", "links"];
                app.tRlinksV2Template = table('Size',[1 4], ...
                    'VariableTypes', tRLVarTypes, ...
                    'VariableNames', tRLVarNames);
                app.tRlinksV2 = app.tRlinksV2Template;
                % disp('creating template for app.tRlinksV2');
                created = true;
            else
                app.tRlinksV2 = app.tRlinksV2Template; % clear the table
            end
        end

        function created = createTableSchemasPro(app)
            created = false;
            % Create a standard format table of Lights
            if isempty(app.tLightsPro) || ~ istable(app.tLightsPro)
                % Create new table Schema
                tLightsVarTypes =  ["string", "string", "double","string","string","string","logical","double","string", ...
                    "string","double", "string", "double", "double","logical","string"];
                tLightsVarNames =  ["groupname", "grouptype", "lightno","lightname","productname","model","on","bright","colour", ...
                    "uniqueid","groupno", "type", "x", "y",  "reachable","colormode"];
                app.tLightsProTemplate = table('Size',[1 16], ...
                    'VariableTypes', tLightsVarTypes, ...
                    'VariableNames', tLightsVarNames);
                app.tLightsPro = app.tLightsProTemplate;
                % disp('creating template for app.tLightsPro');
                created = true;
            else
                app.tLightsPro = app.tLightsProTemplate; % clear the table
            end
            % Create a standard format table of Sensors
            if isempty(app.tSensorsPro) || ~ istable(app.tSensorsPro)
                % Create new table Schema
                tSensorsvarTypes = ["double", "string", "string", "string", "string", "double", "double","datetime","string"];
                tSensorsvarNames = ["sensorno", "sensorname", "modelID", "productname", "type", "battery", "temp","lastevent","uniqueid"];
                app.tSensorsProTemplate = table('Size',[1 9], ...
                    'VariableTypes', tSensorsvarTypes, ...
                    'VariableNames', tSensorsvarNames);
                app.tSensorsPro = app.tSensorsProTemplate;
                % disp('creating template for app.tSensorsPro');
                created = true;
            else
                app.tSensorsPro = app.tSensorsProTemplate; % clear the table
            end
            % Create a table of Sensor temperatures
            if isempty(app.tTempsPro) || ~ istable(app.tTempsPro)
                tTempsvarTypes = ["string", "double", "string","datetime"];
                tTempsvarNames = ["temp", "battery", "sensorname","lastevent"];
                app.tTempsProTemplate = table('Size',[1 4], ...
                    'VariableTypes', tTempsvarTypes, ...
                    'VariableNames', tTempsvarNames);
                app.tTempsPro = app.tTempsProTemplate;
                % disp('creating template for app.tTempsPro');
                created = true;
            else
                app.tTempsPro = app.tTempsProTemplate; % clear the table
            end
            % Create table of Sensor stats
            if isempty(app.sensorStatsPro) || ~ istable(app.sensorStatsPro)
                % Create new table Schema
                statsVarTypes = ["string","datetime","double"];
                statsVarNames = ["sensorname","lastevent","triggered"];
                app.sensorStatsProTemplate = table('Size',[1 3], ...
                    'VariableTypes', statsVarTypes, ...
                    'VariableNames', statsVarNames);
                app.sensorStatsPro = app.sensorStatsProTemplate;
                % disp('creating template for app.sensorStatsPro');
                created = true;
            else
                % app.sensorStatsPro = app.sensorStatsProTemplate; % clear the table
            end
            % Create a table of unreachable Lights
            if isempty(app.tUnreachablePro) || ~ istable(app.tUnreachablePro)
                tUnreachablevarTypes = ["string", "string"];
                tUnreachablevarNames = ["ID", "lightname"];
                app.tUnreachableProTemplate = table('Size',[1 2], ...
                    'VariableTypes', tUnreachablevarTypes, ...
                    'VariableNames', tUnreachablevarNames);
                app.tUnreachablePro = app.tUnreachableProTemplate;
                % disp('creating template for app.tUnreachablePro');
                created = true;
            else
                app.tUnreachablePro = app.tUnreachableProTemplate; % clear the table
            end
            % Create a table of Schedules and Scenes
            if isempty(app.tSchedulesPro) || ~ istable(app.tSchedulesPro)
                tSchedulesvarTypes = ["double", "string", "string", "datetime", "datetime", "double", "string","string", ...
                    "string", "double", "string", "string", ...
                    "double", "string", ...
                    "double", "string", "double", "string", "string"];
                tSchedulesvarNames = ["scheduleseqno", "location", "schedulename", "created", "lasttriggered", "timestriggered", "status", "action", ...
                    "target", "id", "description", "RL", ...
                    "groupno", "groupdesc", ...
                    "sensorno", "sensordesc","scheduleno", "scheduledesc","scenedesc"];
                app.tSchedulesProTemplate = table('Size',[1 19], ...
                    'VariableTypes', tSchedulesvarTypes, ...
                    'VariableNames', tSchedulesvarNames);
                app.tSchedulesPro = app.tSchedulesProTemplate;
                % disp('creating template for app.tSchedulesPro');
                created = true;
            else
                app.tSchedulesPro = app.tSchedulesProTemplate; % clear the table
            end
        end

    end %%% Methods (Access = private) - Main processing

    %%%
    %%%
    %%%


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            clc
            app.startup = true;
            app.LaunchTime.Text = append("Launched ",string(app.lastLaunch));
            %
            % Determine folder from which the app was launched
            %
            if isempty (string(app.appFolder))
                fullPath = mfilename('fullpath');
                app.appFolder = fileparts(which(fullPath));
                fprintf('app.appFolder = %s.\n', app.appFolder);
            end

            app.dataRepository = app.appFolder; % CURRENT directory (* NOT necessarily the directory from which the App was launched *)

            errCode = getStats (app, 'startup');

            try
                restartDetails = load(app.diagFileName,"restartDetails").restartDetails;
                app.vAPI = load(app.diagFileName,"restartV").restartV;
                app.refreshLimit = load(app.diagFileName,"limit").limit;
                app.lightsOnRoomsOnly = load(app.diagFileName,"lightsOnOnly").lightsOnOnly;
                app.autoRestartCount = load(app.diagFileName,"restartCount").restartCount;
                app.RestartCount.Value = app.autoRestartCount;
                app.BridgeDropDown.Value = app.vAPI;
                app.RefreshLimit.Value = app.refreshLimit;
                if app.lightsOnRoomsOnly
                    app.lightsOnRoomsOnlyCheckbox.Value = 1;
                else
                    app.lightsOnRoomsOnlyCheckbox.Value = 0;
                end

                if ~contains(restartDetails, 'success','IgnoreCase',true)
                    app.restartMsg = restartDetails;
                else
                    app.restartMsg = "";
                end
            catch ME % set everything to default values
                app.vAPI = 1;
                restartV = app.vAPI;
                app.refreshLimit = 500;
                limit = app.refreshLimit;
                app.lightsOnRoomsOnly = false;
                lightsOnOnly = app.lightsOnRoomsOnly;
                app.autoRestartCount = 0;
                restartCount = app.autoRestartCount ;
                if ispc
                    save([app.appFolder '\hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
                else
                    save([app.appFolder '/hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
                end
                restartApp(app);
                return
            end

            if app.vAPI == 1
                app.bridgeProMode = false;
            else
                app.bridgeProMode = true;
            end
            if app.vAPI == 2
                app.bridgeProMode = true;
                app.RulesTable.ColumnName = {'No'; 'Area'; 'Schedule/Scene Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Components'; 'On Timeout'; 'Mins'; 'Startup State'; 'Time Slots'};
                w = app.RulesTable.ColumnWidth;
                w{4} = 0;  % hide columns 4-6
                w{5} = 0;
                w{6} = 0;
                app.RulesTable.ColumnWidth = w;
            else
                app.RulesTable.ColumnName = {'No'; 'Area'; 'Rule Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Action'; 'Target'; 'Tgt No'; 'Target Name'; 'Resource Link'};
                w = app.RulesTable.ColumnWidth;
                w{4} = 'auto'; % unhide columns 4-6
                w{5} = 'auto';
                w{6} = 'auto';
                app.RulesTable.ColumnWidth = w;
            end

            if app.restoreWindowPos
                restoreWindowState(app);
            end
            app.lastRefresh = datetime("now","TimeZone","UTC");

            % Hide Colour column (9) in LightsTable
            % w = app.LightsTable.ColumnWidth;
            % w{9} = 0;
            % app.LightsTable.ColumnWidth = w;
            % Show Colour column in LightsTable
            % w = app.LightsTable.ColumnWidth;
            % w{9} = 'auto';            % or a numeric width, e.g. 120
            % app.LightsTable.ColumnWidth = w;


            if ispc
                htmlFolder = [app.appFolder '\HTML'];
            else
                htmlFolder = [app.appFolder '/HTML'];
            end
            if ~exist(htmlFolder, 'dir')
                error ('HTML folder <%s> is missing.\n', htmlFolder);
            else
                fprintf('HTML folder <%s> found.\n', htmlFolder);
            end

            % Make connections to external HTML sources
            % HTML is saved in the HTML folder..
            if ispc
                addpath (genpath(strcat(app.appFolder, '\HTML')));
                addpath (genpath(strcat(app.appFolder, '\Classes')));
            else
                addpath (genpath(strcat(app.appFolder, '/HTML')));
                addpath (genpath(strcat(app.appFolder, '/Classes')));
            end

            app.HTMLTempTrend.HTMLSource =  fullfile(app.appFolder, 'HTML', 'blinkTextFlexible.html');
            app.HTMLLowBatMsg.HTMLSource = fullfile(app.appFolder, 'HTML','pulseTextHue.html');
            app.HTMLLightsOn.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLLastMotion.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLLowBatDevs.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLUnreachableLights.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');

            % Pulse image
            app.Pulse = pulsingImage(app.HueLogo, 'huelogo.png');
            app.Pulse.HtmlComp.Data.size = struct('width', 145, 'height', 123);
            app.Pulse.HtmlComp.Position = [1 1 145 123];
            app.Pulse.setSpeed(10);
            app.Pulse.setOn(true);

            wID = 'MATLAB:ui:uihtml:ErrorOccuredInHTMLSource';
            warning('off',wID);

            % app.dataRepository = app.appFolder; % CURRENT directory (* NOT necessarily the directory from which the App was launched *)

            switch app.vAPI
                case 1
                    bridgeVersion = 'BridgeV2';
                case 2
                    bridgeVersion = 'BridgePro';
                otherwise
                    bridgeVersion = 'AllBridges';
            end

            if ispc
                repositoryName = ['\HueData' bridgeVersion];
            else
                repositoryName = ['/HueData' bridgeVersion];
            end

            fprintf('\n%s starting Up in Directory: %s\nLooking for existing Hue Data Repository %s...', mfilename, app.appFolder, repositoryName);
            %%%
            %%% First check whether the current directory is already the data repository..
            %%%
            if ~contains(app.dataRepository,repositoryName)
                app.dataRepository = [app.dataRepository repositoryName];
                % Now check if there is a data repository sub-directory...
                if ~exist(app.dataRepository, 'dir')
                    mkdir(app.dataRepository);
                    fprintf('Not found.  Created new Hue Data Repository: %s\n', app.dataRepository);
                else
                    fprintf('Found.\n');
                end
            else
                fprintf('Found.\n');
            end
            fileName = 'userNameHue.mat';
            fprintf('Looking for file: %s in %s...', fileName, app.dataRepository);
            usernameFile = fullfile(app.dataRepository, fileName);
            % Check if file exists
            if ~exist(usernameFile, 'file')
                fprintf('not found.  Attempting to copy from main Hue directory..');
                if ispc
                    sourceDir = 'C:\Users\spfol\OneDrive\Matlab2025\Current_r2025\Hue';
                    sourceFile = fullfile(sourceDir, fileName);
                else
                    sourceDir = '/Users/stephenfolmer/Library/CloudStorage/OneDrive-Personal/Matlab2025/Current_r2025/Hue'; % directorty containing master of userNameHue.mat
                    sourceFile = fullfile(sourceDir, fileName);
                end
                targetFile = fullfile(app.dataRepository, fileName);
                % Check if file exists
                if exist(sourceFile, 'file')
                    % Copy the file
                    copyfile(sourceFile, targetFile);
                    fprintf('Source File copied to: %s\n', targetFile);
                else
                    fprintf('\n** Master Source File not found: %s **\n', sourceFile);
                end
            else
                fprintf('Found.\n');
            end

            if errCode == 0
                errCode = getStats (app, 'refresh');
            else
                fprintf('\nerrCode: %d returned from getStats',errCode);
            end

            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;

            if app.bridgeSwitch
                app.restartMsg = 'Bridge switched and defaulted to On-line Mode. ';
                app.bridgeSwitch = false;
            end

            if errCode == 0
                if ~ app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = append(app.restartMsg, ' Bridge data loaded.');
                    app.LightsTable.Visible ="on";
                else
                    app.DiagnosticsMsgTextArea.Value = append('Previously saved Bridge data loaded. ',app.restartMsg);
                end
                app.successfulStart = true;
                app.restartMsg = "";
                startTimer(app);
            elseif ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Start-up failed.'];
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                app.successfulStart = false;
            end


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            restartDetails = append('Successfully terminated on ',strDt, '.');
            restartV = app.vAPI;
            limit = app.refreshLimit;
            lightsOnOnly = app.lightsOnRoomsOnly;
            restartCount = 0; % reset when exited correctly..
            if ispc
                save([app.appFolder '\hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
            else
                save([app.appFolder '/hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
            end
            warning('on', 'all');
            saveWindowState(app);
            stopTimer(app);
            closereq;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % If not yet successfully started (e.g. lack of internet) re-initiate startup processing..
            if ~app.successfulStart
                startupFcn(app);
            else
                clc % only clear command windo when explicitly refreshed (retains any necessary diagnostics)
                refreshDetails(app);
            end
        end

        % Cell selection callback: LightsTable
        function LightsTableCellSelection(app, event)
            pause(0.01);  % 10ms delay allows CellEditCallback to finish
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.cellSelIndices = event.Indices;
            noLights = size(app.LightsTable.Data);
            try % cater for selection outside of lights grid boundary
                switch app.cellSelIndices(1,2)
                    case 8 % brightness
                        app.DiagnosticsMsgTextArea.Value = newBrightness(app, event);
                        return;
                    case 9 % colour
                        app.DiagnosticsMsgTextArea.Value = "Colour selected.";
                    otherwise % toggle
                        drawnow limitrate
                        if app.cellSelIndices(1,2) == 1 % Group selected
                            if app.prevEventName == "KeyPress" && app.editGroup == app.LightsTable.Data{app.cellSelIndices(1,1),1}
                                % brightness changed - only update brightness; don't toggle group lights
                                if app.xyColour(1,1) == 0
                                    groupBrightness(app, noLights);
                                    app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " brightness changed.");
                                else
                                    coloursChanged = groupColour(app, noLights);
                                    if coloursChanged > 0
                                        app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " colour set to ", app.newColr,".");
                                    else
                                        app.DiagnosticsMsgTextArea.Value = append("No colour lights found in ",app.LightsTable.Data{app.cellSelIndices(1,1),1}, ".  Change ignored.");
                                    end
                                end
                                app.editGroup = 'none'; % reset so that the same group will toggle if selected next
                            else
                                selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
                                grpType = app.LightsTable.Data{app.cellSelIndices(1,1),2};
                                for i = 1:noLights(1,1)
                                    if app.LightsTable.Data{i,1} == selGrp ...
                                            && app.LightsTable.Data{i,2} == grpType
                                        numLight = app.LightsTable.Data{i,3};
                                        onOff = toggleLights(app, numLight, i, true);
                                        for j = 1:noLights(1,1)
                                            if app.LightsTable.Data{j,3} == numLight
                                                app.LightsTable.Data{j,7} = onOff;
                                            end
                                        end
                                    end
                                end
                                app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights toggled.");
                            end
                        else
                            if app.prevEventName ~= "KeyPress" % ignore toggle if brightness was just updated
                                numLight = app.LightsTable.Data{app.cellSelIndices(1,1),3};
                                onOff = toggleLights(app, numLight, app.cellSelIndices(1,1), true);
                                for j = 1:noLights(1,1)
                                    if app.LightsTable.Data{j,3} == numLight
                                        app.LightsTable.Data{j,7} = onOff;
                                    end
                                end
                                app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light toggled.");
                            end
                        end
                        highlightOnLights(app);
                        app.prevEventName = event.EventName;
                        app.xyColour = [0,0];
                        updateONGroupsRooms(app);
                end
            catch
            end

        end

        % Button pushed function: ToggleALLLightsButton
        function ToggleALLLightsButtonPushed(app, event)

            app.DiagnosticsMsgTextArea.Value = 'Toggling ALL Lights..';
            drawnow limitrate
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                numLight = app.LightsTable.Data{i,3};
                toggleLights(app, numLight, i, false);
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights toggled.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            updateONGroupsRooms(app);
        end

        % Button pushed function: AllLightsOn
        function AllLightsOnButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights ON..';
            drawnow limitrate
            noLights = size(app.LightsTable.Data);
            if app.vAPI == 1
                options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
                state = struct('on',true);
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 0
                        app.LightsTable.Data{i,7} = 1;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            numLight = app.LightsTable.Data{i,3};
                            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
                            webwrite(wrURL, state, options);
                            app.lightsOn = app.lightsOn + 1;
                        end
                    end
                end
            else
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 0
                        app.LightsTable.Data{i,7} = 1;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            lightUUID = app.LightsTable.Data{i,10};
                            body = struct('on', struct('on', true));
                            path = "/clip/v2/resource/light/" + lightUUID;
                            method = 'PUT';
                            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);
                            app.lightsOn = app.lightsOn + 1;
                        end
                    end
                end
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights ON.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.ONLights.Value = app.lightsOn;
            if app.lightsOn == 1
                app.LightsOnLabel.Text = 'Light';
            else
                app.LightsOnLabel.Text = 'Lights';
            end
            updateONGroupsRooms(app);
            blinkLightsOn(app);
            % fprintf('\n AllLightson function: app.lightsOn %d', app.lightsOn);
        end

        % Button pushed function: AllLightsOff
        function AllLightsOffButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights OFF..';
            drawnow limitrate
            noLights = size(app.LightsTable.Data);
            if app.vAPI == 1
                options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
                state = struct('on',false);
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 1
                        app.LightsTable.Data{i,7} = 0;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            numLight = app.LightsTable.Data{i,3};
                            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
                            webwrite(wrURL, state, options);
                        end
                    end
                end
            else
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 1
                        app.LightsTable.Data{i,7} = 0;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            lightUUID = app.LightsTable.Data{i,10};
                            body = struct('on', struct('on', false));
                            app.lightsOn = app.lightsOn - 1;
                            path = "/clip/v2/resource/light/" + lightUUID;
                            method = 'PUT';
                            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);
                        end
                    end
                end
            end
            if app.lightsOn == 1
                app.LightsOnLabel.Text = 'Light';
            else
                app.LightsOnLabel.Text = 'Lights';
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights OFF.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.ONLights.Value = 0;
            updateONGroupsRooms(app);
            app.lightsOn = 0;
            blinkLightsOn(app);
        end

        % Cell edit callback: LightsTable
        function LightsTableCellEdit(app, event)

            drawnow limitrate
            app.cellSelIndices = event.Indices;
            app.editGroup = app.LightsTable.Data{event.Indices(1,1),1};
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.xyColour = [0,0];
            app.newColr = '';
            matched = 0;
            if app.cellSelIndices(1,2) == 9 % Colour edited
                app.colBri = app.LightsTable.Data{event.Indices(1,1),8};
                switch upper(event.EditData)
                    case 'B'
                        app.newColr = 'blue';
                        app.xyColour= [0.15, 0.06];
                    case 'C'
                        app.newColr = 'cyan';
                        app.xyColour= [0.17, 0.34];
                    case 'G'
                        app.newColr = 'green';
                        app.xyColour= [0.17, 0.7];
                    case 'Y'
                        app.newColr = 'yellow';
                        app.xyColour= [0.44, 0.51];
                    case 'O'
                        app.newColr = 'orange';
                        app.xyColour= [0.56, 0.42];
                    case 'R'
                        app.newColr = 'red';
                        app.xyColour= [0.7, 0.3];
                    case 'P'
                        app.newColr = 'purple';
                        app.xyColour= [0.25, 0.1];
                    case 'M'
                        app.newColr = 'magenta';
                        app.xyColour= [0.38, 0.17];
                    case 'W'
                        app.newColr = 'white';
                        app.xyColour= [0.31, 0.33];
                    otherwise
                        str = event.EditData;
                        if ~ any(isstrprop(str, 'digit')) % no numbers
                            [matched, app.newColr,rgb] = findNearestCssColourName(app, '', event.EditData);
                            if matched > 1
                                app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                                app.DiagnosticsMsgTextArea.Value = append(num2str(matched), ' matches found for: ', event.EditData, ...
                                    '. ', app.newColr, ' selected.');
                            elseif app.newColr == "not found"
                                app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                                app.DiagnosticsMsgTextArea.Value = append('Unable to find a match for: ', event.EditData);
                                % get the original XY values back from column 9
                                app.currentX = str2double(extractBetween(app.LightsTable.Data{event.Indices(1,1),9},'[','],'));
                                app.currentY = str2double(extractBetween(app.LightsTable.Data{event.Indices(1,1),9},'],[',']'));
                                app.xyColour= [app.currentX, app.currentY];
                                return
                            end
                            [app.currentX, app.currentY] = rgb2xy(app, rgb);
                            app.xyColour= [app.currentX, app.currentY];
                            result = newColour (app, event);
                            if contains(result,'ignored','IgnoreCase',true)
                                app.DiagnosticsMsgTextArea.Value = result;
                                return
                            end
                        else
                            % Not a simple shorthand colour, so check whether  valid XY values..
                            %%%
                            [valid, x, y] = validateXYcolour(app, str);
                            if ~ valid
                                app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                                app.DiagnosticsMsgTextArea.Value = 'Invalid colour.  Must be: Hue XY colour values (in xx,yy format) OR initial characters of a colour name.';
                                return;
                            end
                            app.currentX = x;
                            app.currentY = y;
                            app.xyColour= [x, y];
                            rgb = hueXYtoRGB(app,x,y,app.LightsTable.Data{event.Indices(1,1),8});
                            [~,app.newColr] = findNearestCssColourName(app, rgb);
                            %%%
                        end
                end
                if matched < 2 % already set if matched colours > 1
                    app.DiagnosticsMsgTextArea.Value = newColour (app, event);
                end
            else % Brightness edited
                if isnan(event.NewData)
                    app.LightsTable.Data{event.Indices(1,1),8} = event.PreviousData; % restore previous numerical value
                    app.DiagnosticsMsgTextArea.Value = 'Brightness must be a numerical value from 1 to 100.';
                    return;
                else
                    app.DiagnosticsMsgTextArea.Value = newBrightness (app, event);
                end
            end
        end

        % Selection changed function: LightsTable
        function LightsTableSelectionChanged(app, event)
            app.prevCellSel = event.PreviousSelection;
            if isempty(app.prevCellSel)
                app.prevCellSel = [0 0];
            end
        end

        % Key press function: LightsTable
        function LightsTableKeyPressed(app, event)
            app.lastKey = event.Key;
            switch app.lastKey
                case "return"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                case "tab"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                otherwise
                    app.prevEventName = event.EventName;
            end
        end

        % Key press function: HueDashboardUIFigure
        function HueDashboardUIFigureKeyPress(app, event)
            app.lastKey = event.Key;
        end

        % Value changed function: lightsOnRoomsOnlyCheckbox
        function lightOnRoomsOnly(app, event)

            if app.lightsOnRoomsOnlyCheckbox.Value == 1
                app.lightsOnRoomsOnly = true;
            else
                app.lightsOnRoomsOnly = false;
            end
            RefreshButtonPushed(app);
        end

        % Close request function: HueDashboardUIFigure
        function HueDashboardUIFigureCloseRequest(app, event)

            stopTimer(app);

            delete(app)

        end

        % Button pushed function: AutoRefreshButton
        function AutoButtonPushed(app, event)
            app.autoRefreshCount = 0;
            app.sensorStatsV2 = app.sensorStatsV2Template;
            app.sensorStatsPro = app.sensorStatsProTemplate;
            if app.autoRefresh % Was ON
                app.AutoRefreshButton.BackgroundColor = 'black';
                app.AutoRefreshButton.FontColor = 'yellow';
                app.AutoRefreshButton.Text = 'Auto OFF';
                app.AutoRefreshButton.Tooltip = 'Details will NOT be refreshed automatically when a Sensor detects Motion.';
                app.autoRefresh = false;
            else % Was OFF
                app.AutoRefreshButton.BackgroundColor = app.RefreshButton.BackgroundColor;
                app.AutoRefreshButton.FontColor = app.RefreshButton.FontColor;
                app.AutoRefreshButton.Text = 'Auto ON';
                app.AutoRefreshButton.Tooltip = 'Details will be refreshed automatically when a Sensor detects Motion.';
                app.autoRefresh = true;
                refreshDetails(app);
            end
        end

        % Value changed function: BridgeDropDown
        function BridgeVersionChanged(app, event)
            app.vAPI = app.BridgeDropDown.Value;
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            restartDetails = append('Bridge switch successful on ',strDt, '.');
            restartV = app.vAPI;
            limit = app.refreshLimit;
            lightsOnOnly = app.lightsOnRoomsOnly;
            restartCount = app.autoRestartCount ;
            if ispc
                save([app.appFolder '\hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
            else
                save([app.appFolder '/hueAppDiagnostics'],"restartDetails","restartV","limit","lightsOnOnly","restartCount");
            end
            if app.vAPI == 1
                app.bridgeProMode = false;
            else
                app.bridgeProMode = true;
            end
            % revert to on-line mode when switching bridges
            app.OfflineMode.Value = 0;
            app.offlineMode = false;
            app.AutoRefreshButton.Visible ="on";
            app.ResponseLimitSwitch.Visible ="on";
            app.RefreshButton.Visible = "on";
            app.bridgeSwitch = true;
            restart(app);
        end

        % Value changed function: OfflineMode
        function offlineModeChanged(app, event)
            if app.OfflineMode.Value == 1
                app.offlineMode = true;
                app.DiagnosticsMsgTextArea.Value = 'Switched to Off-line Mode.';
                app.autoRefresh = false;
                app.AutoRefreshButton.Visible ="off";
                app.ResponseLimitSwitch.Visible ="off";
                app.RefreshButton.Visible = "off";
                app.ToggleALLLightsButton.Visible = "off";
                app.AllLightsOff.Visible = "off";
                app.AllLightsOn.Visible = "off";
            else
                app.offlineMode = false;
                app.DiagnosticsMsgTextArea.Value = 'Switched back to On-line Mode.';
                app.autoRefresh = true;
                app.AutoRefreshButton.Visible ="on";
                app.ResponseLimitSwitch.Visible ="on";
                app.RefreshButton.Visible = "on";
                app.ToggleALLLightsButton.Visible = "on";
                app.AllLightsOff.Visible = "on";
                app.AllLightsOn.Visible = "on";
            end
            restartDetails = append('Restarted: ', app.DiagnosticsMsgTextArea.Value);
            restartV = app.vAPI;
            lightsOnOnly = app.lightsOnRoomsOnly;
            app.autoRestartCount = app.autoRestartCount + 1;
            restartCount = app.autoRestartCount;
            if ispc
                save([app.appFolder '\hueAppDiagnostics'],"restartDetails","restartV","lightsOnOnly","restartCount");
            else
                save([app.appFolder '/hueAppDiagnostics'],"restartDetails","restartV","lightsOnOnly","restartCount");
            end
            drawnow limitrate
            pause(2);
            restart(app);
        end

        % Value changed function: ResponseLimitSwitch
        function ResponseLimitSwitchFlipped(app, event)
            if app.ResponseLimitSwitch.Value == "On"
                app.ResponseLimitSwitch.Tooltip = 'Response Limit ON.';
            else
                app.ResponseLimitSwitch.Tooltip = 'Response Limit OFF.';
            end

        end

        % Value changed function: RefreshLimit
        function RefreshLimitValueChanged(app, event)
            app.refreshLimit = app.RefreshLimit.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HueDashboardUIFigure = uifigure('Visible', 'off');
            app.HueDashboardUIFigure.Name = 'HueDashboard';
            app.HueDashboardUIFigure.Icon = fullfile(pathToMLAPP, 'huelogo.png');
            app.HueDashboardUIFigure.CloseRequestFcn = createCallbackFcn(app, @HueDashboardUIFigureCloseRequest, true);
            app.HueDashboardUIFigure.KeyPressFcn = createCallbackFcn(app, @HueDashboardUIFigureKeyPress, true);
            app.ExitButton = uibutton(app.HueDashboardUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PhilipsHueDashboardLabel = uilabel(app.HueDashboardUIFigure);
            app.PhilipsHueDashboardLabel.Text = 'Philips Hue Dashboard';
            app.RefreshButton = uibutton(app.HueDashboardUIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Refresh the display with latest Bridge data.'};
            app.RefreshButton.Text = 'Refresh';
            app.IPAddressLabel_3 = uilabel(app.HueDashboardUIFigure);
            app.IPAddressLabel_3.Text = 'API v.';
            app.IPAddressLabel = uilabel(app.HueDashboardUIFigure);
            app.IPAddressLabel.Text = 'IP Address';
            app.ZigbeeChannelLabel = uilabel(app.HueDashboardUIFigure);
            app.ZigbeeChannelLabel.Text = 'Zigbee Chan';
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel = uilabel(app.HueDashboardUIFigure);
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel.Text = 'Last  Firmware Update / Software Version';
            app.IPAddressLabel_4 = uilabel(app.HueDashboardUIFigure);
            app.IPAddressLabel_4.Text = 'Auto Update';
            app.PreviousStatusRefreshLabel = uilabel(app.HueDashboardUIFigure);
            app.PreviousStatusRefreshLabel.Text = {'Last Bridge'; 'Data Refresh'};
            app.ZigbeeChannelLabel_2 = uilabel(app.HueDashboardUIFigure);
            app.ZigbeeChannelLabel_2.Text = {'Refresh'; 'Response'};
            app.APIversion = uitextarea(app.HueDashboardUIFigure);
            app.APIversion.Editable = 'off';
            app.APIversion.Tooltip = {'Current Bridge API version.'};
            app.IPAddress = uitextarea(app.HueDashboardUIFigure);
            app.IPAddress.Editable = 'off';
            app.IPAddress.Tooltip = {'Current Bridge IP Address.'};
            app.ZigbeeChannel = uieditfield(app.HueDashboardUIFigure, 'numeric');
            app.ZigbeeChannel.Editable = 'off';
            app.ZigbeeChannel.Tooltip = {'The Zigbee Channel selected in the Bridge settings'};
            app.LastFirmwareUpdate = uitextarea(app.HueDashboardUIFigure);
            app.LastFirmwareUpdate.Editable = 'off';
            app.LastFirmwareUpdate.Tooltip = {'Time and version of the last Bridge Software update.'};
            app.AutoSWUpdate = uitextarea(app.HueDashboardUIFigure);
            app.AutoSWUpdate.Editable = 'off';
            app.AutoSWUpdate.Tooltip = {'Indicates whether the Bridge settings have Software Auto-Update enabled.'};
            app.LastBridgeDataRefreshTextArea = uitextarea(app.HueDashboardUIFigure);
            app.LastBridgeDataRefreshTextArea.Editable = 'off';
            app.LastBridgeDataRefreshTextArea.Tooltip = {'Timestamp of last Bridge Data Refresh.'};
            app.Response = uieditfield(app.HueDashboardUIFigure, 'numeric');
            app.Response.ValueDisplayFormat = '%.0f';
            app.Response.Editable = 'off';
            app.Response.Tooltip = {'Response time (seconds) for last Refresh.  When this exceeds the response time limit the app will restart to improve efficiency.'};
            app.IPAddressLabel_2 = uilabel(app.HueDashboardUIFigure);
            app.IPAddressLabel_2.Text = 'Bridge ID';
            app.BridgeID = uitextarea(app.HueDashboardUIFigure);
            app.BridgeID.Editable = 'off';
            app.BridgeID.Tooltip = {'The Bridge ID specified in the configuration. '};
            app.SWversion = uitextarea(app.HueDashboardUIFigure);
            app.SWversion.Editable = 'off';
            app.Label = uilabel(app.HueDashboardUIFigure);
            app.Label.Text = '/';
            app.SWUpDateAvail = uitextarea(app.HueDashboardUIFigure);
            app.SWUpDateAvail.Editable = 'off';
            app.SWUpDateAvail.Tooltip = {'Indicates whether the Bridge has pending Software Updates.'};
            app.SensorTemperaturesPanel = uipanel(app.HueDashboardUIFigure);
            app.SensorTemperaturesPanel.Title = 'Sensor Temperatures (max/min)';
            app.CMSensorsLabel = uilabel(app.SensorTemperaturesPanel);
            app.CMSensorsLabel.Text = 'Comfort      18-21';
            app.CMSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CMSensors.Editable = 'off';
            app.CLSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CLSensors.Editable = 'off';
            app.COSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.COSensors.Editable = 'off';
            app.WASensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.WASensors.Editable = 'off';
            app.HOSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.HOSensors.Editable = 'off';
            app.HotLabel = uilabel(app.SensorTemperaturesPanel);
            app.HotLabel.Text = 'Hot      > 30';
            app.WarmLabel = uilabel(app.SensorTemperaturesPanel);
            app.WarmLabel.Text = 'Warm   > 21';
            app.CoolLabel = uilabel(app.SensorTemperaturesPanel);
            app.CoolLabel.Text = 'Cool     < 18';
            app.ColdLable = uilabel(app.SensorTemperaturesPanel);
            app.ColdLable.Text = 'Cold     < 10';
            app.FreezingLable = uilabel(app.SensorTemperaturesPanel);
            app.FreezingLable.Text = 'Freezing < 0';
            app.FRSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.FRSensors.Editable = 'off';
            app.SensorsandSwitchesLabel = uilabel(app.HueDashboardUIFigure);
            app.SensorsandSwitchesLabel.Tooltip = {'Temperatures in Celcius and Battery percentage remaining.'; 'Last Events pertain to triggered detections or programmed automations for Sensors and manual interactions with Switches/dimmers.'};
            app.SensorsandSwitchesLabel.Interpreter = 'html';
            app.SensorsandSwitchesLabel.Text = 'Sensors and Switches';
            app.SensorsTable = uitable(app.HueDashboardUIFigure);
            app.SensorsTable.ColumnName = {'#/°C'; 'Batt'; 'Name (model)'; 'Last Event'};
            app.SensorsTable.RowName = {};
            app.SensorsTable.ColumnSortable = true;
            app.LightsOnLabel = uilabel(app.HueDashboardUIFigure);
            app.LightsOnLabel.Tooltip = {'TOGGLE switches lights between ON and OFF state.'; '*'; 'Click on a Group cell (Column 1) to Toggle a lights Group OR Toggle individual lights by clicking light-specific cells in Cols 2-7.  '; '*'; 'To apply brightness or colour changes to light Groups:'; '1) Type brightness percentage or colour character in Col 8 (Bri) or Col 9 (Colour), respectively - do NOT hit Enter or Tab.'; '2) Click on a Group cell in the SAME row as the above changes were made. '; '*'; 'To apply brightness or colour changes to ONE light:'; '1) Type brightness percentage or colour value in Col 8 (Bri) or Col 9 (Colour), respectively.'; '2) Hit Enter or Tab.'; '*'; 'Valid Colour values are:'; '1) One-character code:  r(ed), g(reen), b(lue) , y(ellow), w(hite), p(urple), o(range), c(yan), m(agenta).'; '2) [X,Y] values in format: 99,99  -  e.g. 16,08'};
            app.LightsOnLabel.Text = 'Lights';
            app.ToggleALLLightsButton = uibutton(app.HueDashboardUIFigure, 'push');
            app.ToggleALLLightsButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleALLLightsButtonPushed, true);
            app.ToggleALLLightsButton.Text = {'Toggle ALL'; 'Lights'};
            app.AllLightsOff = uibutton(app.HueDashboardUIFigure, 'push');
            app.AllLightsOff.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOffButtonPushed, true);
            app.AllLightsOff.Text = 'ALL OFF';
            app.AllLightsOn = uibutton(app.HueDashboardUIFigure, 'push');
            app.AllLightsOn.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOnButtonPushed, true);
            app.AllLightsOn.Text = 'ALL ON';
            app.LightsTable = uitable(app.HueDashboardUIFigure);
            app.LightsTable.ColumnName = {'Group'; 'Type'; '#'; 'Name'; 'Product Name'; 'Model'; 'On'; 'Bri'; 'Colour'; 'ID'};
            app.LightsTable.RowName = {};
            app.LightsTable.ColumnSortable = [true true true true true true true false true false];
            app.LightsTable.ColumnEditable = [false false false false false false false true true false];
            app.LightsTable.CellEditCallback = createCallbackFcn(app, @LightsTableCellEdit, true);
            app.LightsTable.CellSelectionCallback = createCallbackFcn(app, @LightsTableCellSelection, true);
            app.LightsTable.SelectionChangedFcn = createCallbackFcn(app, @LightsTableSelectionChanged, true);
            app.LightsTable.Multiselect = 'off';
            app.LightsTable.Tooltip = {''};
            app.LightsTable.KeyPressFcn = createCallbackFcn(app, @LightsTableKeyPressed, true);
            app.RulesLabel_2 = uilabel(app.HueDashboardUIFigure);
            app.RulesLabel_2.Tooltip = {'Columns can be rearranged by dragging the column heading.'};
            app.RulesLabel_2.Text = 'Rules';
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel = uilabel(app.HueDashboardUIFigure);
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Text = 'Number of Rules NOT triggered since the last Bridge firmware update:';
            app.NeverTriggered = uieditfield(app.HueDashboardUIFigure, 'numeric');
            app.NeverTriggered.Editable = 'off';
            app.NeverTriggered.Tooltip = {'The number of Rules ''never'' triggered is based on the ''timestriggered'' value held on the Bridge.  This is reset when the Bridge software is updated.  Also note that multiple Rules are created for every event or condition set for each device.'};
            app.RulesTable = uitable(app.HueDashboardUIFigure);
            app.RulesTable.ColumnName = {'No'; 'Area'; 'Rule Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Action'; 'Target'; 'Tgt No'; 'Target Name'; 'Resource Link'};
            app.RulesTable.ColumnRearrangeable = 'on';
            app.RulesTable.RowName = {};
            app.RulesTable.ColumnSortable = true;
            app.UnreachableLightsLabel = uilabel(app.HueDashboardUIFigure);
            app.UnreachableLightsLabel.Tooltip = {'Any unreachable lights will be listed here.'};
            app.UnreachableLightsLabel.Text = 'Unreachable Lights';
            app.TableLights = uitable(app.HueDashboardUIFigure);
            app.TableLights.ColumnName = {'ID'; 'Light'};
            app.TableLights.RowName = {};
            app.TableLights.ColumnSortable = true;
            app.TableSensors = uitable(app.HueDashboardUIFigure);
            app.TableSensors.ColumnName = {'%'; 'Model'; 'Name'};
            app.TableSensors.RowName = {};
            app.TableSensors.ColumnSortable = true;
            app.DiagnosticsTextAreaLabel = uilabel(app.HueDashboardUIFigure);
            app.DiagnosticsTextAreaLabel.Text = 'Diagnostics';
            app.DiagnosticsMsgTextArea = uitextarea(app.HueDashboardUIFigure);
            app.DiagnosticsMsgTextArea.Editable = 'off';
            app.DiagnosticsTimeTextArea = uitextarea(app.HueDashboardUIFigure);
            app.DiagnosticsTimeTextArea.Editable = 'off';
            app.BridgeConfigurationandUsagePanel = uipanel(app.HueDashboardUIFigure);
            app.BridgeConfigurationandUsagePanel.Title = 'Bridge Configuration and Usage';
            app.InstalledLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InstalledLabel.Text = 'Installed';
            app.BridgeLimitLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.BridgeLimitLabel.Text = 'Bridge Limit';
            app.UsedLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.UsedLabel.Text = 'Used %';
            app.OnActiveLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.OnActiveLabel.Text = 'On / Active';
            app.LightsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.LightsLabel.Text = 'Lights';
            app.AVLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVLights.Editable = 'off';
            app.BRLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRLights.Editable = 'off';
            app.PCLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCLights.ValueDisplayFormat = '%5.2f';
            app.PCLights.Editable = 'off';
            app.ONLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONLights.Editable = 'off';
            app.RulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.RulesLabel.Text = 'Rules';
            app.AVRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVRules.Editable = 'off';
            app.BRRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRRules.Editable = 'off';
            app.PCRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCRules.ValueDisplayFormat = '%5.2f';
            app.PCRules.Editable = 'off';
            app.ONRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONRules.Editable = 'off';
            app.GroupsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.GroupsLabel.Text = 'Groups';
            app.AVGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVGroups.Editable = 'off';
            app.BRGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRGroups.Editable = 'off';
            app.PCGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCGroups.ValueDisplayFormat = '%5.2f';
            app.PCGroups.Editable = 'off';
            app.OnGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.OnGroups.Editable = 'off';
            app.ScenesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ScenesLabel.Text = 'Scenes';
            app.AVScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVScenes.Editable = 'off';
            app.BRScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRScenes.Editable = 'off';
            app.PCScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCScenes.ValueDisplayFormat = '%5.2f';
            app.PCScenes.Editable = 'off';
            app.SchedulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SchedulesLabel.Text = 'Schedules';
            app.AVSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSchedules.Editable = 'off';
            app.BRSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSchedules.Editable = 'off';
            app.PCSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSchedules.ValueDisplayFormat = '%5.2f';
            app.PCSchedules.Editable = 'off';
            app.ONSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONSchedules.Editable = 'off';
            app.ResourceLinksLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ResourceLinksLabel.Text = 'Resource Links';
            app.AVResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVResourceLinks.Editable = 'off';
            app.BRResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRResourceLinks.Editable = 'off';
            app.PCResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCResourceLinks.ValueDisplayFormat = '%5.2f';
            app.PCResourceLinks.Editable = 'off';
            app.SensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SensorsLabel.Text = 'Sensors';
            app.AVSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSensors.Editable = 'off';
            app.BRSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSensors.Editable = 'off';
            app.PCSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSensors.ValueDisplayFormat = '%5.2f';
            app.PCSensors.Editable = 'off';
            app.MotionSensors = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensors.Text = '99';
            app.MotionSensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensorsLabel.Text = 'Motion Sensors ';
            app.DimmerSwitches = uilabel(app.BridgeConfigurationandUsagePanel);
            app.DimmerSwitches.Text = '99';
            app.SwitchesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SwitchesLabel.Text = 'Switches';
            app.devicesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.devicesLabel.Text = 'devices:';
            app.TotalDevices = uilabel(app.BridgeConfigurationandUsagePanel);
            app.TotalDevices.Text = '88';
            app.InLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InLabel.Text = 'In';
            app.onLabel = uilabel(app.HueDashboardUIFigure);
            app.onLabel.Text = 'on';
            app.WarningsPanel = uipanel(app.HueDashboardUIFigure);
            app.WarningsPanel.Title = 'Warnings';
            app.UnreachableLightsHdr = uilabel(app.WarningsPanel);
            app.UnreachableLightsHdr.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.UnreachableLightsHdr.Text = 'Unreachable Lights';
            app.LowBatteryDevicesHdr = uilabel(app.WarningsPanel);
            app.LowBatteryDevicesHdr.Tooltip = {'Number of devices with a battery level below 30%.'};
            app.LowBatteryDevicesHdr.Text = 'Low Battery Devices';
            app.HTMLLowBatDevs = uihtml(app.WarningsPanel);
            app.HTMLLowBatDevs.Tooltip = {'Number of devices with a battery level below 30%.'};
            app.HTMLUnreachableLights = uihtml(app.WarningsPanel);
            app.HTMLUnreachableLights.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.HTMLLowBatMsg = uihtml(app.HueDashboardUIFigure);
            app.HTMLTempTrend = uihtml(app.HueDashboardUIFigure);
            app.HTMLLightsOn = uihtml(app.HueDashboardUIFigure);
            app.HTMLLastMotion = uihtml(app.HueDashboardUIFigure);
            app.ResponseLimitSwitch = uiswitch(app.HueDashboardUIFigure, 'rocker');
            app.ResponseLimitSwitch.ValueChangedFcn = createCallbackFcn(app, @ResponseLimitSwitchFlipped, true);
            app.ResponseLimitSwitch.Tooltip = {'Enable auto-restart if Response Limit is exceeded.  Response Limit is calculated as Startup time * 1.25.'};
            app.HueLogo = uipanel(app.HueDashboardUIFigure);
            app.inLabel = uilabel(app.HueDashboardUIFigure);
            app.inLabel.Text = 'in';
            app.RoomsLabel = uilabel(app.HueDashboardUIFigure);
            app.RoomsLabel.Text = 'Rooms';
            app.noRoomsLightsOn = uilabel(app.HueDashboardUIFigure);
            app.noRoomsLightsOn.Text = '99';
            app.lightsOnRoomsOnlyCheckbox = uicheckbox(app.HueDashboardUIFigure);
            app.lightsOnRoomsOnlyCheckbox.ValueChangedFcn = createCallbackFcn(app, @lightOnRoomsOnly, true);
            app.lightsOnRoomsOnlyCheckbox.Tooltip = {'Show Only Rooms with Lights ON in the Grid.'};
            app.lightsOnRoomsOnlyCheckbox.Text = '';
            app.AutoRefreshButton = uibutton(app.HueDashboardUIFigure, 'push');
            app.AutoRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoRefreshButton.Tooltip = {'Auto-refresh upon Sensor Motion detection.  Checks at 5 second intervals.   Resets the Refresh Count and Sensor Stats.'};
            app.AutoRefreshButton.Text = 'Auto ON';
            app.BridgeDropDownLabel = uilabel(app.HueDashboardUIFigure);
            app.BridgeDropDownLabel.Text = 'Bridge';
            app.BridgeDropDown = uidropdown(app.HueDashboardUIFigure);
            app.BridgeDropDown.Items = {'v2', 'Pro'};
            app.BridgeDropDown.ItemsData = [1 2];
            app.BridgeDropDown.ValueChangedFcn = createCallbackFcn(app, @BridgeVersionChanged, true);
            app.BridgeDropDown.Tooltip = {'Select which Bridge to connect to.'};
            app.BridgeDropDown.Value = 1;
            app.OfflineMode = uicheckbox(app.HueDashboardUIFigure);
            app.OfflineMode.ValueChangedFcn = createCallbackFcn(app, @offlineModeChanged, true);
            app.OfflineMode.Tooltip = {'Switch to Offline Mode. (For testing and data analysis.)'};
            app.OfflineMode.Text = '';
            app.inTimesTriggeredOrderLabel = uilabel(app.HueDashboardUIFigure);
            app.inTimesTriggeredOrderLabel.Text = '(in Times Triggered Order)';
            app.Sunset = uilabel(app.HueDashboardUIFigure);
            app.Sunset.Text = 'Sunset';
            app.version = uilabel(app.HueDashboardUIFigure);
            app.version.Text = 'v7.7';
            app.LaunchTime = uilabel(app.HueDashboardUIFigure);
            app.LaunchTime.Tooltip = {'Tiime when this app was manually launched.'};
            app.RefreshLimit = uieditfield(app.HueDashboardUIFigure, 'numeric');
            app.RefreshLimit.Limits = [10 Inf];
            app.RefreshLimit.ValueDisplayFormat = '%4d';
            app.RefreshLimit.ValueChangedFcn = createCallbackFcn(app, @RefreshLimitValueChanged, true);
            app.RefreshLimit.Tooltip = {'Maximum number of auto-refresh cycles befor the app will auto-restart.  Minimum value is 10.'};
            app.RefreshLimit.Value = 500;
            app.LIMITLabel = uilabel(app.HueDashboardUIFigure);
            app.LIMITLabel.Text = 'Refresh Limit';
            app.RestartCount = uieditfield(app.HueDashboardUIFigure, 'numeric');
            app.RestartCount.Editable = 'off';
            app.RestartCount.Tooltip = {'Auto-restart Count.'};
            app.ContextMenu = uicontextmenu(app.HueDashboardUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            % Show the figure after all components are created
            app.HueDashboardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HueDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HueDashboardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HueDashboardUIFigure)
        end
    end
end